classdef MRItrace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        OpenMRIMenu                 matlab.ui.container.Menu
        OpenLabelFileMenu           matlab.ui.container.Menu
        SaveLabelFileMenu           matlab.ui.container.Menu
        ViewMenu                    matlab.ui.container.Menu
        Menu_Single                 matlab.ui.container.Menu
        Menu_Axial                  matlab.ui.container.Menu
        Menu_Coronal                matlab.ui.container.Menu
        Menu_Sagittal               matlab.ui.container.Menu
        Menu_Double                 matlab.ui.container.Menu
        Menu_AxialCoronal           matlab.ui.container.Menu
        Menu_AxialSagittal          matlab.ui.container.Menu
        Menu_CoronalSagittal        matlab.ui.container.Menu
        Menu_Triple                 matlab.ui.container.Menu
        OptionsMenu                 matlab.ui.container.Menu
        ColorCodesMenu              matlab.ui.container.Menu
        Panel_DisplayParameters     matlab.ui.container.Panel
        EditField_Contrast          matlab.ui.control.NumericEditField
        CenterBrightnessLabel       matlab.ui.control.Label
        WidthContrastLabel          matlab.ui.control.Label
        Slider_Brightness           matlab.ui.control.Slider
        EditField_Brightness        matlab.ui.control.NumericEditField
        Slider_Contrast             matlab.ui.control.Slider
        UIAxes_imgHist              matlab.ui.control.UIAxes
        ButtonGroup_DisplayOption   matlab.ui.container.ButtonGroup
        ImageButton                 matlab.ui.control.RadioButton
        ImageLabelButton            matlab.ui.control.RadioButton
        LabelButton                 matlab.ui.control.RadioButton
        Button_Plot                 matlab.ui.control.Button
        Button_SelectRange          matlab.ui.control.Button
        Button_3Dshape              matlab.ui.control.RadioButton
        Panel_Axial                 matlab.ui.container.Panel
        Slider_Axial                matlab.ui.control.Slider
        Spinner_Axial               matlab.ui.control.Spinner
        Button_home_Axial           matlab.ui.control.Button
        Button_select_Axial         matlab.ui.control.Button
        Panel_Coronal               matlab.ui.container.Panel
        Slider_Coronal              matlab.ui.control.Slider
        Spinner_Coronal             matlab.ui.control.Spinner
        Button_home_Coronal         matlab.ui.control.Button
        Button_select_Coronal       matlab.ui.control.Button
        Panel_Sagittal              matlab.ui.container.Panel
        Slider_Sagittal             matlab.ui.control.Slider
        Spinner_Sagittal            matlab.ui.control.Spinner
        Button_home_Sagittal        matlab.ui.control.Button
        Button_select_Sagittal      matlab.ui.control.Button
        Panel_TracingLabels         matlab.ui.container.Panel
        UITable_Labels              matlab.ui.control.Table
        TracingCcodeEditFieldLabel  matlab.ui.control.Label
        EditField_CurrentTracing    matlab.ui.control.NumericEditField
        Panel_TracingTools          matlab.ui.container.Panel
        StateButton_pen             matlab.ui.control.StateButton
        StateButton_eraser          matlab.ui.control.StateButton
        Redo_button                 matlab.ui.control.Button
        Undo_button                 matlab.ui.control.Button
        StateButton_drawing         matlab.ui.control.StateButton
        OpacitySliderLabel          matlab.ui.control.Label
        Opacity_Slider              matlab.ui.control.Slider
        PenSizeSpinnerLabel         matlab.ui.control.Label
        PenSizeSpinner              matlab.ui.control.Spinner
        Panel_LabelOverlay          matlab.ui.container.Panel
        ModeButtonGroup             matlab.ui.container.ButtonGroup
        NoButton                    matlab.ui.control.RadioButton
        Show_Button                 matlab.ui.control.RadioButton
        Edit_Button                 matlab.ui.control.RadioButton
        PlaneButtonGroup            matlab.ui.container.ButtonGroup
        AxialButton                 matlab.ui.control.RadioButton
        CoronalButton               matlab.ui.control.RadioButton
        SagittalButton              matlab.ui.control.RadioButton
        OverlayOpacityLabel         matlab.ui.control.Label
        OpacityOverlay_Slider       matlab.ui.control.Slider
        UIAxes_Axial                matlab.ui.control.UIAxes
        UIAxes_Coronal              matlab.ui.control.UIAxes
        UIAxes_Sagittal             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileV % File variables
        dV    % display variables for Display components (Axes, Panels, Buttons, etc.) that are adjusted with resizing the App window
        data  % data variables including the MRI & tracing Volumes & the data dimension variables that do not change
        tV    % temporary variables for Operation & Tracing variables that varies during the App operation
        % isPressed      % cell array with keys currently held
        % isBusy         % flag (true/false) indicating that no key presses can be received
        
%         chH   % The handle of crosshair
%         ch1
%         ch2
%         ch3
%         ch4
%         ch5
%         ch6
%         ch7
%         ch8
        
%         UIAxes_Axial
%         UIAxes_Coronal
%         UIAxes_Sagittal
    end
    
    methods (Access = private)
        
        function img = prepare2Dimages(app, overlayIx)
          % Get Variables
            V = app.data.V; 
            
            curX = app.tV.curX; 
            curY = app.tV.curY; 
            curZ = app.tV.curZ; 
            
            if nargin==1
                cur_VT = app.data.cur_VT; 
              % Original Image
                curAimg = squeeze(V(:,:,curZ)); 
                curCimg = squeeze(V(:,curY,:)); 
                curSimg = squeeze(V(curX,:,:));     
              % Create Tracing Image 
                curAimgT = squeeze(cur_VT(:,:,curZ)); 
                curCimgT = squeeze(cur_VT(:,curY,:)); 
                curSimgT = squeeze(cur_VT(curX,:,:)); 
                
            else
                if app.tV.overlayMode == 1 || app.tV.overlayMode == 2 % Show mode
                    cur_oVT = zeros(size(V)); 
                    eval(['cur_oVT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
                        % app.data.cur_oVT = cur_oVT; 
                   % Change curX or curY or curZ
                    toverlayXYZ = find(overlayIx~=0); 
                    changeVal = overlayIx(overlayIx~=0); 
                    switch toverlayXYZ
                        case 3
                            curZ = curZ + changeVal; 
                        case 2
                            curY = curY + changeVal; 
                        case 1
                            curX = curX + changeVal; 
                    end
                elseif app.tV.overlayMode == 3 || app.tV.overlayMode == 4 % Edit mode
                    if ~isfield(app.data, 'cur_oVT')
                        % When there is cur_oVT (before overlay editing), create cur_oVT from the VT#
                        cur_oVT = zeros(size(V)); 
                        eval(['cur_oVT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
                        app.data.cur_oVT = cur_oVT; 
                    else
                        % When there is cur_oVT (after overlay editing started), use the saved cur_oVT 
                        cur_oVT = app.data.cur_oVT; 
                    end
                end
%                 app.data.cur_oVT = cur_oVT; 
% %                 cur_VT = zeros(size(V)); 
% %                 eval(['cur_VT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
% % %                 eval(['app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '} = find(app.data.cur_VT(:)==app.tV.labelN);']); 
                

              % Original Image
                curAimg = squeeze(V(:,:,curZ)); 
                curCimg = squeeze(V(:,curY,:)); 
                curSimg = squeeze(V(curX,:,:));     
              % Create Tracing Image 
                curAimgT = squeeze(cur_oVT(:,:,curZ)); 
                curCimgT = squeeze(cur_oVT(:,curY,:)); 
                curSimgT = squeeze(cur_oVT(curX,:,:)); 
            end
            % Save "img" data
                img.curAimg = curAimg; 
                img.curCimg = curCimg; 
                img.curSimg = curSimg; 
                img.curAimgT = curAimgT; 
                img.curCimgT = curCimgT; 
                img.curSimgT = curSimgT; 
                
        end
        
        function img = prepare2Dimages_overlayEdit(app)
          % Get Variables
            V = app.data.V; 
            
            curX = app.tV.curX; 
            curY = app.tV.curY; 
            curZ = app.tV.curZ; 
            
            if ~isfield(app.data, 'cur_oVTe')
                
                cur_oVTe = zeros(size(app.data.V)); 
                cur_oVTe(app.data.cur_otempVTe) = app.tV.labelN * 100; 
                % Update Overlay image
                switch app.tV.overlayPlane
                    case 'Axial'
                        cur_oVTe(:,:, app.tV.curZ) = app.data.cur_VT(:,:, app.tV.curZ + app.tV.sliceNchange); % <-- extract adjacent slice label information at the beginning of overlay editting
                    case 'Coronal'
                        cur_oVTe(:, app.tV.curZ, :) = app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :); % <-- extract adjacent slice label information at the beginning of overlay editting
                    case 'Sagittal'
                        cur_oVTe(app.tV.curX, :, :) = app.data.cur_VT(app.tV.curX + app.tV.sliceNchange, :, :); % <-- extract adjacent slice label information at the beginning of overlay editting
                end
            else
                cur_oVTe = app.data.cur_oVTe; 
            end
            
%             if ~isfield(app.data, 'cur_oVT')
%                 % When there is cur_oVT (before overlay editing), create cur_oVT from the VT#
%                 cur_oVT = zeros(size(V)); 
%                 eval(['cur_oVT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
%                 app.data.cur_oVT = cur_oVT; 
%             else
%                 % When there is cur_oVT (after overlay editing started), use the saved cur_oVT 
%                 cur_oVT = app.data.cur_oVT; 
%             end
            
          % Original Image
            curAimg = squeeze(V(:,:,curZ)); 
            curCimg = squeeze(V(:,curY,:)); 
            curSimg = squeeze(V(curX,:,:));     
          % Create Tracing Image 
            curAimgT = squeeze(cur_oVTe(:,:,curZ)); 
            curCimgT = squeeze(cur_oVTe(:,curY,:)); 
            curSimgT = squeeze(cur_oVTe(curX,:,:)); 
            
          % Save "img" data
            img.curAimg = curAimg; 
            img.curCimg = curCimg; 
            img.curSimg = curSimg; 
            img.curAimgT = curAimgT; 
            img.curCimgT = curCimgT; 
            img.curSimgT = curSimgT; 
                
        end
        
        function oimg = prepare2Dimages_overlay(app)
          % Get Variables
%             V = app.data.V; 
%             
%             curX = app.tV.curX; 
%             curY = app.tV.curY; 
%             curZ = app.tV.curZ; 
            
%             if ~isfield(app.data, 'cur_oVTe')
%                 cur_oVTe = zeros(size(app.data.V)); 
                switch app.tV.overlayPlane
                    case 'Axial'
                        cur_oSTa(:,:, app.tV.curZ) = app.data.cur_VT(:,:, app.tV.curZ + app.tV.sliceNchange); % <-- extract adjacent slice label information at the beginning of overlay editting
                    case 'Coronal'
                        cur_oSTc(:, app.tV.curZ, :) = app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :); % <-- extract adjacent slice label information at the beginning of overlay editting
                    case 'Sagittal'
                        cur_oSTs(app.tV.curX, :, :) = app.data.cur_VT(app.tV.curX + app.tV.sliceNchange, :, :); % <-- extract adjacent slice label information at the beginning of overlay editting
                end
%             else
%                 cur_oVTe = app.data.cur_oVTe; 
%             end
            
%           % Original Image
%             curAimg = squeeze(V(:,:,curZ)); 
%             curCimg = squeeze(V(:,curY,:)); 
%             curSimg = squeeze(V(curX,:,:));     
%           % Create Tracing Image 
%             curAimgT = squeeze(cur_oVTe(:,:,curZ)); 
%             curCimgT = squeeze(cur_oVTe(:,curY,:)); 
%             curSimgT = squeeze(cur_oVTe(curX,:,:)); 
            
        % Save "img" data
%             oimg.curAimg = curAimg; 
%             oimg.curCimg = curCimg; 
%             oimg.curSimg = curSimg; 
%             oimg.curAimgT = curAimgT; 
%             oimg.curCimgT = curCimgT; 
%             oimg.curSimgT = curSimgT;             
            oimg.curImgT = curAimgT; 
            
%             cur_oVT = zeros(size(V)); 
%             eval(['cur_oVT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
%             
%            % Change curX or curY or curZ
%             toverlayXYZ = find(overlayIx~=0); 
%             changeVal = overlayIx(overlayIx~=0); 
%             switch toverlayXYZ
%                 case 3
%                     curZ = curZ + changeVal; 
%                 case 2
%                     curY = curY + changeVal; 
%                 case 1
%                     curX = curX + changeVal; 
%             end
%             
%           % Original Image
%             curAimg = squeeze(V(:,:,curZ)); 
%             curCimg = squeeze(V(:,curY,:)); 
%             curSimg = squeeze(V(curX,:,:));     
%           % Create Tracing Image 
%             curAimgT = squeeze(cur_oVT(:,:,curZ)); 
%             curCimgT = squeeze(cur_oVT(:,curY,:)); 
%             curSimgT = squeeze(cur_oVT(curX,:,:)); 
%             
%         % Save "img" data
%             oimg.curAimg = curAimg; 
%             oimg.curCimg = curCimg; 
%             oimg.curSimg = curSimg; 
%             oimg.curAimgT = curAimgT; 
%             oimg.curCimgT = curCimgT; 
%             oimg.curSimgT = curSimgT; 
            
        end
        
        
        function clear_AxesControls(app)

          % Axial axes & controllers
            cla(app.UIAxes_Axial); app.UIAxes_Axial.Title.String = ''; 
            set(app.UIAxes_Axial,        'Visible', 'off'); 
            set(app.Panel_Axial,         'Visible', 'off'); 
            set(app.Slider_Axial,        'Visible', 'off'); 
            set(app.Spinner_Axial,       'Visible', 'off'); 
            set(app.Button_select_Axial, 'Visible', 'off'); 
            set(app.Button_home_Axial,   'Visible', 'off'); 
            
          % Coronal axes & controllers
            cla(app.UIAxes_Coronal); app.UIAxes_Coronal.Title.String = ''; 
            set(app.UIAxes_Coronal,        'Visible', 'off'); 
            set(app.Panel_Coronal,         'Visible', 'off'); 
            set(app.Slider_Coronal,        'Visible', 'off'); 
            set(app.Spinner_Coronal,       'Visible', 'off'); 
            set(app.Button_select_Coronal, 'Visible', 'off'); 
            set(app.Button_home_Coronal,   'Visible', 'off'); 
            
          % Sagittal axes & controllers
            cla(app.UIAxes_Sagittal); app.UIAxes_Sagittal.Title.String = ''; 
            set(app.UIAxes_Sagittal,        'Visible', 'off'); 
            set(app.Panel_Sagittal,         'Visible', 'off'); 
            set(app.Slider_Sagittal,        'Visible', 'off'); 
            set(app.Spinner_Sagittal,       'Visible', 'off'); 
            set(app.Button_select_Sagittal, 'Visible', 'off'); 
            set(app.Button_home_Sagittal,   'Visible', 'off'); 
            
        end
        
        
        function prepare_AxesControls(app)
        % Set axes & controllers
        
            switch app.tV.view
                case 'Single_Axial'
                    set(app.UIAxes_Axial, 'Position', app.dV.pos_Single_Axial,  'Visible', 'on'); 
                    set(app.Panel_Axial,  'Position', app.dV.pos_Panel_Axial_S, 'Visible', 'on'); 
                    set(app.Slider_Axial,         'Visible', 'on'); 
                    set(app.Spinner_Axial,        'Visible', 'on'); 
                    set(app.Button_select_Axial, 'Visible', 'on'); % app.Button_select_Axial.Value  = 0; 
                    set(app.Button_home_Axial,   'Visible', 'on'); % app.Button_home_Axial.Value    = 0; 
                case 'Single_Coronal'
                    set(app.UIAxes_Coronal, 'Position', app.dV.pos_Single_Coronal,  'Visible', 'on'); 
                    set(app.Panel_Coronal,  'Position', app.dV.pos_Panel_Coronal_S, 'Visible', 'on'); 
                    set(app.Slider_Coronal,         'Visible', 'on'); 
                    set(app.Spinner_Coronal,        'Visible', 'on'); 
                    set(app.Button_select_Coronal, 'Visible', 'on'); 
                    set(app.Button_home_Coronal,   'Visible', 'on'); 
                case 'Single_Sagittal'
                    set(app.UIAxes_Sagittal, 'Position', app.dV.pos_Single_Sagittal,  'Visible', 'on'); 
                    set(app.Panel_Sagittal,  'Position', app.dV.pos_Panel_Sagittal_S, 'Visible', 'on'); 
                    set(app.Slider_Sagittal,         'Visible', 'on'); 
                    set(app.Spinner_Sagittal,        'Visible', 'on'); 
                    set(app.Button_select_Sagittal, 'Visible', 'on'); 
                    set(app.Button_home_Sagittal,   'Visible', 'on'); 
    
                case 'Double_AxialCoronal'
                  % Axial axes & controllers
                    set(app.UIAxes_Axial, 'Position', app.dV.pos_Double_AxialCoronal.Axial, 'Visible', 'on'); 
                    set(app.Panel_Axial,  'Position', app.dV.pos_Panel_Axial_Dac,           'Visible', 'on'); 
                    set(app.Slider_Axial,         'Visible', 'on'); 
                    set(app.Spinner_Axial,        'Visible', 'on'); 
                    set(app.Button_select_Axial, 'Visible', 'on'); 
                    set(app.Button_home_Axial,   'Visible', 'on'); 
                  % Coronal axes & controllers
                    set(app.UIAxes_Coronal, 'Position', app.dV.pos_Double_AxialCoronal.Coronal, 'Visible', 'on'); 
                    set(app.Panel_Coronal,  'Position', app.dV.pos_Panel_Coronal_Dac,           'Visible', 'on'); 
                    set(app.Slider_Coronal,         'Visible', 'on'); 
                    set(app.Spinner_Coronal,        'Visible', 'on'); 
                    set(app.Button_select_Coronal, 'Visible', 'on'); 
                    set(app.Button_home_Coronal,   'Visible', 'on'); 
                case 'Double_AxialSagittal'
                  % Axial axes & controllers
                    set(app.UIAxes_Axial, 'Position', app.dV.pos_Double_AxialSagittal.Axial, 'Visible', 'on'); 
                    set(app.Panel_Axial,  'Position', app.dV.pos_Panel_Axial_Das,            'Visible', 'on'); 
                    set(app.Slider_Axial,         'Visible', 'on'); 
                    set(app.Spinner_Axial,        'Visible', 'on'); 
                    set(app.Button_select_Axial, 'Visible', 'on'); 
                    set(app.Button_home_Axial,   'Visible', 'on'); 
                  % Sagittal Image Controllers on
                    set(app.UIAxes_Sagittal, 'Position', app.dV.pos_Double_AxialSagittal.Sagittal, 'Visible', 'on'); 
                    set(app.Panel_Sagittal,  'Position', app.dV.pos_Panel_Sagittal_Das,            'Visible', 'on'); 
                    set(app.Slider_Sagittal,         'Visible', 'on'); 
                    set(app.Spinner_Sagittal,        'Visible', 'on'); 
                    set(app.Button_select_Sagittal, 'Visible', 'on'); 
                    set(app.Button_home_Sagittal,   'Visible', 'on'); 
                case 'Double_CoronalSagittal'
                  % Coronal axes & controllers
                    set(app.UIAxes_Coronal, 'Position', app.dV.pos_Double_CoronalSagittal.Coronal, 'Visible', 'on'); 
                    set(app.Panel_Coronal,  'Position', app.dV.pos_Panel_Coronal_Dcs,              'Visible', 'on'); 
                    set(app.Slider_Coronal,         'Visible', 'on'); 
                    set(app.Spinner_Coronal,        'Visible', 'on'); 
                    set(app.Button_select_Coronal, 'Visible', 'on'); 
                    set(app.Button_home_Coronal,   'Visible', 'on'); 
                  % Sagittal axes & controllers
                    set(app.UIAxes_Sagittal, 'Position', app.dV.pos_Double_CoronalSagittal.Sagittal, 'Visible', 'on'); 
                    set(app.Panel_Sagittal,  'Position', app.dV.pos_Panel_Sagittal_Dcs,              'Visible', 'on'); 
                    set(app.Slider_Sagittal,         'Visible', 'on'); 
                    set(app.Spinner_Sagittal,        'Visible', 'on'); 
                    set(app.Button_select_Sagittal, 'Visible', 'on'); 
                    set(app.Button_home_Sagittal,   'Visible', 'on'); 
                case 'Triple'
                  % Axial axes & panel
                    set(app.UIAxes_Axial, 'Position', app.dV.pos_Triple.Axial,  'Visible', 'on'); 
                    set(app.Panel_Axial,  'Position', app.dV.pos_Panel_Axial_T, 'Visible', 'on'); 
                    set(app.Slider_Axial,          'Visible', 'on'); 
                    set(app.Spinner_Axial,         'Visible', 'on'); 
                    set(app.Button_select_Axial, 'Visible', 'on'); 
                    set(app.Button_home_Axial,   'Visible', 'on'); 
                  % Coronal axes & panel
                    set(app.UIAxes_Coronal, 'Position', app.dV.pos_Triple.Coronal,  'Visible', 'on'); 
                    set(app.Panel_Coronal,  'Position', app.dV.pos_Panel_Coronal_T, 'Visible', 'on'); 
                    set(app.Slider_Coronal,          'Visible', 'on'); 
                    set(app.Spinner_Coronal,         'Visible', 'on'); 
                    set(app.Button_select_Coronal, 'Visible', 'on'); 
                    set(app.Button_home_Coronal,   'Visible', 'on'); 
                  % Sagittal axes & panel
                    set(app.UIAxes_Sagittal, 'Position', app.dV.pos_Triple.Sagittal,  'Visible', 'on'); 
                    set(app.Panel_Sagittal,  'Position', app.dV.pos_Panel_Sagittal_T, 'Visible', 'on'); 
                    set(app.Slider_Sagittal,         'Visible', 'on'); 
                    set(app.Spinner_Sagittal,        'Visible', 'on'); 
                    set(app.Button_select_Sagittal, 'Visible', 'on'); 
                    set(app.Button_home_Sagittal,   'Visible', 'on'); 
                    
            end
            
            
        end
        
        function plot_image_label(app)
            switch app.tV.display
                case 'Image'
                    switch app.tV.view
                        case 'Single_Axial'
                            plot_image(app, 'Axial'); 
                        case 'Single_Coronal'
                            plot_image(app, 'Coronal'); 
                        case 'Single_Sagittal'
                            plot_image(app, 'Sagittal'); 
                        case 'Double_AxialCoronal'
                            plot_image(app, 'Axial'); 
                            plot_image(app, 'Coronal'); 
                        case 'Double_AxialSagittal'
                            plot_image(app, 'Axial'); 
                            plot_image(app, 'Sagittal'); 
                        case 'Double_CoronalSagittal'
                            plot_image(app, 'Coronal'); 
                            plot_image(app, 'Sagittal'); 
                        case 'Triple'
                            plot_image(app, 'Axial'); 
                            plot_image(app, 'Coronal'); 
                            plot_image(app, 'Sagittal'); 
                    end
                    
                case 'ImageLabel'
                    switch app.tV.view
                        case 'Single_Axial'
                            plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                            plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Axial') 
                                plot_overlay_label(app, 'Axial'); 
                            end
                        case 'Single_Coronal'
                            plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                            plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Coronal') 
                                plot_overlay_label(app, 'Coronal'); 
                            end
                        case 'Single_Sagittal'
                            plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                            plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Sagittal') 
                                plot_overlay_label(app, 'Sagittal'); 
                            end
                        case 'Double_AxialCoronal'
                            plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                            plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Axial') 
                                plot_overlay_label(app, 'Axial'); 
                            end
                            plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                            plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Coronal') 
                                plot_overlay_label(app, 'Coronal'); 
                            end
                        case 'Double_AxialSagittal'
                            plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                            plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Axial') 
                                plot_overlay_label(app, 'Axial'); 
                            end
                            plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                            plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Sagittal') 
                                plot_overlay_label(app, 'Sagittal'); 
                            end
                        case 'Double_CoronalSagittal'
                            plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                            plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Coronal') 
                                plot_overlay_label(app, 'Coronal'); 
                            end
                            plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                            plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Sagittal') 
                                plot_overlay_label(app, 'Sagittal'); 
                            end
                        case 'Triple'
                            plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                            plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Axial') 
                                plot_overlay_label(app, 'Axial'); 
                            end
                            plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                            plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Coronal') 
                                plot_overlay_label(app, 'Coronal'); 
                            end
                            plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                            plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                            if app.tV.overlayMode > 0 && isequal(app.tV.overlayPlane, 'Sagittal') 
                                plot_overlay_label(app, 'Sagittal'); 
                            end
                    end
                    
                case 'Label'
                    switch app.tV.view
                        case 'Single_Axial'
                            plot_label_only(app, 'Axial'); 
                        case 'Single_Coronal'
                            plot_label_only(app, 'Coronal'); 
                        case 'Single_Sagittal'
                            plot_label_only(app, 'Sagittal'); 
                        case 'Double_AxialCoronal'
                            plot_label_only(app, 'Axial'); 
                            plot_label_only(app, 'Coronal'); 
                        case 'Double_AxialSagittal'
                            plot_label_only(app, 'Axial'); 
                            plot_label_only(app, 'Sagittal'); 
                        case 'Double_CoronalSagittal'
                            plot_label_only(app, 'Coronal'); 
                            plot_label_only(app, 'Sagittal'); 
                        case 'Triple'
                            plot_label_only(app, 'Axial'); 
                            plot_label_only(app, 'Coronal'); 
                            plot_label_only(app, 'Sagittal'); 
                    end
            end
%             app.tV.crosshairOn = 0; % Update apploV indicating that the axes are ready to draw crosshair
        end
        
        function plot_image(app, curAxes)
            eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
            img = prepare2Dimages(app); 
            app.data.img = img; 
            switch curAxes
                case 'Axial'
                    curImg = img.curAimg; 
                    tcurAxes.Title.String = ['Axial (Z=' num2str(app.tV.curZ) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Axial_curXLim, 'YLim', app.tV.Axial_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Axial.Value, app.tV.curZ)
                        app.Slider_Axial.Value = app.tV.curZ; 
                        app.Spinner_Axial.Value = app.tV.curZ; 
                    end
                case 'Coronal'
                    curImg = img.curCimg; 
                    tcurAxes.Title.String = ['Coronal (Y=' num2str(app.tV.curY) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Coronal_curXLim, 'YLim', app.tV.Coronal_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Coronal.Value, app.tV.curY)
                        app.Slider_Coronal.Value = app.tV.curY; 
                        app.Spinner_Coronal.Value = app.tV.curY; 
                    end
                case 'Sagittal'
                    curImg = img.curSimg; 
                    tcurAxes.Title.String = ['Sagittal (X=' num2str(app.tV.curX) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Sagittal_curXLim, 'YLim', app.tV.Sagittal_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Coronal.Value, app.tV.curY)
                        app.Slider_Coronal.Value = app.tV.curY; 
                        app.Spinner_Coronal.Value = app.tV.curY; 
                    end
            end
            % hold(tcurAxes, 'on'); h = findobj(tcurAxes, 'Type', 'Image', 'Tag', ''); delete(h); 
            imagesc(tcurAxes, fliplr(rot90(curImg)), app.tV.imgVrange); colormap(tcurAxes,'gray'); 
            hold(tcurAxes, 'off'); axis(tcurAxes, 'off'); 
            if app.tV.crosshairOn == 1
                plotCH(app, curAxes); 
            end
            
        end
        
        
        function plot_label(app, curAxes)
            eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
            img = prepare2Dimages(app); 
            app.data.img = img; 
            switch curAxes
                case 'Axial'
                    curImgT = img.curAimgT; 
                    tcurAxes.Title.String = ['Axial (Z=' num2str(app.tV.curZ) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Axial_curXLim, 'YLim', app.tV.Axial_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Axial.Value, app.tV.curZ)
                        app.Slider_Axial.Value = app.tV.curZ; 
                        app.Spinner_Axial.Value = app.tV.curZ; 
                    end
                case 'Coronal'
                    curImgT = img.curCimgT; 
                    tcurAxes.Title.String = ['Coronal (Y=' num2str(app.tV.curY) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Coronal_curXLim, 'YLim', app.tV.Coronal_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Coronal.Value, app.tV.curY)
                        app.Slider_Coronal.Value = app.tV.curY; 
                        app.Spinner_Coronal.Value = app.tV.curY; 
                    end
                case 'Sagittal'
                    curImgT = img.curSimgT; 
                    tcurAxes.Title.String = ['Sagittal (X=' num2str(app.tV.curX) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Sagittal_curXLim, 'YLim', app.tV.Sagittal_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Sagittal.Value, app.tV.curX)
                        app.Slider_Sagittal.Value = app.tV.curX; 
                        app.Spinner_Sagittal.Value = app.tV.curX; 
                    end
            end
          % Create RGB valued label image from curImgT
            tcurImgT = fliplr(rot90(curImgT)); 
            curImgTrgb = zeros(size(tcurImgT,1), size(tcurImgT,2), 3); 
            eval(['tRGB = app.dV.cCodes(app.data.VT' num2str(app.tV.labelN) '.cCode, :);']); 
                [tX, tY] = find(tcurImgT==app.tV.labelN); 
                    curImgTrgb(tX,tY,1) = tRGB(1); 
                    curImgTrgb(tX,tY,2) = tRGB(2); 
                    curImgTrgb(tX,tY,3) = tRGB(3); 
                curImgTalpha = zeros(size(tcurImgT)); 
                curImgTalpha(tcurImgT==app.tV.labelN) = app.tV.alphaL; 
            % hold(tcurAxes, 'on'); h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
            timg = imagesc(tcurAxes, curImgTrgb); set(timg,'AlphaData', curImgTalpha); hold(tcurAxes, 'off'); 
            timg.Tag = 'Label'; 
            if app.tV.crosshairOn == 1
                plotCH(app, curAxes); 
            end
        end
        
        
        function plot_label_only(app, curAxes)
            eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
            img = prepare2Dimages(app); 
            app.data.img = img; 
            switch curAxes
                case 'Axial'
                    curImgT = img.curAimgT; 
                    tcurAxes.Title.String = ['Axial (Z=' num2str(app.tV.curZ) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Axial_curXLim, 'YLim', app.tV.Axial_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Axial.Value, app.tV.curZ)
                        app.Slider_Axial.Value = app.tV.curZ; 
                        app.Spinner_Axial.Value = app.tV.curZ; 
                    end
                case 'Coronal'
                    curImgT = img.curCimgT; 
                    tcurAxes.Title.String = ['Coronal (Y=' num2str(app.tV.curY) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Coronal_curXLim, 'YLim', app.tV.Coronal_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Coronal.Value, app.tV.curY)
                        app.Slider_Coronal.Value = app.tV.curY; 
                        app.Spinner_Coronal.Value = app.tV.curY; 
                    end
                case 'Sagittal'
                    curImgT = img.curSimgT; 
                    tcurAxes.Title.String = ['Sagittal (X=' num2str(app.tV.curX) ')']; 
                    set(tcurAxes, 'XLim', app.tV.Sagittal_curXLim, 'YLim', app.tV.Sagittal_curYLim, 'Box','on'); 
                    % Update slide & spinner values
                    if ~isequal(app.Slider_Sagittal.Value, app.tV.curX)
                        app.Slider_Sagittal.Value = app.tV.curX; 
                        app.Spinner_Sagittal.Value = app.tV.curX; 
                    end
            end
          % Create RGB valued label image from curImgT
            tcurImgT = fliplr(rot90(curImgT)); 
            curImgTrgb = zeros(size(tcurImgT,1), size(tcurImgT,2), 3); 
            eval(['tRGB = app.dV.cCodes(app.data.VT' num2str(app.tV.labelN) '.cCode, :);']); 
%                 tRGB = app.dV.cCodes(app.tV.labelN,:);             
%                 transRGB = rgbTrans(app); 
                [tX, tY] = find(tcurImgT==app.tV.labelN); 
                    curImgTrgb(tX,tY,1) = tRGB(1); 
                    curImgTrgb(tX,tY,2) = tRGB(2); 
                    curImgTrgb(tX,tY,3) = tRGB(3); 
                curImgTalpha = zeros(size(tcurImgT)); 
                curImgTalpha(tcurImgT==app.tV.labelN) = app.tV.alphaL; 
            blankCurImg = zeros(size(curImgT)); 
            hold(tcurAxes, 'on'); h = findobj(tcurAxes, 'Type', 'Image', 'Tag', ''); delete(h); 
            imagesc(tcurAxes, fliplr(rot90(blankCurImg)), app.tV.imgVrange); colormap(tcurAxes,'gray'); hold(tcurAxes, 'on'); 
            timg = imagesc(tcurAxes, curImgTrgb); set(timg,'AlphaData', curImgTalpha); hold(tcurAxes, 'off'); 
            timg.Tag = 'Label'; 
            if app.tV.crosshairOn == 1
                plotCH(app, curAxes); 
            end
        end
        
        function app = determinePositions(app)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Determine the UIAxes Position parameters for the multiple disply modes
        % < Rules >
        % 1. Calculate the size ratios
        %    - the App Width to Height (aW2H)
        %    - (X,Y,Z) of the MRI volume (y2x, y2z, x2x)
        % 2. Set the normalized margin sizes
        %    - left, buttom, betweenLeft/Right, betweenTop/Bottom
        % 3. Determine the normalized X, Y, Z sizes (Xr, Yr, Zr) based on the Height or Width of the App
        %    - Triple & Single views: based on the Height
        %    - Double views         : based on the Width
        % 4. Set the normalized Position values in consideration of the App Width to Height ratio (aW2H)
        %    - If Xr,Yr,Zr are determined relative to the App Height (Triple & Single views), 
        %      Convert the (Left, Width) Position values by Dividing the (Xr,Yr,Zr) values with the "aW2H"
        %    - If Xr,Yr,Zr are determined relative to the App Width (Double views), 
        %      Convert the (Bottom, Height) Position values by Multiplying the (Xr,Yr,Zr) values with the "aW2H"
            
          % Measure Volume sizes
            V = app.data.V; 
            xyzMax = size(V); 
            nX = xyzMax(1); 
            nY = xyzMax(2); 
            nZ = xyzMax(3); 
            
          % Find the center slices of the axial, coronal, and sagittal planes
            xyzC = round(xyzMax/2); 
            
          % Index matrices of the Original Volume
            oixA = reshape(1:nX*nY, nX, nY); 
            oixC = reshape(1:nX*nZ, nX, nZ); 
            oixS = reshape(1:nY*nZ, nY, nZ); 
            
          % Index matrices of the Display Volume
            dixA = fliplr(rot90(oixA)); 
            dixC = fliplr(rot90(oixC)); 
            dixS = fliplr(rot90(oixS)); 
            
          % Save the variables to app.data
            app.data.xyzC = xyzC; 
            app.data.xyzMax = xyzMax; 
            app.data.nX = nX;  app.data.nY = nY;  app.data.nZ = nZ; 
            app.data.oixA = oixA;  app.data.oixC = oixC;  app.data.oixS = oixS;  
            app.data.dixA = dixA;  app.data.dixC = dixC;  app.data.dixS = dixS;  
            
         % Calculate the size ratio b/t (App Width & Height) (X,Y,Z) of the MRI volume (y2x, y2z, x2z)
            aW2H = app.dV.aW / app.dV.aH; % the App Width to Height ratio (aW2H)
            if aW2H > 2
                aW2H = 2; 
            end
          % Calculate y2x, y2z, z2x ratio Axes sizes
            app.data.y2x = app.data.nY / app.data.nX; 
            app.data.y2z = app.data.nY / app.data.nZ; 
            app.data.z2x = app.data.nZ / app.data.nX; 
            app.data.x2y = app.data.nX / app.data.nY; 
            app.data.z2y = app.data.nZ / app.data.nY; 
            app.data.x2z = app.data.nX / app.data.nZ; 
            
         % Set the default normalized margin sizes for Axes (am)
            % Set the default margin (m) for Left/Right, Top/Bottom, and between display elements
%             app.dV.w_LP = app.dV.LP_w / app.dV.aW; 
            
%             app.dV.m = .01; 
            app.dV.am = 0; %.015; 
            app.dV.amt = 0; %.03; % Axes margin for Top in consideration of the "Title" space 
            app.dV.am4b = 0; %.04; %.035; %.03; % margin for the Axes Buttons 
            app.dV.aL2R = app.dV.am + app.dV.am4b; % margin for Between Left & Right Axes
            
%             app.dV.aL = app.dV.m + app.dV.w_LP + app.dV.am; % Left for all Axes
            app.dV.aB = app.dV.am; % Bottom for all Axes

            aW = app.dV.aW; aH = app.dV.aH; 
            
            app.dV.aL = app.dV.LP_Rm + app.dV.LP_w + app.dV.am; % Left for all Axes
            app.dV.tW = aW - app.dV.aL; 
            
          % Set the default normalized sizes of Axes Panels
%             app.dV.apW = .04; 


%             dV = app.dV; 
            
%             AxesPanel_H = 40; 
            AxesPanel_W = 50; 
            
         %% Triple view: Calculate Xr, Yr, Zr relative to the Height of the App GUI
            posT.Coronal_H  = app.dV.aH / 2; % <- Z
            posT.Coronal_W  = posT.Coronal_H * app.data.x2z; % <- X
            posT.Sagittal_H = posT.Coronal_H; % <- Z
            posT.Sagittal_W = posT.Coronal_H * app.data.y2z; % <- Y
            posT.Axial_H    = posT.Sagittal_W; % <- Y
            posT.Axial_W    = posT.Coronal_W; % <- X
                posT.adjHa = (app.dV.aH - posT.Axial_H) / 2; % Calculate the height adjustment values for Axial
                posT.adjWc = (posT.Sagittal_W - posT.Coronal_W) / 2; % Calculate the width adjustment values for Coronal

%             posT.Zr = (1 - (app.dV.am + app.dV.amt)) / 2; % relative to Height
%             posT.Yr = posT.Zr * app.data.y2z; 
%             posT.Xr = posT.Zr * app.data.z2x; 
%                 posT.adjHa = (1 - (posT.Yr + app.dV.aB)) / 2; % Calculate the height adjustment values for Axial
%                 posT.adjWc = (posT.Yr - posT.Xr) / 2; % Calculate the width adjustment values for Coronal
%             posT.Axial_W    = (posT.Xr/aW2H)*aW; 
%             posT.Axial_H    = (posT.Yr)*aH; 
%             posT.Coronal_W  = (posT.Xr/aW2H)*aW; 
%             posT.Coronal_H  = (posT.Zr)*aH; 
%             posT.Sagittal_W = (posT.Yr/aW2H)*aW; 
%             posT.Sagittal_H = (posT.Zr)*aH; 
            
            app.dV.pos_Triple.Axial    = [app.dV.aL,                                               posT.adjHa,       posT.Axial_W,     posT.Axial_H]; 
            app.dV.pos_Triple.Coronal  = [app.dV.aL + posT.Axial_W + AxesPanel_W*.5 + posT.adjWc,  posT.Sagittal_H,  posT.Coronal_W,   posT.Coronal_H]; 
            app.dV.pos_Triple.Sagittal = [app.dV.aL + posT.Axial_W + AxesPanel_W*.5                0,                posT.Sagittal_W,  posT.Sagittal_H]; 
                app.dV.pos_Panel_Axial_T    = [app.dV.pos_Triple.Axial(1)    + app.dV.pos_Triple.Axial(3),     app.dV.pos_Triple.Axial(2) + posT.Axial_H*0.1,        AxesPanel_W,   app.dV.pos_Triple.Axial(4)*.8]; 
                app.dV.pos_Panel_Coronal_T  = [app.dV.pos_Triple.Coronal(1)  + app.dV.pos_Triple.Coronal(3),   app.dV.pos_Triple.Coronal(2) + posT.Coronal_H*0.1,    AxesPanel_W,   app.dV.pos_Triple.Coronal(4)*.8]; 
                app.dV.pos_Panel_Sagittal_T = [app.dV.pos_Triple.Sagittal(1) + app.dV.pos_Triple.Sagittal(3),  app.dV.pos_Triple.Sagittal(2) + posT.Sagittal_H*0.1,  AxesPanel_W,   app.dV.pos_Triple.Sagittal(4)*.8]; 
%             app.dV.pos_Triple.Axial    = [app.dV.aL,                                                                    (app.dV.aB + posT.adjHa         )*aH,  posT.Axial_W,  posT.Axial_H]; 
%             app.dV.pos_Triple.Coronal  = [app.dV.aL + ((posT.Xr + posT.adjWc)/aW2H + app.dV.aL2R)*aW + AxesPanel_W*.5,  (app.dV.aB + posT.Zr + app.dV.am)*aH,  posT.Coronal_W,  posT.Coronal_H]; 
%             app.dV.pos_Triple.Sagittal = [app.dV.aL + (posT.Xr/aW2H + app.dV.aL2R               )*aW + AxesPanel_W*.5,  (app.dV.aB                      )*aH,  posT.Sagittal_W,  posT.Sagittal_H]; 
%                 app.dV.pos_Panel_Axial_T    = [app.dV.pos_Triple.Axial(1)    + app.dV.pos_Triple.Axial(3),     app.dV.pos_Triple.Axial(2) + posT.Axial_H*0.1,     AxesPanel_W,   app.dV.pos_Triple.Axial(4)*.8]; 
%                 app.dV.pos_Panel_Coronal_T  = [app.dV.pos_Triple.Coronal(1)  + app.dV.pos_Triple.Coronal(3),   app.dV.pos_Triple.Coronal(2) + posT.Coronal_H*0.1,   AxesPanel_W,   app.dV.pos_Triple.Coronal(4)*.8]; 
%                 app.dV.pos_Panel_Sagittal_T = [app.dV.pos_Triple.Sagittal(1) + app.dV.pos_Triple.Sagittal(3),  app.dV.pos_Triple.Sagittal(2) + posT.Coronal_H*0.1,  AxesPanel_W,   app.dV.pos_Triple.Sagittal(4)*.8]; 
            app.dV.posT = posT; 
            
         %% Double views
          % Double_AxialCoronal view: Calculate Xr, Yr, Zr relative to the Width of the App GUI
            posDac.Axial_W = (app.dV.aW - (app.dV.aL + AxesPanel_W*1.6))/2; 
            posDac.Axial_H = posDac.Axial_W * app.data.y2x; 
            posDac.Coronal_W = posDac.Axial_W; 
            posDac.Coronal_H = posDac.Axial_W * app.data.z2x; 
%             posDac.Xr = (1 - (app.dV.aL + app.dV.aL2R + app.dV.am4b)) / 2; 
%             posDac.Yr = posDac.Xr * app.data.y2x; 
%             posDac.Zr = posDac.Xr * app.data.z2x; 
%             posDac.Yr = (1 - (app.dV.aL + app.dV.aL2R + app.dV.am4b)) / 2; %(1+app.data.y2x); % relative to Width
%             posDac.Xr = posDac.Yr / app.data.y2x; 
%             posDac.Zr = posDac.Xr * app.data.z2x; 
                posDac.adjHa = (app.dV.aH - posDac.Axial_H) / 2; 
                posDac.adjHc = (app.dV.aH - posDac.Coronal_H) / 2; 
%                 posDac.adjHa = (1 - posDac.Yr*aW2H) / 2; % Calculate the height adjustment values for Axial & Coronal
%                 posDac.adjHc = (1 - posDac.Zr*aW2H) / 2; 
%             posDac.Axial_W = (posDac.Xr)*aW; 
%             posDac.Axial_H = (posDac.Yr*aW2H)*aH; 
%             posDac.Coronal_W = (posDac.Xr)*aW; 
%             posDac.Coronal_H = (posDac.Zr*aW2H)*aH; 

            app.dV.pos_Double_AxialCoronal.Axial   = [app.dV.aL,                                    posDac.adjHa,  posDac.Axial_W,  posDac.Axial_H]; 
            app.dV.pos_Double_AxialCoronal.Coronal = [app.dV.aL + posDac.Axial_W + AxesPanel_W*.5,  posDac.adjHc,  posDac.Coronal_W,  posDac.Coronal_H]; 
%             app.dV.pos_Double_AxialCoronal.Axial   = [app.dV.aL                          ,  (app.dV.aB + posDac.adjHa)*aH,  posDac.Axial_W,  posDac.Axial_H]; 
%             app.dV.pos_Double_AxialCoronal.Coronal = [app.dV.aL + posDac.Xr + app.dV.aL2R,  (app.dV.aB + posDac.adjHc)*aH,  posDac.Coronal_W,  posDac.Coronal_H]; 
                app.dV.pos_Panel_Axial_Dac   = [app.dV.pos_Double_AxialCoronal.Axial(1)   + app.dV.pos_Double_AxialCoronal.Axial(3) ,    app.dV.pos_Double_AxialCoronal.Axial(2) + posDac.Axial_H*0.1,      AxesPanel_W,  app.dV.pos_Double_AxialCoronal.Axial(4)*.8]; 
                app.dV.pos_Panel_Coronal_Dac = [app.dV.pos_Double_AxialCoronal.Coronal(1) + app.dV.pos_Double_AxialCoronal.Coronal(3) ,  app.dV.pos_Double_AxialCoronal.Coronal(2) + posDac.Coronal_H*0.1,  AxesPanel_W,  app.dV.pos_Double_AxialCoronal.Coronal(4)*.8]; 
            
          % Double_AxialSagittal view: Calculate Xr, Yr, Zr relative to the Width of the App GUI
            posDas.Axial_W = (app.dV.tW -AxesPanel_W*1.6) / (1 + app.data.y2x); % <- X
            posDas.Axial_H = posDas.Axial_W * app.data.y2x; % <- Y 
            posDas.Sagittal_W = posDas.Axial_H; % <- Y
            posDas.Sagittal_H = posDas.Axial_W * app.data.z2x; % <- Z
                posDas.adjHa = (app.dV.aH - posDas.Axial_H) / 2; 
                posDas.adjHs = (app.dV.aH - posDas.Sagittal_H) / 2; 
%             posDas.Xr = (1 - (app.dV.aL + app.dV.aL2R + app.dV.am4b)) / (1+app.data.y2x); % relative to Width
%             posDas.Yr = posDas.Xr * app.data.y2x; 
%             posDas.Zr = posDas.Xr * app.data.z2x; 
%                 posDas.adjHa = (1 - posDas.Yr*aW2H) / 2; % Calculate the height adjustment values for Axial & Sagittal
%                 posDas.adjHs = (1 - posDas.Zr*aW2H) / 2; 
            app.dV.pos_Double_AxialSagittal.Axial    = [app.dV.aL,                                    posDas.adjHa,  posDas.Axial_W,     posDas.Axial_H]; 
            app.dV.pos_Double_AxialSagittal.Sagittal = [app.dV.aL + posDas.Axial_W + AxesPanel_W*.5,  posDas.adjHs,  posDas.Sagittal_W,  posDas.Sagittal_H]; 
%             app.dV.pos_Double_AxialSagittal.Axial    = [(app.dV.aL)*aW,                            (app.dV.aB + posDas.adjHa)*aH + AxesPanel_H,  (posDas.Xr)*aW,  (posDas.Yr*aW2H)*aH - AxesPanel_H]; 
%             app.dV.pos_Double_AxialSagittal.Sagittal = [(app.dV.aL + posDas.Xr + app.dV.aL2R)*aW,  (app.dV.aB + posDas.adjHs)*aH + AxesPanel_H,  (posDas.Yr)*aW,  (posDas.Zr*aW2H)*aH - AxesPanel_H]; 
                app.dV.pos_Panel_Axial_Das    = [app.dV.pos_Double_AxialSagittal.Axial(1)    + app.dV.pos_Double_AxialSagittal.Axial(3),     app.dV.pos_Double_AxialSagittal.Axial(2) + posDas.Axial_H*0.1,        AxesPanel_W,   app.dV.pos_Double_AxialSagittal.Axial(4)*.8]; 
                app.dV.pos_Panel_Sagittal_Das = [app.dV.pos_Double_AxialSagittal.Sagittal(1) + app.dV.pos_Double_AxialSagittal.Sagittal(3),  app.dV.pos_Double_AxialSagittal.Sagittal(2) + posDas.Sagittal_H*0.1,  AxesPanel_W,   app.dV.pos_Double_AxialSagittal.Sagittal(4)*.8]; 
            
          % Double_CoronalSagittal view: Calculate Xr, Yr, Zr relative to the Width of the App GUI
            posDcs.Coronal_W = (app.dV.tW -AxesPanel_W*1.6) / (1 + app.data.y2x); % <- X
            posDcs.Coronal_H = posDcs.Coronal_W * app.data.z2x; % <- Z 
            posDcs.Sagittal_W = posDcs.Coronal_W * app.data.y2x; % <- Y
            posDcs.Sagittal_H = posDcs.Coronal_H; % <- Z
                posDcs.adjHc = (app.dV.aH - posDcs.Coronal_H) / 2; 
                posDcs.adjHs = (app.dV.aH - posDcs.Sagittal_H) / 2; 
%             posDcs.Xr = (1 - (app.dV.aL + app.dV.aL2R + app.dV.am4b)) / (1+app.data.y2x); % relative to Width
%             posDcs.Yr = posDcs.Xr * app.data.y2x; 
%             posDcs.Zr = posDcs.Xr * app.data.z2x; 
%             % Calculate the height adjustment values for Coronal & Sagittal
%             posDcs.adjHcs = (1 - posDcs.Zr*aW2H) / 2;             
            % Set Position of Double_CoronalSagittal
            app.dV.pos_Double_CoronalSagittal.Coronal  = [app.dV.aL                   ,  posDcs.adjHc,  posDcs.Coronal_W,   posDcs.Coronal_H]; 
            app.dV.pos_Double_CoronalSagittal.Sagittal = [app.dV.aL + posDcs.Coronal_W + AxesPanel_W*.5,  posDcs.adjHs,  posDcs.Sagittal_W,  posDcs.Sagittal_H]; 
                app.dV.pos_Panel_Coronal_Dcs  = [app.dV.pos_Double_CoronalSagittal.Coronal(1)  + app.dV.pos_Double_CoronalSagittal.Coronal(3),   app.dV.pos_Double_CoronalSagittal.Coronal(2) + posDcs.Coronal_H*0.1,    AxesPanel_W,   app.dV.pos_Double_CoronalSagittal.Coronal(4)*.8]; 
                app.dV.pos_Panel_Sagittal_Dcs = [app.dV.pos_Double_CoronalSagittal.Sagittal(1) + app.dV.pos_Double_CoronalSagittal.Sagittal(3),  app.dV.pos_Double_CoronalSagittal.Sagittal(2) + posDcs.Sagittal_H*0.1,  AxesPanel_W,   app.dV.pos_Double_CoronalSagittal.Sagittal(4)*.8]; 
            app.dV.posDac = posDac; 
            app.dV.posDas = posDas; 
            app.dV.posDcs = posDcs; 
            
         %% Single views: Calculate Xr, Yr, Zr relative to the Height of theh App GUI
          % Single_Axial view
            posSa.H = app.dV.aH; % <- Y
            posSa.W = posSa.H * app.data.x2y; % <- X
%             posSa.Yr = 1 - (app.dV.am + app.dV.amt); 
%             posSa.Xr = posSa.Yr / app.data.y2x; 
            app.dV.pos_Single_Axial = [app.dV.aL,  0,  posSa.W,  posSa.H]; 
                app.dV.pos_Panel_Axial_S    = [app.dV.pos_Single_Axial(1) + app.dV.pos_Single_Axial(3),        app.dV.pos_Single_Axial(2) + posSa.H*0.1,     AxesPanel_W,   app.dV.pos_Single_Axial(4)*.8]; 
%             app.dV.pos_Single_Axial = [(app.dV.aL)*aW,  (app.dV.aB)*aH + AxesPanel_H,  (posSa.Xr/aW2H)*aW,  (posSa.Yr)*aH - AxesPanel_H]; 
%                 app.dV.pos_Panel_Axial_S    = [app.dV.pos_Single_Axial(1) + app.dV.pos_Single_Axial(3),        app.dV.pos_Single_Axial(2) + posT.Axial_H*0.1,     AxesPanel_W,   app.dV.pos_Single_Axial(4)*.8]; 
% %                 app.dV.pos_Panel_Axial_T    = [app.dV.pos_Triple.Axial(1)    + app.dV.pos_Triple.Axial(3)*.05,     app.dV.pos_Triple.Axial(2)    - AxesPanel_H *.8,   app.dV.pos_Triple.Axial(3)    * .9,   AxesPanel_H]; 
% %                 app.dV.pos_Panel_Coronal_T  = [app.dV.pos_Triple.Coronal(1)  + app.dV.pos_Triple.Coronal(3)*.05,   app.dV.pos_Triple.Coronal(2)  - AxesPanel_H *.8,   app.dV.pos_Triple.Coronal(3)  * .9,   AxesPanel_H]; 
% %                 app.dV.pos_Panel_Sagittal_T = [app.dV.pos_Triple.Sagittal(1) + app.dV.pos_Triple.Sagittal(3)*.05,  app.dV.pos_Triple.Sagittal(2) - AxesPanel_H *.8,   app.dV.pos_Triple.Sagittal(3) * .9,   AxesPanel_H]; 
            app.dV.posSa = posSa; 
          % Single_Coronal view
            posSc.H = app.dV.aH; % <- Y
            posSc.W = posSc.H * app.data.z2x; % <- X
%             posSc.Zr = 1 - (app.dV.am + app.dV.amt); 
%             posSc.Xr = posSc.Zr / app.data.z2x; 
            app.dV.pos_Single_Coronal = [app.dV.aL,  0,  posSc.W,  posSc.H]; 
                app.dV.pos_Panel_Coronal_S  = [app.dV.pos_Single_Coronal(1) + app.dV.pos_Single_Coronal(3),    app.dV.pos_Single_Coronal(2) + posSc.H*0.1,   AxesPanel_W,   app.dV.pos_Single_Coronal(4)*.8]; 
            app.dV.posSc = posSc; 
          % Single_Sagittal view
            posSs.H = app.dV.aH; % <- Z
            posSs.W = posSs.H * app.data.y2z; % <- Y
%             posSs.Zr = 1 - (app.dV.am + app.dV.amt); 
%             posSs.Yr = posSs.Zr * app.data.y2z; 
            app.dV.pos_Single_Sagittal = [app.dV.aL,  0,  posSs.W,  posSs.H]; 
%             app.dV.pos_Single_Sagittal = [(app.dV.aL)*aW,  (app.dV.aB)*aH + AxesPanel_H,  (posSs.Yr/aW2H)*aW,  (posSs.Zr)*aH] - AxesPanel_H; 
                app.dV.pos_Panel_Sagittal_S = [app.dV.pos_Single_Sagittal(1) + app.dV.pos_Single_Sagittal(3),  app.dV.pos_Single_Sagittal(2) + posSs.H*0.1,  AxesPanel_W,   app.dV.pos_Single_Sagittal(4)*.8]; 
            app.dV.posSs = posSs; 
            
        end
        
        
        function curAxes = determineCurAxes(app, dPoint)
          % Define the ranges of the X and Y axes
            aXrange = [0, app.UIAxes_Axial.Position(3)] + app.UIAxes_Axial.Position(1); 
            aYrange = [0, app.UIAxes_Axial.Position(4)] + app.UIAxes_Axial.Position(2); 
            cXrange = [0, app.UIAxes_Coronal.Position(3)] + app.UIAxes_Coronal.Position(1); 
            cYrange = [0, app.UIAxes_Coronal.Position(4)] + app.UIAxes_Coronal.Position(2); 
            sXrange = [0, app.UIAxes_Sagittal.Position(3)] + app.UIAxes_Sagittal.Position(1); 
            sYrange = [0, app.UIAxes_Sagittal.Position(4)] + app.UIAxes_Sagittal.Position(2); 
            
            switch app.tV.view
                case 'Triple'
                  % Determine the current axes of the mouse point (dPoint)
                    if dPoint(1) > aXrange(1) && dPoint(1) < aXrange(2) && dPoint(2) > aYrange(1) && dPoint(2) < aYrange(2)
                        curAxes = 'Axial'; 
                    elseif dPoint(1) > cXrange(1) && dPoint(1) < cXrange(2) && dPoint(2) > cYrange(1) && dPoint(2) < cYrange(2)
                        curAxes = 'Coronal'; 
                    elseif dPoint(1) > sXrange(1) && dPoint(1) < sXrange(2) && dPoint(2) > sYrange(1) && dPoint(2) < sYrange(2)
                        curAxes = 'Sagittal'; 
                    else
                        curAxes = 'None'; 
                    end
                case 'Double_AxialCoronal'
                    if dPoint(1) > aXrange(1) && dPoint(1) < aXrange(2) && dPoint(2) > aYrange(1) && dPoint(2) < aYrange(2)
                        curAxes = 'Axial'; 
                    elseif dPoint(1) > cXrange(1) && dPoint(1) < cXrange(2) && dPoint(2) > cYrange(1) && dPoint(2) < cYrange(2)
                        curAxes = 'Coronal'; 
                    else
                        curAxes = 'None'; 
                    end
                case 'Double_AxialSagittal'
                    if dPoint(1) > aXrange(1) && dPoint(1) < aXrange(2) && dPoint(2) > aYrange(1) && dPoint(2) < aYrange(2)
                        curAxes = 'Axial'; 
                    elseif dPoint(1) > sXrange(1) && dPoint(1) < sXrange(2) && dPoint(2) > sYrange(1) && dPoint(2) < sYrange(2)
                        curAxes = 'Sagittal'; 
                    else
                        curAxes = 'None'; 
                    end
                case 'Double_CoronalSagittal'
                    if dPoint(1) > cXrange(1) && dPoint(1) < cXrange(2) && dPoint(2) > cYrange(1) && dPoint(2) < cYrange(2)
                        curAxes = 'Coronal'; 
                    elseif dPoint(1) > sXrange(1) && dPoint(1) < sXrange(2) && dPoint(2) > sYrange(1) && dPoint(2) < sYrange(2)
                        curAxes = 'Sagittal'; 
                    else
                        curAxes = 'None'; 
                    end
                case 'Single_Axial'
                    if dPoint(1) > aXrange(1) && dPoint(1) < aXrange(2) && dPoint(2) > aYrange(1) && dPoint(2) < aYrange(2)
                        curAxes = 'Axial'; 
                    else
                        curAxes = 'None'; 
                    end
                case 'Single_Coronal'
                    if dPoint(1) > cXrange(1) && dPoint(1) < cXrange(2) && dPoint(2) > cYrange(1) && dPoint(2) < cYrange(2)
                        curAxes = 'Coronal'; 
                    else
                        curAxes = 'None'; 
                    end
                case 'Single_Sagittal'
                    if dPoint(1) > sXrange(1) && dPoint(1) < sXrange(2) && dPoint(2) > sYrange(1) && dPoint(2) < sYrange(2)
                        curAxes = 'Sagittal'; 
                    else
                        curAxes = 'None'; 
                    end
            end
        end
        
%         function crosshairlistnerA(app, src, data)
%             chPos = data.CurrentPosition; 
%             app.tV.curCHpoint = [chPos(1), chPos(2), app.tV.curZ]; 
%         end
% 
%         function crosshairlistnerC(app, src, data)
%             chPos = data.CurrentPosition; 
%             app.tV.curCHpoint = [chPos(1), app.dV.curY, chPos(2)]; 
%         end
% 
%         function crosshairlistnerS(app, src, data)
%             chPos = data.CurrentPosition; 
%             app.tV.curCHpoint = [app.dV.curX, chPos(1), chPos(2)]; 
%         end


        function [curAxes, app] = plotCH(app, source)
            if ~ischar(source) % from the displayed image
                  % Determine if the current mouse point is inside of an Axes
                    dPoint = get(app.UIFigure,'CurrentPoint'); 
                    curAxes = determineCurAxes(app, dPoint); 
                    
        %             clickType = get(app.UIFigure, 'SelectionType'); 
        %             app.tV.LeftMouseDown = true; 

                    if ~isequal(curAxes, 'None')
                        eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
                        eval(['curXLim = app.UIAxes_' curAxes '.XLim;']); 
                        eval(['curYLim = app.UIAxes_' curAxes '.YLim;']); 
                        eval(['curPoint = app.UIAxes_' curAxes '.CurrentPoint;']); 
                        x = round(curPoint(1,1)); y = round(curPoint(1,2)); 

                        hSx = curXLim(1); 
                        hEx = curXLim(2); 
                        vSy = curYLim(1); 
                        vEy = curYLim(2); 
                        minAxesSize = min([diff(curXLim), diff(curYLim)]); 
                        hSsize = 1 * minAxesSize/100; % <--------------------------------
%                         hSsize = 5; 
                        
                        Lc = x-hSsize; Rc = x+hSsize; Br = y-hSsize; Tr = y+hSsize; 
                        
                      % First delete any existing crosshair lines
                        h = findobj(tcurAxes, 'Type', 'Line'); delete(h); 
                        
                      % plot central square 
                        hold(tcurAxes, 'on'); 
                        plot(tcurAxes, [Lc,Rc], [Br,Br], 'r', 'LineWidth', .5); 
                        plot(tcurAxes, [Lc,Rc], [Tr,Tr], 'r', 'LineWidth', .5); 
                        plot(tcurAxes, [Lc,Lc], [Br,Tr], 'r', 'LineWidth', .5); 
                        plot(tcurAxes, [Rc,Rc], [Br,Tr], 'r', 'LineWidth', .5); 
                      % Plot horizontal & vertical lines
                        plot(tcurAxes, [hSx, Lc], [y,y],     'r', 'LineWidth', .5); 
                        plot(tcurAxes, [Rc, hEx], [y,y],     'r', 'LineWidth', .5); 
                        plot(tcurAxes, [x, x],    [vSy, Br], 'r', 'LineWidth', .5); 
                        plot(tcurAxes, [x, x],    [Tr, vEy],  'r', 'LineWidth', .5); 
                        hold(tcurAxes, 'off'); 
                        switch curAxes
                            case 'Axial'
                                app.tV.curX = (app.data.nX - x)+1; 
                                app.tV.curY = (app.data.nY - y)+1; 
                            case 'Coronal'
                                app.tV.curX = (app.data.nX - x)+1; 
                                app.tV.curZ = (app.data.nZ - y)+1; 
                            case 'Sagittal'
                                app.tV.curY = (app.data.nY - x)+1; 
                                app.tV.curZ = (app.data.nZ - y)+1; 
                        end
                       
                    end
%                     [app.tV.curX, app.tV.curY, app.tV.curZ]
            else
                curAxes = source; 
                eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
                eval(['curXLim = app.UIAxes_' curAxes '.XLim;']); 
                eval(['curYLim = app.UIAxes_' curAxes '.YLim;']); 
                hSx = curXLim(1); 
                hEx = curXLim(2); 
                vSy = curYLim(1); 
                vEy = curYLim(2); 
                minAxesSize = min([diff(curXLim), diff(curYLim)]); 
                hSsize = 1 * minAxesSize/100; % <--------------------------------
%                 hSsize = 5; 
                % eval(['curPoint = app.UIAxes_' curAxes '.CurrentPoint;']); 
                % x = curPoint(1,1); y = curPoint(1,2); 
                switch curAxes
                    case 'Axial'
                        x = (app.data.nX - app.tV.curX)+1; 
                        y = (app.data.nY - app.tV.curY)+1;                                
                    case 'Coronal'
                        x = (app.data.nX - app.tV.curX)+1; 
                        y = (app.data.nZ - app.tV.curZ)+1; 
                    case 'Sagittal'
                        x = (app.data.nY - app.tV.curY)+1; 
                        y = (app.data.nZ - app.tV.curZ)+1; 
                end
                Lc = x-hSsize; Rc = x+hSsize; Br = y-hSsize; Tr = y+hSsize; 
                
              % First delete any existing crosshair lines
                h = findobj(tcurAxes, 'Type', 'Line'); delete(h); 
                
              % plot central square 
                hold(tcurAxes, 'on'); 
                plot(tcurAxes, [Lc,Rc], [Br,Br], 'r', 'LineWidth', .5); 
                plot(tcurAxes, [Lc,Rc], [Tr,Tr], 'r', 'LineWidth', .5); 
                plot(tcurAxes, [Lc,Lc], [Br,Tr], 'r', 'LineWidth', .5); 
                plot(tcurAxes, [Rc,Rc], [Br,Tr], 'r', 'LineWidth', .5); 
              % Plot horizontal & vertical lines
                plot(tcurAxes, [hSx, Lc], [y,y],     'r', 'LineWidth', .5); 
                plot(tcurAxes, [Rc, hEx], [y,y],     'r', 'LineWidth', .5); 
                plot(tcurAxes, [x, x],    [vSy, Br], 'r', 'LineWidth', .5); 
                plot(tcurAxes, [x, x],    [Tr, vEy],  'r', 'LineWidth', .5); 
                hold(tcurAxes, 'off'); 
            end
            app.tV.crosshairOn = 1; 
            eval(['app.tV.chS_' curAxes ' = [Lc, Rc, Br, Tr];']); 
        end
        
        
        function plot_overlay_label(app, curAxes)
            eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
            switch curAxes
                case 'Axial'
                    curImgT = squeeze(app.data.cur_VT(:,:, app.tV.curZ + app.tV.sliceNchange)); % <-- extract adjacent slice label information at the beginning of overlay editting
                case 'Coronal'
                    curImgT = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); % <-- extract adjacent slice label information at the beginning of overlay editting
                case 'Sagittal'
                    curImgT = squeeze(app.data.cur_VT(app.tV.curX + app.tV.sliceNchange, :, :)); % <-- extract adjacent slice label information at the beginning of overlay editting
            end
            
          % Create RGB valued label image from curImgT
            tcurImgT = fliplr(rot90(curImgT)); 
            curImgTrgb = zeros(size(tcurImgT,1), size(tcurImgT,2), 3); 
                [tX, tY] = find(tcurImgT==app.tV.labelN); 
                    curImgTrgb(tX,tY,1) = app.tV.showColor(1); 
                    curImgTrgb(tX,tY,2) = app.tV.showColor(2); 
                    curImgTrgb(tX,tY,3) = app.tV.showColor(3); % Blue color for "Show"
                curImgTalpha = zeros(size(tcurImgT)); 
                curImgTalpha(tcurImgT==app.tV.labelN) = app.tV.overlayAlphaL; 
            hold(tcurAxes, 'on'); 
            h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); delete(h); 
            timg = imagesc(tcurAxes, curImgTrgb); set(timg,'AlphaData', curImgTalpha); 
            timg.Tag = 'OverlayLabel'; 
            if ~isempty(tX)            
                x4text = tcurAxes.XLim(1)+3; 
                y4text = tcurAxes.YLim(2)-3; 
                h = findobj(tcurAxes, 'Type', 'Text'); delete(h); 
                text(tcurAxes, x4text, y4text, ['Overlay Label: ' curAxes ' (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.showColor); 
            end
            hold(tcurAxes, 'off'); 
        end
        
        
        function plot_overlayEdit_label(app, curAxes)
            eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
            switch curAxes
                case 'Axial'
                    if ~isequal(app.tV.curZ, app.data.oSTa.sliceN) % when it proceeds to the next slice
                        curImgT = squeeze(app.data.cur_VT(:,:, app.tV.curZ + app.tV.sliceNchange)); % <-- extract adjacent slice label information at the beginning of overlay editting
                    else % editing began on the current slice
                        curImgT = app.data.cur_oSTa; 
                    end
                case 'Coronal'
                    if ~isequal(app.tV.curY, app.data.oSTc.sliceN) % when it proceeds to the next slice
                        curImgT = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); % <-- extract adjacent slice label information at the beginning of overlay editting
                    else % editing began on the current slice
                        curImgT = app.data.cur_oSTc; 
                    end
%                     curImgT = app.data.cur_oSTc; 
%                     if isempty(find(curImgT==app.tV.labelN)) || ~isequal(app.tV.curY, app.data.oSTc.sliceN)
%                         curImgT = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); % <-- extract adjacent slice label information at the beginning of overlay editting
%                     end
                case 'Sagittal'
                    if ~isequal(app.tV.curX, app.data.oSTs.sliceN) % when it proceeds to the next slice
                        curImgT = squeeze(app.data.cur_VT(app.tV.curX + app.tV.sliceNchange, :, :)); % <-- extract adjacent slice label information at the beginning of overlay editting
                    else % editing began on the current slice
                        curImgT = app.data.cur_oSTs; 
                    end
%                     curImgT = app.data.cur_oSTs; 
%                     if isempty(find(curImgT==app.tV.labelN)) || ~isequal(app.tV.curX, app.data.oSTs.sliceN)
%                         curImgT = squeeze(app.data.cur_VT(app.tV.curX + app.tV.sliceNchange, :, :)); % <-- extract adjacent slice label information at the beginning of overlay editting
%                     end
            end
            if ~isempty(find(curImgT>0))
              % Create RGB valued label image from curImgT
                tcurImgT = fliplr(rot90(curImgT)); 
                curImgTrgb = zeros(size(tcurImgT,1), size(tcurImgT,2), 3); 
                    [tX, tY] = find(tcurImgT==app.tV.labelN); 
                        curImgTrgb(tX,tY,1) = app.tV.editColor(1); 
                        curImgTrgb(tX,tY,2) = app.tV.editColor(2); % Green color for "Edit"
                        curImgTrgb(tX,tY,3) = app.tV.editColor(3); 
                    curImgTalpha = zeros(size(tcurImgT)); 
                    curImgTalpha(tcurImgT==app.tV.labelN) = app.tV.overlayAlphaL; 
                hold(tcurAxes, 'on'); 
                h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); delete(h); 
                timg = imagesc(tcurAxes, curImgTrgb); set(timg,'AlphaData', curImgTalpha); 
                timg.Tag = 'OverlayLabel'; 
                if ~isempty(tX) 
                    x4text = tcurAxes.XLim(1)+3; 
                    y4text = tcurAxes.YLim(2)-3; 
                    h = findobj(tcurAxes, 'Type', 'Text'); delete(h); 
                    text(tcurAxes, x4text, y4text, ['Overlay Edit Label: ' curAxes ' (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.editColor); 
                end
                hold(tcurAxes, 'off'); 
            end
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.Name = 'MRItrace'; 
            
          % Set the [x,y,z] axes display ratio to be equal
            app.UIAxes_Axial.DataAspectRatio = [1, 1, 1]; 
            app.UIAxes_Coronal.DataAspectRatio = [1, 1, 1]; 
            app.UIAxes_Sagittal.DataAspectRatio = [1, 1, 1]; 
            
          % Clear all axes & control components  
            clear_AxesControls(app); 
            
            app.dV.mriLoaded = 0; % Set to 0 for preventing errors caused by plotting MRIs with operatiing the DisplayParameter Panel before MRI is loaded
            
            app.tV.display = 'Image'; 
            app.tV.view = 'Triple'; 
            app.UIAxes_imgHist.Visible = 0; 
            
          % 1. Determine "Positions" of the App window
            d=get(0,'screensize'); 
            sW = d(3); sH = d(4); % screen Width & Height
            sW2H = sW / sH; % screen Width to Height ratio
            if sW2H > 2
                sW2H = 2; % Limit the maximum W:H ratio to 2.0
            end
            app.dV.sW = sW; 
            app.dV.sH = sH; 
            app.dV.sW2H = sW2H; 
            app.UIFigure.Units = 'pixels'; 
            
          % 2. Determine the App GUI size based on the current computer screen size
            % - App [W:H] ratio = 16:9
            % - Set the App Height (aH) to be 75% (3/4) of the screen height (sH)
            % - Set the App Width (aW) to be (aH * 16/9)
            if sH*.80 >= 950
                aH = sH * .80; % sH * .590
                aW = aH * sW2H; 
                app.UIFigure.Position = [.05*sW, .15*sH, aW, aH]; 
            elseif sH*.95 <=950
                aH = sH*.90; 
                aW = aH * sW2H; 
                app.UIFigure.Position = [.05*sW, .05*sH, aW, aH]; 
            end
            app.dV.aW = aW; 
            app.dV.aH = aH; 

          % 3. Set the Positions of the left display option panels
            app.Panel_DisplayParameters.Units   = 'pixels'; 
            app.ButtonGroup_DisplayOption.Units = 'pixels'; 
            app.Panel_TracingLabels.Units       = 'pixels'; 
            app.Panel_TracingTools.Units        = 'pixels'; 
            
            % Set the Left Panel position variables
            app.dV.LP_Rm = 12; 
            app.dV.LP_Hm = 10; 
            app.dV.LP_w = 160; 
                app.dV.DP_h = 243; 
                app.dV.DO_h = 100; 
                app.dV.TL_h = 187; 
                app.dV.TT_h = 121; 
                app.dV.LO_h = 152; 
            app.dV.DP_b = app.dV.aH - (app.dV.DP_h + app.dV.LP_Hm*1); 
            app.dV.DO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.LP_Hm*2); 
            app.dV.TL_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.LP_Hm*3); 
            app.dV.TT_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LP_Hm*4); 
            app.dV.LO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LO_h + app.dV.LP_Hm*5); 
            
            app.Panel_DisplayParameters.Position =   [app.dV.LP_Rm,   app.dV.DP_b,   app.dV.LP_w,   app.dV.DP_h]; 
            app.ButtonGroup_DisplayOption.Position = [app.dV.LP_Rm,   app.dV.DO_b,   app.dV.LP_w,   app.dV.DO_h]; 
            app.Panel_TracingLabels.Position =       [app.dV.LP_Rm,   app.dV.TL_b,   app.dV.LP_w,   app.dV.TL_h]; 
            app.Panel_TracingTools.Position =        [app.dV.LP_Rm,   app.dV.TT_b,   app.dV.LP_w,   app.dV.TT_h]; 
            app.Panel_LabelOverlay.Position =        [app.dV.LP_Rm,   app.dV.LO_b,   app.dV.LP_w,   app.dV.LO_h]; 
            
          % Set up the initial UITable_Labels
            d = {
                1,  'Label_1',  1
                2,  'Label_2',  2
                3,  'Label_3',  3
                4,  'Label_4',  4
                5,  'Label_5',  5
                6,  'Label_6',  6
                7,  'Label_7',  7
                8,  'Label_8',  8
                9,  'Label_9',  9
                10, 'Label_10', 10
                }; 
            app.UITable_Labels.Data = d; 
%             app.UITable_Labels.ColumnName = {'#', 'Label Name', 'Color'}; 
            app.UITable_Labels.ColumnWidth = {20, 65, 46}; 
%             app.UITable_Labels.Position(4) = 132; 
%             app.UITable_Labels.ColumnEditable = {true, true}; 

          % Create Tracing Volume
            for j=1:10
                eval(['app.data.VT' num2str(j) '.label = ''Label ' num2str(j) ''';']); 
                eval(['app.data.VT' num2str(j) '.cCode = ' num2str(j) ';']); 
                eval(['app.data.VT' num2str(j) '.vix = cell(1,100);']); 
                
                eval(['app.data.oVT' num2str(j) '.label = ''Label ' num2str(j) ''';']); 
                eval(['app.data.oVT' num2str(j) '.cCode = ' num2str(j) ';']); 
                eval(['app.data.oVT' num2str(j) '.vix = cell(1,100);']); 
            end
            
          % Set the default status variables
            app.tV.mriLoaded          = 0; 
            app.tV.mriPlotted         = 0; 
                app.tV.controlDown    = 0; 
                app.tV.shiftDown      = 0; 
                app.tV.altDown        = 0; 
            app.tV.crosshairOn        = 0; 
            app.tV.tracing_on         = 0; 
            
            app.tV.pen_down = 0; 
            app.tV.eraser_down = 0; 
            app.tV.drawing_down = 0; 
            
%             app.tV.pen_Axial_down     = 0; 
%             app.tV.erase_Axial_down   = 0; 
%             app.tV.select_Axial_down  = 0;             
            
            app.tV.LeftMouseDown    = 0; 
            app.tV.RightMouseDown   = 0; 
                app.tV.rectSelectOn_Axial    = 0; 
                app.tV.rectSelectOn_Coronal  = 0; 
                app.tV.rectSelectOn_Sagittal = 0; 
            app.tV.alphaL = 1; 
            app.Opacity_Slider.Limits = [0, 1]; 
                
          % Set the cCode to be 1 (default)
            app.tV.labelN = 1; 
            app.EditField_CurrentTracing.Value = 1; 
            
          % Set Tracing Tools' buttons unselected
            app.StateButton_drawing.Value = 0; 
            app.StateButton_pen.Value = 0; 
            app.StateButton_eraser.Value  = 0;  
            
            app.tV.overlayMode = 0; 
                        
            app.dV.cCodes = [
                1.0000    0.8398         0 % Gold
                0.8203    0.4102    0.1172 % Chocolate
                0.6445    0.1641    0.1641 % Brown
                1.0000    0.6445         0 % Orange
                1.0000    0.0781    0.5742 % DeepPink
                1.0000         0         0 % Red
                     0    1.0000         0 % Lime
                     0    0.5000         0 % Green
                     0    0.3906         0 % DarkGreen
                     0    1.0000    1.0000 % Cyan
                     0         0    1.0000 % Blue
                0.1172    0.5625    1.0000 % DodgerBlue
                1.0000         0    1.0000 % Magenta
                0.5781         0    0.8242 % DarkViolet
                0.5000         0    0.5000 % Purple
                ]; 
            app.tV.editColor = [0 1 0]; % Green
            app.tV.showColor = [0 1 1]; % BlueGreen
            
            app.tV.sliceNchange = -1; 
            app.tV.sliceNchangeText = '-1'; 
            
        end

        % Menu selected function: OpenMRIMenu
        function OpenMRIMenuSelected(app, event)
            
%           % Show Warning Message that Unsaved Label information will be lost. 
%           % - Load a new MRI only after confirming "ok"
%             selection = uiconfirm(app.UIFigure,'Opening a New MRI file will erase all unsaved tracing label data. Proceed?', 'Open File Warning'); 
%             switch selection
%                 case 'OK'
                    app.tV.mriPlotted = false; 
                    clear_AxesControls(app); % first, clear all axes & control components
                    
                  % Set default values
                    app.tV.viewChange = 1; % set viewChange to 0
                    app.tV.nView = 'Triple';  
                    app.tV.view = 'Triple'; 
                    
                    app.UIFigure.Pointer = 'arrow'; 
                    app.NoButton.Value = 1; 
                    app.tV.overlayMode = 0; 
                    app.AxialButton.Value = 1; 
                    app.tV.overlayPlane = 'Axial'; 
                    
                    clear_AxesControls(app); % first, clear all axes & control components
                    
                  % Get file
                    [ofilename,path] = uigetfile({'*.nii'; '*.nii.gz'}); % [ofilename,path] = uigetfile('*.nii'); 
                    pathfile = [path ofilename]; 
                    % app.UIFigure.Name = ['MRItrace: ' pathfile]; 
                    [filepath0,oname,ext] = fileparts(ofilename); 
                    % newFileName = [oname '_c' ext]; 
                    % newpathfile = [path newFileName]; 
                    app.fileV.pathfile = pathfile; 
                    app.fileV.path = path; 
                    app.fileV.ofilename = ofilename; 
                    app.fileV.oname = oname; 
                    app.fileV.ext = ext; 
                    
                    assignin('base', 'path', path); 
                    assignin('base', 'ofilename', ofilename); 
        
                  % Determine LabelFileName
                    LabelFileName = [oname '_label' ext]; 
                    LabelPathFile = [path LabelFileName]; 
                    app.fileV.LabelPathFile = LabelPathFile; 
                    
                  % Set the display option to the default
                    app.tV.display = 'Image'; 
                    app.ImageButton.Value = 1; 
                    % app.ButtonGroup_DisplayOption.SelectedObject.Text = 'Image'; 
                    
                  % Load MRI file
                    switch ext
                        case '.nii'
                            V = niftiread(pathfile); 
                            hdr = niftiinfo(pathfile); 
                        case '.gz'
                            gunzip(pathfile); % Temporarily Save unzipped MRI files to the original directory
                            V = niftiread(pathfile(1:end-3)); 
                            hdr = niftiinfo(pathfile(1:end-3)); 
                            delete(pathfile(1:end-3)); % Delete the temporarily saved file. 
                            app.fileV.ext = '.nii'; 
                    end
                    app.data.V = V; 
                    app.data.hdr = hdr; 
                    app.data.xyzMax = size(V); 
                    app.data.xyzC = round(size(V)/2); 
                    app.tV.curIx = 1; 
                    app.tV.lastIx = 1; 
                    app.data.cur_VT = zeros(size(V)); 
                    app.data.cur_tempVT = []; 
                        app.tV.ocurIx = 1; 
                        app.tV.olastIx = 1; 
                    
                  % Determine Positions of the display items 
                    app.dV.aW = app.UIFigure.Position(3); app.dV.aH = app.UIFigure.Position(4); 
                    app = determinePositions(app); 
                    
                  % Determine Default (full) Image Range to display
                    imgR = [
                        1, app.data.xyzMax(1) % X: left-to-right
                        1, app.data.xyzMax(2) % Y: anterior-to-posterior
                        1, app.data.xyzMax(3) % Z: superior-to-inferior
                        ]; 
                    % curImgR = imgR; 
                    
                    app.tV.imgR = imgR; 
                    app.tV.Axial_curXLim = imgR(1,:); 
                    app.tV.Axial_curYLim = imgR(2,:); 
                    app.tV.Coronal_curXLim = imgR(1,:); 
                    app.tV.Coronal_curYLim = imgR(3,:); 
                    app.tV.Sagittal_curXLim = imgR(2,:); 
                    app.tV.Sagittal_curYLim = imgR(3,:); 
                    
                  % Set Axes Slider limits
                    set(app.Slider_Axial,    'Limits', [imgR(3,1), imgR(3,2)]); 
                    set(app.Slider_Coronal,  'Limits', [imgR(2,1), imgR(2,2)]); 
                    set(app.Slider_Sagittal, 'Limits', [imgR(1,1), imgR(1,2)]); 
                    
                  % Set the Initial Slices to display in the Axial, Coronal, & Sagittal planes
                    curZ = round(app.data.xyzC(3)); % the middle Z
                    curY = round(app.data.xyzC(2)); % the middle Y
                    curX = round(app.data.xyzC(1)); % the middle X
                        app.tV.curX = curX; % for Sagittal view
                        app.tV.curY = curY; % for Coronal view
                        app.tV.curZ = curZ; % for Axial view
                        
                  % Create Tracing Volume
                    for j=1:10
                        eval(['app.data.VT' num2str(j) '.label = ''Label ' num2str(j) ''';']); 
                        eval(['app.data.VT' num2str(j) '.cCode = ' num2str(j) ';']); 
                        eval(['app.data.VT' num2str(j) '.vix = cell(1,100);']); 
                    end
                    
                  % Create overlay tracing slice container for Axial(oSTa), Coronal(oSTc), and Sagittal(oSTs)
                    app.data.oSTa.sliceN = 0; 
                    app.data.oSTa.pix = cell(1,100); 
                    app.data.oSTc.sliceN = 0; 
                    app.data.oSTc.pix = cell(1,100); 
                    app.data.oSTs.sliceN = 0; 
                    app.data.oSTs.pix = cell(1,100); 
                        app.data.cur_oSTa = zeros(app.data.nY, app.data.nX); 
                        app.data.cur_oSTc = zeros(app.data.nX, app.data.nZ); 
                        app.data.cur_oSTs = zeros(app.data.nZ, app.data.nY); 
                        app.data.cur_otempSTa = []; 
                        app.data.cur_otempSTc = []; 
                        app.data.cur_otempSTs = []; 
                    app.tV.sliceNchange = -1; 
                    app.tV.sliceNchangeText = '-1'; 
                        
                  % Set the Values of the UIAxes Sliders
                    app.Slider_Axial.Value = curZ; 
                    app.Slider_Coronal.Value = curY; 
                    app.Slider_Sagittal.Value = app.data.xyzC(1); 
                  % Set the Values of the UIAxes Spinners
                    app.Spinner_Axial.Value    = curZ; 
                    app.Spinner_Coronal.Value  = curY; 
                    app.Spinner_Sagittal.Value = curX; 
                    
%                   % Create the original-to-display index conversion matrix for the 3 plane
%                     nX = app.data.nX; nY = app.data.nY; nZ = app.data.nZ; 
%                     app.tV.oixA = reshape(1:nX*nY, nX, nY); 
%                     app.tV.nixA = flipud(app.tV.oixA'); 
%                     app.tV.oixC = reshape(1:nX*nZ, nX, nZ); 
%                     app.tV.nixC = flipud(app.tV.oixC'); 
%                     app.tV.oixS = reshape(1:nY*nZ, nY, nZ); 
%                     app.tV.nixS = flipud(app.tV.oixS'); 
                    
                  % Plot Image Value Histogram
                    V3t = V(:); 

                  %% To modify %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Exclude extremely low or high values (<= 0.01 %ile or >= 0.99 %ile)
                    V3t( V(:) <= quantile(V(:), 0.01) | V(:) >= quantile(V(:), 0.99999) ) = []; 
                    V3t = double(V3t(:)); 
                    % V3 = V3(V3~=0); % Exclude 0 values
                    V3r = [min(V3t), max(V3t)]; 
                    
                    % imgVr = quantile(V3t,[0.01, 0.99]); % image value range excluding outliers (bottom 1% & top 1%)
                    imgVr = [min(V3t), max(V3t)]; 
                    imgVc = mean(imgVr); 
                    imgVw = range(imgVr)/2; 
                    
                    % Determine imgV Range parameters
                    imgVlow = imgVc-imgVw; 
                    if imgVlow < V3r(1)
                        imgVlow = V3r(1); 
                    end
                    imgVup = imgVc+imgVw; 
                    if imgVup > V3r(2)
                        imgVup = V3r(2); 
                    end
                    imgVwidth = imgVup-imgVlow; 
                    
                    app.data.V3t = V3t; 
                    app.tV.imgVc = imgVc; 
                    app.tV.imgVw = imgVw; 
                    app.tV.imgVrange = [imgVlow, imgVlow+imgVwidth]; 
                  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    app.UIAxes_imgHist.Visible = 1; 
                    ht = histogram(app.UIAxes_imgHist, V3t, 100); 
                    hold(app.UIAxes_imgHist, 'on'); 
                    app.UIAxes_imgHist.XLim = V3r; 
                    app.UIAxes_imgHist.YLim = [0, max(ht.Values)*1.2]; 
                    % app.UIAxes_imgHist.XTick = [0:200:V3r(2)]; 
                    rectangle(app.UIAxes_imgHist, 'Position',[imgVlow, 0, imgVwidth, max(ht.Values)*1.2], 'FaceColor',[.9 .9 .9], 'EdgeColor','r', 'LineWidth',1); 
                    ht = histogram(app.UIAxes_imgHist, V3t, 100); 
                    plot(app.UIAxes_imgHist, [imgVc, imgVc], [0, max(ht.Values)*1.2], 'r--'); 
                    hold(app.UIAxes_imgHist,'off'); 
                    grid(app.UIAxes_imgHist,'on'); 
                    
                    % Set Brightness & Contrast Slider limits
                    set(app.Slider_Brightness, 'Limits', [min(V3t), max(V3t)]); 
                    set(app.Slider_Brightness, 'Value', imgVc); 
                    set(app.EditField_Brightness, 'Value', imgVc); 
                    set(app.Slider_Contrast, 'Limits', [1, max(V3t)/2]); 
                    set(app.Slider_Contrast, 'Value', imgVw); 
                    set(app.EditField_Contrast, 'Value', imgVw); 
                    
                    
                    % Reset the default status variables
                    app.tV.mriLoaded = 1; 
                    app.tV.mriPlotted         = 0; 
                    
                    app.tV.viewChange = 1; 
                    
                        app.tV.controlDown    = 0; 
                        app.tV.shiftDown      = 0; 
                        app.tV.altDown        = 0; 
                    app.tV.crosshairOn        = 0; 
                    app.tV.LeftMouseDown    = 0; 
                    app.tV.RightMouseDown   = 0; 
                        app.tV.rectSelectOn_Axial    = 0; 
                        app.tV.rectSelectOn_Coronal  = 0; 
                        app.tV.rectSelectOn_Sagittal = 0; 
                    
                    app.tV.pen_down = 0; 
                    app.tV.eraser_down = 0; 
                    app.tV.drawing_down = 0; 
                    
                  % Set Tracing Tools' buttons unselected
                    app.StateButton_drawing.Value = 0; 
                    app.StateButton_pen.Value = 0; 
                    app.StateButton_eraser.Value  = 0;  
                    
                    app.tV.drawing = 0; 
                    
                  % Set default overlay parameters
                    app.tV.overlayMode = 0; 
                    app.tV.overlayPlane = 'Axial'; 
                    app.tV.overlayAlphaL = 0.5; 
                    
                    
                    assignin('base', 'V', V); 
%                     cur_VT = app.data.Vt{1}; 
%                     assignin('base', 'cur_VT', cur_VT); 
                    
%                 case 'Cancel'
%                     return
%             end
            
        end

        % Selection changed function: ButtonGroup_DisplayOption
        function ButtonGroup_DisplayOptionSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_DisplayOption.SelectedObject;            
            switch selectedButton.Text
                case 'Image'
                    app.tV.display = 'Image'; 
                case 'Image + Label'
                    app.tV.display = 'ImageLabel'; 
                case 'Label'
                    app.tV.display = 'Label'; 
            end
        end

        % Value changed function: Slider_Brightness
        function Slider_BrightnessValueChanged(app, event)
            if app.tV.mriLoaded == 1 % <-- Control to run only after MRI is loaded
                imgVc = app.Slider_Brightness.Value;
                imgVc = round(imgVc*10)/10; 
                app.tV.imgVc = imgVc; 
                set(app.EditField_Brightness, 'Value', imgVc); 
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Update Image Value Histogram
                V3t = app.data.V3t; 
                V3r = [min(V3t), max(V3t)]; 
                imgVw = app.tV.imgVw; 
                
                % Determine imgV Range parameters
                imgVlow = imgVc-imgVw; 
                if imgVlow < V3r(1)
                    imgVlow = V3r(1); 
                end
                imgVup = imgVc+imgVw; 
                if imgVup > V3r(2)
                    imgVup = V3r(2); 
                end
                imgVwidth = imgVup-imgVlow; 
                app.tV.imgVrange = [imgVlow, imgVlow+imgVwidth]; 
                
                % Plot Histogram
                ht = histogram(app.UIAxes_imgHist, V3t, 100); 
                hold(app.UIAxes_imgHist, 'on'); 
                app.UIAxes_imgHist.XLim = V3r; 
                app.UIAxes_imgHist.YLim = [0, max(ht.Values)*1.2]; 
                % app.UIAxes_imgHist.XTick = [0:200:V3r(2)]; 
                rectangle(app.UIAxes_imgHist, 'Position',[imgVlow, 0, imgVwidth, max(ht.Values)*1.2], 'FaceColor',[.9 .9 .9], 'EdgeColor','r', 'LineWidth',1); 
                ht = histogram(app.UIAxes_imgHist, V3t, 100); 
                plot(app.UIAxes_imgHist, [imgVc, imgVc], [0, max(ht.Values)*1.2], 'r--'); 
                hold(app.UIAxes_imgHist,'off'); 
                grid(app.UIAxes_imgHist,'on'); 
            
                if app.tV.mriPlotted == true % <-- Control to plot MRIs only after initially plotting MRI with the initial Brightness & Contrast values
%                     plot_images(app); 
                    plot_image_label(app); 
                    
                end
            end
            
        end

        % Value changed function: Slider_Contrast
        function Slider_ContrastValueChanged(app, event)
            if app.tV.mriLoaded == 1 % <-- Control to run only after MRI is loaded
                imgVw = app.Slider_Contrast.Value;
                imgVw = round(imgVw*10)/10; 
                app.tV.imgVw = imgVw; 
                set(app.EditField_Contrast, 'Value', imgVw); 
                
                % Update Image Value Histogram
                V3t = app.data.V3t; 
                V3r = [min(V3t), max(V3t)]; 
                imgVc = app.tV.imgVc; 
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Determine imgV Range parameters
                imgVlow = imgVc-imgVw; 
                if imgVlow < V3r(1)
                    imgVlow = V3r(1); 
                end
                imgVup = imgVc+imgVw; 
                if imgVup > V3r(2)
                    imgVup = V3r(2); 
                end
                imgVwidth = imgVup-imgVlow; 
                app.tV.imgVrange = [imgVlow, imgVlow+imgVwidth]; 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % Plot Histogram
                ht = histogram(app.UIAxes_imgHist, V3t, 100); 
                hold(app.UIAxes_imgHist, 'on'); 
                app.UIAxes_imgHist.XLim = V3r; 
                app.UIAxes_imgHist.YLim = [0, max(ht.Values)*1.2]; 
                % app.UIAxes_imgHist.XTick = [0:200:V3r(2)]; 
                rectangle(app.UIAxes_imgHist, 'Position',[imgVlow, 0, imgVwidth, max(ht.Values)*1.2], 'FaceColor',[.9 .9 .9], 'EdgeColor','r', 'LineWidth',1); 
                ht = histogram(app.UIAxes_imgHist, V3t, 100); 
                plot(app.UIAxes_imgHist, [imgVc, imgVc], [0, max(ht.Values)*1.2], 'r--'); 
                hold(app.UIAxes_imgHist,'off'); 
                grid(app.UIAxes_imgHist,'on'); 
                
                if app.tV.mriPlotted == true % <-- Control to plot MRIs only after initially plotting MRI with the initial Brightness & Contrast values
%                     plot_images(app); 
                    plot_image_label(app); 
                end
            end
            
        end

        % Value changed function: EditField_Brightness
        function EditField_BrightnessValueChanged(app, event)
            if app.tV.mriLoaded == 1 % <-- Control to run only after MRI is loaded
                imgVc = app.EditField_Brightness.Value;
                imgVc = round(imgVc*10)/10; 
                app.tV.imgVc = imgVc; 
                set(app.EditField_Brightness, 'Value', imgVc); 
                
                % Update Image Value Histogram
                V3t = app.data.V3t; 
                V3r = [min(V3t), max(V3t)]; 
                imgVw = app.tV.imgVw; 
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Determine imgV Range parameters
                imgVlow = imgVc-imgVw; 
                if imgVlow < V3r(1)
                    imgVlow = V3r(1); 
                end
                imgVup = imgVc+imgVw; 
                if imgVup > V3r(2)
                    imgVup = V3r(2); 
                end
                imgVwidth = imgVup-imgVlow; 
                app.tV.imgVrange = [imgVlow, imgVlow+imgVwidth]; 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % Plot Histogram
                ht = histogram(app.UIAxes_imgHist, V3t, 100); xlim(V3r); set(gca, 'XTick', [0:200:V3r(2)]); 
                ylim([0, max(ht.Values)*1.2]); hold(app.UIAxes_imgHist,'on');
                rectangle(app.UIAxes_imgHist, 'Position',[imgVlow, 0, imgVwidth, max(ht.Values)*1.2], 'FaceColor',[.9 .9 .9], 'EdgeColor','r', 'LineWidth',1); 
                histogram(app.UIAxes_imgHist, V3t, 100); 
                plot(app.UIAxes_imgHist, [imgVc, imgVc], ylim, 'r--'); hold(app.UIAxes_imgHist,'off'); 
                grid(app.UIAxes_imgHist,'on'); 
                
                if app.tV.mriPlotted == true % <-- Control to plot MRIs only after initially plotting MRI with the initial Brightness & Contrast values
%                     plot_images(app); 
                    plot_image_label(app); 
                end
            end
            
        end

        % Value changed function: EditField_Contrast
        function EditField_ContrastValueChanged(app, event)
            if app.tV.mriLoaded == 1 % <-- Control to run only after MRI is loaded
                imgVw = app.EditField_Contrast.Value;
                imgVw = round(imgVw*10)/10; 
                app.tV.imgVw = imgVw; 
                set(app.EditField_Contrast, 'Value', imgVw); 
                
                % Update Image Value Histogram
                V3t = app.data.V3t; 
                V3r = [min(V3t), max(V3t)]; 
                imgVc = app.tV.imgVc; 
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Determine imgV Range parameters
                imgVlow = imgVc-imgVw; 
                if imgVlow < V3r(1)
                    imgVlow = V3r(1); 
                end
                imgVup = imgVc+imgVw; 
                if imgVup > V3r(2)
                    imgVup = V3r(2); 
                end
                imgVwidth = imgVup-imgVlow; 
                app.tV.imgVrange = [imgVlow, imgVlow+imgVwidth]; 
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % Plot Histogram
                ht = histogram(app.UIAxes_imgHist, V3t, 100); xlim(V3r); set(gca, 'XTick', [0:200:V3r(2)]); 
                ylim([0, max(ht.Values)*1.2]); hold(app.UIAxes_imgHist,'on');
                rectangle(app.UIAxes_imgHist, 'Position',[imgVlow, 0, imgVwidth, max(ht.Values)*1.2], 'FaceColor',[.9 .9 .9], 'EdgeColor','r', 'LineWidth',1); 
                histogram(app.UIAxes_imgHist, V3t, 100); 
                plot(app.UIAxes_imgHist, [imgVc, imgVc], ylim, 'r--'); hold(app.UIAxes_imgHist,'off'); 
                grid(app.UIAxes_imgHist,'on'); 
                
                if app.tV.mriPlotted == true % <-- Control to plot MRIs only after initially plotting MRI with the initial Brightness & Contrast values
%                     plot_images(app); 
                    plot_image_label(app); 
                end
            end
            
        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)
            
            if app.tV.mriLoaded == 1 % <-- Control to prevent plotting MRIs before Loading a new MRI
                
              % Prepare UIAxes if viewChange occurred
                if app.tV.viewChange == 1
                    clear_AxesControls(app); % first, clear all axes & control components
                    
                  % Reset Axes component Positions if they changed due to Figure Resizing
                    curFigPosition = app.UIFigure.Position; 
%                     prepare_AxesControls(app); % prepare Axes & Controls
                    switch app.tV.view
                        case 'Single_Axial'
                            if ~isequal(app.UIAxes_Axial.Position, app.dV.pos_Single_Axial)
                                app.dV.aW = curFigPosition(3); app.dV.aH = curFigPosition(4); 
                                determinePositions(app); 
                                prepare_AxesControls(app); % prepare Axes & Controls
                            end
                        case 'Single_Coronal'
                            if ~isequal(app.UIAxes_Coronal.Position, app.dV.pos_Single_Coronal)
                                app.dV.aW = curFigPosition(3); app.dV.aH = curFigPosition(4); 
                                determinePositions(app); 
                                prepare_AxesControls(app); % prepare Axes & Controls
                            end
                        case 'Single_Sagittal'
                            if ~isequal(app.UIAxes_Sagittal.Position, app.dV.pos_Single_Sagittal)
                                app.dV.aW = curFigPosition(3); app.dV.aH = curFigPosition(4); 
                                determinePositions(app); 
                                prepare_AxesControls(app); % prepare Axes & Controls
                            end
                        case 'Double_AxialCoronal'
                            if ~isequal(app.UIAxes_Axial.Position, app.dV.pos_Double_AxialCoronal.Axial) || ~isequal(app.UIAxes_Coronal.Position, app.dV.pos_Double_AxialCoronal.Coronal)
                                app.dV.aW = curFigPosition(3); app.dV.aH = curFigPosition(4); 
                                determinePositions(app); 
                                prepare_AxesControls(app); % prepare Axes & Controls
                            end
                        case 'Double_AxialSagittal'
                            if ~isequal(app.UIAxes_Axial.Position, app.dV.pos_Double_AxialSagittal.Axial) || ~isequal(app.UIAxes_Sagittal.Position, app.dV.pos_Double_AxialSagittal.Sagittal)
                                app.dV.aW = curFigPosition(3); app.dV.aH = curFigPosition(4); 
                                determinePositions(app); 
                                prepare_AxesControls(app); % prepare Axes & Controls
                            end
                        case 'Double_CoronalSagittal'
                            if ~isequal(app.UIAxes_Coronal.Position, app.dV.pos_Double_CoronalSagittal.Coronal) || ~isequal(app.UIAxes_Sagittal.Position, app.dV.pos_Double_CoronalSagittal.Sagittal)
                                app.dV.aW = curFigPosition(3); app.dV.aH = curFigPosition(4); 
                                determinePositions(app); 
                                prepare_AxesControls(app); % prepare Axes & Controls
                            end
                        case 'Triple'
                            if ~isequal(app.UIAxes_Axial.Position, app.dV.pos_Triple.Axial) || ~isequal(app.UIAxes_Coronal.Position, app.dV.pos_Triple.Coronal) || ~isequal(app.UIAxes_Sagittal.Position, app.dV.pos_Triple.Sagittal)
                                app.dV.aW = curFigPosition(3); app.dV.aH = curFigPosition(4); 
                                determinePositions(app); 
                                prepare_AxesControls(app); % prepare Axes & Controls
                            end
                    end
                    
                end
                
                app.tV.viewChange = 0; % set viewChange to 0
                
                plot_image_label(app); 
%                 plot_images(app); 
                
                app.tV.mriPlotted = 1;  % Update app.tV indicating that MRI plotting was done so the axes are ready to operate
%                 app.tV.crosshairOn = 0; % Update apploV indicating that the axes are ready to draw crosshair
            end
            
        end

        % Menu selected function: Menu_Single
        function Menu_SingleSelected(app, event)
            app.tV.nView = 'Single'; 
        end

        % Menu selected function: Menu_Double
        function Menu_DoubleSelected(app, event)
            app.tV.nView = 'Double'; 
        end

        % Menu selected function: Menu_Triple
        function Menu_TripleSelected(app, event)
            app.tV.nView = 'Triple'; 
            app.tV.view = 'Triple'; 
            app.tV.viewChange = 1; 
            app.tV.mriPlotted = 0; 
        end

        % Menu selected function: Menu_Axial
        function Menu_AxialSelected(app, event)
            app.tV.plane = 'Axial'; 
            app.tV.view = [app.tV.nView '_Axial']; 
            app.tV.viewChange = 1; 
            app.tV.mriPlotted = 0; 
        end

        % Menu selected function: Menu_Coronal
        function Menu_CoronalSelected(app, event)
            app.tV.plane = 'Coronal'; 
            app.tV.view = [app.tV.nView '_Coronal']; 
            app.tV.viewChange = 1; 
            app.tV.mriPlotted = 0; 
        end

        % Menu selected function: Menu_Sagittal
        function Menu_SagittalSelected(app, event)
            app.tV.plane = 'Sagittal'; 
            app.tV.view = [app.tV.nView '_Sagittal']; 
            app.tV.viewChange = 1; 
            app.tV.mriPlotted = 0; 
        end

        % Menu selected function: Menu_AxialCoronal
        function Menu_AxialCoronalSelected(app, event)
            app.tV.plane = 'AxialCoronal'; 
            app.tV.view = [app.tV.nView '_AxialCoronal']; 
            app.tV.viewChange = 1; 
            app.tV.mriPlotted = 0; 
        end

        % Menu selected function: Menu_AxialSagittal
        function Menu_AxialSagittalSelected(app, event)
            app.tV.plane = 'AxialSagittal'; 
            app.tV.view = [app.tV.nView '_AxialSagittal']; 
            app.tV.viewChange = 1; 
            app.tV.mriPlotted = 0; 
        end

        % Menu selected function: Menu_CoronalSagittal
        function Menu_CoronalSagittalSelected(app, event)
            app.tV.plane = 'CoronalSagittal'; 
            app.tV.view = [app.tV.nView '_CoronalSagittal']; 
            app.tV.viewChange = 1; 
            app.tV.mriPlotted = 0; 
        end

        % Button pushed function: Button_SelectRange
        function Button_SelectRangePushed(app, event)

            app; 
%             range = drawrectangle(app.UIAxes_Axial); 
% %             set(app.UIAxes_Sagittal, 'Position', app.dV.pos_Triple.Sagittal); 
% %             disp(num2str(app.dV.pos_Triple.Sagittal))
            
        end

        % Menu selected function: SaveLabelFileMenu
        function SaveLabelFileMenuSelected(app, event)
            
            eval(['labelfilename = [app.fileV.oname ''_roi_'' app.data.VT' num2str(app.tV.labelN) '.label];']); 
            eval(['SaveTitle = [''Save the ROI label file for ''  app.data.VT' num2str(app.tV.labelN) '.label];']); 
            
            filter = {'*.nii';'*.nii.gz'}; 
            [file,path,indx] = uiputfile(filter, SaveTitle, labelfilename);
            
            dirfilename2write = [path file]; 
            
            niftiwrite(single(app.data.cur_VT), dirfilename2write, app.data.hdr); 
            disp(['Saved ' labelfilename 'file!']); 
            
%             app.fileV.pathfile = pathfile; 
%             app.fileV.path = path; 
%             app.fileV.ofilename = ofilename; 
%             app.fileV.oname = oname; 
%             app.fileV.ext = ext; 
            
            % Save label file using "uiputfile" function that opening
            % dialog box for specifying directory & filename to save. 
            
        end

        % Value changed function: Slider_Axial
        function Slider_AxialValueChanged(app, event)
            
         % Update curZ & re-plot Axial view
            curZ = app.Slider_Axial.Value; 
            app.tV.curZ = round(curZ); 
            app.Spinner_Axial.Value = round(curZ); 
            prepare2Dimages(app); 
            switch app.tV.display
                case 'Image'
                    plot_image(app, 'Axial'); 
                case 'ImageLabel'
                    plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                    plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                case 'Label'
                    plot_label_only(app, 'Axial'); 
            end
            
            if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Axial')
                plot_overlay_label(app, 'Axial')
            elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Axial')
                plot_overlayEdit_label(app, 'Axial') 
            end
            
            if app.tV.crosshairOn == 1
                plotCH(app, 'Axial'); 
            end
                        
        end

        % Value changed function: Slider_Coronal
        function Slider_CoronalValueChanged(app, event)
          % Update curY & re-plot Coronal view
            curY = app.Slider_Coronal.Value; 
            app.tV.curY = round(curY); 
            app.Spinner_Coronal.Value = round(curY); 
            prepare2Dimages(app); 
            switch app.tV.display
                case 'Image'
                    plot_image(app, 'Coronal'); 
                case 'ImageLabel'
                    plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                    plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                case 'Label'
                    plot_label_only(app, 'Coronal'); 
            end
            
            if app.tV.crosshairOn == 1
                plotCH(app, 'Coronal'); 
            end
            
            if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Coronal')
                plot_overlay_label(app, 'Coronal')
            elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Coronal')
                plot_overlayEdit_label(app, 'Coronal')
            end
            
        end

        % Value changed function: Slider_Sagittal
        function Slider_SagittalValueChanged(app, event)
          % Update curY & re-plot Sagittal view
            curX = app.Slider_Sagittal.Value; 
            app.tV.curX = round(curX); 
            app.Spinner_Sagittal.Value = round(curX); 
            prepare2Dimages(app); 
            switch app.tV.display
                case 'Image'
                    plot_image(app, 'Sagittal'); 
                case 'ImageLabel'
                    plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                    plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                case 'Label'
                    plot_label_only(app, 'Sagittal'); 
            end
            
            if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Sagittal')
                plot_overlay_label(app, 'Sagittal')
            elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Sagittal')
                plot_overlayEdit_label(app, 'Sagittal')
            end
            
            if app.tV.crosshairOn == 1
                plotCH(app, 'Sagittal'); 
            end
                        
        end

        % Value changed function: Spinner_Axial
        function Spinner_AxialValueChanged(app, event)
          % Update curZ & re-plot Axial view
            curZ = app.Spinner_Axial.Value; 
            app.tV.curZ = round(curZ); 
            app.Slider_Axial.Value = round(curZ); 
            prepare2Dimages(app); 
            switch app.tV.display
                case 'Image'
                    plot_image(app, 'Axial'); 
                case 'ImageLabel'
                    plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                    plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                case 'Label'
                    plot_label_only(app, 'Axial'); 
            end
            
            if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Axial')
                plot_overlay_label(app, 'Axial')
            elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Axial')
                plot_overlayEdit_label(app, 'Axial') 
            end
            
            if app.tV.crosshairOn == 1
                plotCH(app, 'Axial'); 
            end
            
        end

        % Value changed function: Spinner_Coronal
        function Spinner_CoronalValueChanged(app, event)
          % Update curY & re-plot Coronal view
            curY = app.Spinner_Coronal.Value; 
            app.tV.curY = round(curY); 
            app.Slider_Coronal.Value = round(curY); 
            prepare2Dimages(app); 
            switch app.tV.display
                case 'Image'
                    plot_image(app, 'Coronal'); 
                case 'ImageLabel'
                    plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                    plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                case 'Label'
                    plot_label_only(app, 'Coronal'); 
            end
            
            if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Coronal')
                plot_overlay_label(app, 'Coronal')
            elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Coronal')
                plot_overlayEdit_label(app, 'Coronal')
            end
            
            if app.tV.crosshairOn == 1
                plotCH(app, 'Coronal'); 
            end
            
        end

        % Value changed function: Spinner_Sagittal
        function Spinner_SagittalValueChanged(app, event)
          % Update curY & re-plot Sagittal view
            curX = app.Spinner_Sagittal.Value; 
            app.tV.curX = round(curX); 
            app.Slider_Sagittal.Value = round(curX); 
            prepare2Dimages(app); 
            switch app.tV.display
                case 'Image'
                    plot_image(app, 'Sagittal'); 
                case 'ImageLabel'
                    plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                    plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                case 'Label'
                    plot_label_only(app, 'Sagittal'); 
            end
            
            if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Sagittal')
                plot_overlay_label(app, 'Sagittal')
            elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Sagittal')
                plot_overlayEdit_label(app, 'Sagittal')
            end
            
            if app.tV.crosshairOn == 1
                plotCH(app, 'Sagittal'); 
            end
            
        end

        % Window button motion function: UIFigure
        function buttonMotion(app, event)
            if app.tV.LeftMouseDown == 1
              % Determine if the current mouse point is inside of an Axes
                dPoint = get(app.UIFigure,'CurrentPoint'); 
                curAxes = determineCurAxes(app, dPoint); 
                
                if ~isequal(curAxes, 'None')
                  % Get the current Axes & the coordinates of the mouse clicks
                    eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
                    curPoint = tcurAxes.CurrentPoint;                   
                    curPoint = round([curPoint(1,1), curPoint(1,2)]); 
                    
                %% CrossHair Moving Operation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if app.tV.mriPlotted == 1 && app.tV.crosshairOn == 1
%                         eval(['curPoint = app.UIAxes_' curAxes '.CurrentPoint;']); 
%                         eval(['cP = app.UIAxes_' curAxes '.CurrentPoint;']); 
%                         cP = [round(cP(1,1)), round(cP(1,2))]; 
                        if app.tV.altDown==1
                                eval(['app.tV.chS_' curAxes ' = [app.UIAxes_' curAxes '.XLim, app.UIAxes_' curAxes '.YLim];']); % change the crosshair square range to the maximum for moving the crosshair beyond the current square range
                                % Update the curX, curY, curZ
                                switch curAxes
                                    case 'Axial'
                                        app.tV.curX = (app.data.nX - curPoint(1))+1; 
                                        app.tV.curY = (app.data.nY - curPoint(2))+1; 
                                        h = findobj(app.UIAxes_Axial, 'Type', 'line'); delete(h); plotCH(app, 'Axial'); % Delete the current crosshair & plot a new one
                                    case 'Coronal'
                                        app.tV.curX = (app.data.nX - curPoint(1))+1; 
                                        app.tV.curZ = (app.data.nZ - curPoint(2))+1; 
                                        h = findobj(app.UIAxes_Coronal, 'Type', 'line'); delete(h); plotCH(app, 'Coronal'); % Remove the current crosshair & plot a new one
                                    case 'Sagittal'
                                        app.tV.curY = (app.data.nY - curPoint(1))+1; 
                                        app.tV.curZ = (app.data.nZ - curPoint(2))+1; 
                                        h = findobj(app.UIAxes_Sagittal, 'Type', 'line'); delete(h); plotCH(app, 'Sagittal'); % Remove the current crosshair & plot a new one
                                end
                                % Re-plot images of other Axes according the current view
                                switch app.tV.view
                                    case 'Double_AxialCoronal'
                                        curAxes2 = setdiff({'Axial', 'Coronal'}, curAxes); curAxes2 = curAxes2{1}; 
                                        eval(['tcurAxes = app.UIAxes_' curAxes2 ';']); 
                                        plot_image(app, curAxes2); hold(tcurAxes, 'on'); plot_label(app, curAxes2); hold(tcurAxes, 'off'); 
                                        if app.tV.crosshairOn == 1
                                            h = findobj(tcurAxes, 'Type', 'line'); delete(h); plotCH(app, curAxes2); 
                                        end
                                    case 'Double_AxialSagittal'
                                        curAxes2 = setdiff({'Axial', 'Sagittal'}, curAxes); curAxes2 = curAxes2{1}; 
                                        eval(['tcurAxes = app.UIAxes_' curAxes2 ';']); 
                                        plot_image(app, curAxes2); hold(tcurAxes, 'on'); plot_label(app, curAxes2); hold(tcurAxes, 'off'); 
                                        if app.tV.crosshairOn == 1
                                            h = findobj(tcurAxes, 'Type', 'line'); delete(h); plotCH(app, curAxes2); 
                                        end
                                    case 'Double_CoronalSagittal'
                                        curAxes2 = setdiff({'Coronal', 'Sagittal'}, curAxes); curAxes2 = curAxes2{1}; 
                                        eval(['tcurAxes = app.UIAxes_' curAxes2 ';']); 
                                        plot_image(app, curAxes2); hold(tcurAxes, 'on'); plot_label(app, curAxes2); hold(tcurAxes, 'off'); 
                                        if app.tV.crosshairOn == 1
                                            h = findobj(tcurAxes, 'Type', 'line'); delete(h); plotCH(app, curAxes2); 
                                        end
                                    case 'Triple'
                                        curAxes23 = setdiff({'Axial', 'Coronal', 'Sagittal'}, curAxes); 
                                        curAxes2 = curAxes23{1}; 
                                        curAxes3 = curAxes23{2}; 
                                        eval(['tcurAxes2 = app.UIAxes_' curAxes2 ';']); 
                                        eval(['tcurAxes3 = app.UIAxes_' curAxes3 ';']); 
                                        plot_image(app, curAxes2); hold(tcurAxes2, 'on'); plot_label(app, curAxes2); hold(tcurAxes2, 'off'); 
                                        plot_image(app, curAxes3); hold(tcurAxes3, 'on'); plot_label(app, curAxes3); hold(tcurAxes3, 'off'); 
                                        if app.tV.crosshairOn == 1
                                            h = findobj(tcurAxes2, 'Type', 'line'); delete(h); plotCH(app, curAxes2); 
                                            h = findobj(tcurAxes3, 'Type', 'line'); delete(h); plotCH(app, curAxes3); 
                                        end
                                end
                        end
                    end
                   
                  %% Pen Tracing Operation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if app.tV.pen_down == 1 && isequal(app.tV.display, 'ImageLabel') && app.tV.altDown==0 && app.tV.eraser_down==0 && ...
                           (app.tV.rectSelectOn_Axial==0 && app.tV.rectSelectOn_Coronal==0 && app.tV.rectSelectOn_Sagittal==0) && ...
                           app.tV.altDown==0 && app.tV.shiftDown==0 && app.tV.controlDown==0
                      % Update tracing on
                        app.tV.tracing_on = 1; 
                        
                        if app.tV.overlayMode < 2
                          % Create cur_VT and insert the curIx's tracing information from the label volume (VT#.vix)
                            cur_VT = zeros(size(app.data.V)); 
                            cur_VT(app.data.cur_tempVT) = app.tV.labelN; 
                            
                          % Add current mouse points to cur_VT
                            switch curAxes
                                case 'Axial'
                                    oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                    cur_VT(oCurPoint(1), oCurPoint(2), app.tV.curZ) = app.tV.labelN; 
                                case 'Coronal'
                                    oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                    cur_VT(oCurPoint(1), app.tV.curY, oCurPoint(2)) = app.tV.labelN; 
                                case 'Sagittal'
                                    oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                    cur_VT(app.tV.curX, oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                            end
                            
                          % Update app.data.VT#.vix of the label voxel index
                            app.data.cur_VT = cur_VT; 
                            app.data.cur_tempVT = find(app.data.cur_VT(:)==app.tV.labelN); 
                            
                          % Delete the previous label image & Re-Plot only the label
                            h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                          % Re-Plot only the label
                            hold(tcurAxes, 'on'); 
                            plot_label(app, curAxes); 
                            hold(tcurAxes, 'off'); 
                            
                        elseif app.tV.overlayMode ==2 && isequal(curAxes, app.tV.overlayPlane)
                            h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); 
                            if ~isempty(h) 
                                % Update Overlay image
                                switch curAxes
                                    case 'Axial'
                                        oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                        if isempty(find(app.data.cur_oSTa == app.tV.labelN))
                                            app.data.cur_oSTa = squeeze(app.data.cur_VT(:, :, app.tV.curZ + app.tV.sliceNchange)); 
                                        else
                                            app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); % <----- Need to swap the X & Y dimension for the original indexing!!
                                            app.data.cur_oSTa(app.data.cur_otempSTa) = app.tV.labelN; 
                                        end
                                        app.data.cur_oSTa(oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                                        app.data.cur_otempSTa = find(app.data.cur_oSTa == app.tV.labelN); 
                                        app.data.oSTa.sliceN = app.tV.curZ; 
                                    case 'Coronal'
                                        oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                        if isempty(find(app.data.cur_oSTc == app.tV.labelN))
                                            app.data.cur_oSTc = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); 
                                        else
                                            app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); % <----- Need to swap the X & Z dimension for the original indexing!!
                                            app.data.cur_oSTc(app.data.cur_otempSTc) = app.tV.labelN; 
                                        end
                                        app.data.cur_oSTc(oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                                        app.data.cur_otempSTc = find(app.data.cur_oSTc == app.tV.labelN); 
                                        app.data.oSTc.sliceN = app.tV.curY; 
                                    case 'Sagittal'
                                        oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                        if isempty(find(app.data.cur_oSTs == app.tV.labelN))
                                            app.data.cur_oSTs = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); 
                                        else
                                            app.data.cur_oSTs = zeros(app.data.nY, app.data.nZ); %%% <----- NO Need to swap the Y & Z dimension for the original indexing!!
                                                    % app.data.cur_oSTs = zeros(app.data.nZ, app.data.nY); 
                                            app.data.cur_oSTs(app.data.cur_otempSTs) = app.tV.labelN; 
                                        end
                                        app.data.cur_oSTs(oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                                        app.data.cur_otempSTs = find(app.data.cur_oSTs == app.tV.labelN); 
                                        app.data.oSTs.sliceN = app.tV.curX; 
                                end
                                hold(tcurAxes, 'on'); 
                                plot_overlayEdit_label(app, curAxes); 
                                hold(tcurAxes, 'off'); 
                                
                            end
                        end
                    end
                    
                  %% Erasing Operation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if app.tV.eraser_down == 1 && isequal(app.tV.display, 'ImageLabel') && app.tV.altDown==0 && app.tV.pen_down==0 && ...
                           (app.tV.rectSelectOn_Axial==0 && app.tV.rectSelectOn_Coronal==0 && app.tV.rectSelectOn_Sagittal==0) && ...
                           app.tV.altDown==0 && app.tV.shiftDown==0 && app.tV.controlDown==0
                      % Update tracing on
                        app.tV.tracing_on = 1; 
                        
                      %% Erasing in Overlay Mode 0 & 1
                        if app.tV.overlayMode < 2
                          % Create cur_VT and insert the curIx's tracing information from cur_tempVT
                            cur_VT = zeros(size(app.data.V)); 
                            cur_VT(app.data.cur_tempVT) = app.tV.labelN; 
                            
                          % Update tracing volume (cur_VT)
                            switch curAxes
                                case 'Axial'
                                    oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                    cur_VT(oCurPoint(1), oCurPoint(2), app.tV.curZ) = 0; 
                                case 'Coronal'
                                    oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                    cur_VT(oCurPoint(1), app.tV.curY, oCurPoint(2)) = 0; 
                                case 'Sagittal'
                                    oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                    cur_VT(app.tV.curX, oCurPoint(1), oCurPoint(2)) = 0; 
                            end
                            app.data.cur_VT = cur_VT; 
                            app.data.cur_tempVT = find(app.data.cur_VT(:)==app.tV.labelN); 
                            
                          % Delete the previous label image & Re-Plot only the label
                            h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                            hold(tcurAxes, 'on'); plot_label(app, curAxes); hold(tcurAxes, 'off'); 
                            
                        %% Erasing in Overlay Mode 0 & 1
                        elseif app.tV.overlayMode==2 && isequal(curAxes, app.tV.overlayPlane)
                            h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); 
                            if ~isempty(h) 
                                % Update Overlay image
                                switch curAxes
                                    case 'Axial'
                                        oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                        if isempty(find(app.data.cur_oSTa == app.tV.labelN))
                                            app.data.cur_oSTa = squeeze(app.data.cur_VT(:, :, app.tV.curZ + app.tV.sliceNchange)); 
                                        else
                                            app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); % <----- Need to swap the X & Y dimension for the original indexing!!
                                            app.data.cur_oSTa(app.data.cur_otempSTa) = app.tV.labelN; 
                                        end
                                        app.data.cur_oSTa(oCurPoint(1), oCurPoint(2)) = 0; 
                                        app.data.cur_otempSTa = find(app.data.cur_oSTa == app.tV.labelN); 
                                    case 'Coronal'
                                        oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                        if isempty(find(app.data.cur_oSTc == app.tV.labelN))
                                            app.data.cur_oSTc = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); 
                                        else
                                            app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); 
                                            app.data.cur_oSTc(app.data.cur_otempSTc) = app.tV.labelN; 
                                        end
                                        app.data.cur_oSTc(oCurPoint(1), oCurPoint(2)) = 0; 
                                        app.data.cur_otempSTc = find(app.data.cur_oSTc == app.tV.labelN); 
                                    case 'Sagittal'
                                        oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                        if isempty(find(app.data.cur_oSTs == app.tV.labelN))
                                            app.data.cur_oSTs = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); 
                                        else
                                            app.data.cur_oSTs = zeros(app.data.nZ, app.data.nY); 
                                            app.data.cur_oSTs(app.data.cur_otempSTc) = app.tV.labelN; 
                                        end
                                        app.data.cur_oSTs(oCurPoint(1), oCurPoint(2)) = 0; 
                                        app.data.cur_otempSTs = find(app.data.cur_oSTs == app.tV.labelN); 
                                end
%                                 delete(h); 
                                plot_overlayEdit_label(app, curAxes); 
                            end
                        end
                        if app.tV.crosshairOn == 1
                            plotCH(app, curAxes); 
                        end
                        
                    end
                end
            end
            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %% Zoom In/Out Operation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 if app.tV.altDown==1 && app.tV.RightMouseDown==1
%                     curPoint = round(get(app.UIFigure,'CurrentPoint')); 
%                     if app.tV.RightMouseDown==0
%                         newPoint = round(get(app.UIFigure,'CurrentPoint')); 
%                         updownDist = newPoint(2) - curPoint(2) 
%                         if updownDist > 0 % moving up
%                             
%                         elseif updownDist < 0 % moving down
%                             
%                         end
%                     end
%                     
%                 end
%                 
%             end
            
        end

        % Window button down function: UIFigure
        function buttonDown(app, event)
          % Determine if the current mouse point is inside of an Axes
            dPoint = get(app.UIFigure,'CurrentPoint'); 
            curAxes = determineCurAxes(app, dPoint); 
            clickType = get(app.UIFigure, 'SelectionType'); 
            
            if ~isequal(curAxes, 'None')
              % Get current Axes & the coordinates of the mouse clicks
                eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
                curPoint = tcurAxes.CurrentPoint; 
                curPoint = round([curPoint(1,1), curPoint(1,2)]); 
                                
                switch clickType
                    case 'extend'
                        app.tV.LeftMouseDown = 1; 
                        
                    case 'normal' % Left mouse button
                        app.tV.LeftMouseDown = 1; 
                        
                          %% Set a Crosshair %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            if app.tV.altDown == 1 && app.tV.crosshairOn == 0 
                                curAxes = plotCH(app, 1); 
                                % [chPoint, curAxes] = plotCH(app, 1); 
                                switch app.tV.view
                                    case 'Double_AxialCoronal'
                                        curAxes2 = setdiff({'Axial', 'Coronal'}, curAxes); curAxes2 = curAxes2{1}; 
                                        eval(['tcurAxes = app.UIAxes_' curAxes2 ';']); 
                                        switch app.tV.display
                                            case 'Image'
                                                plot_image(app, curAxes2); 
                                            case 'ImageLabel'
                                                plot_image(app, curAxes2); hold(tcurAxes, 'on'); plot_label(app, curAxes2); hold(tcurAxes, 'off'); 
                                            case 'Label'
                                                plot_label(app, curAxes2); 
                                        end
                                        plotCH(app, curAxes2); 
                                    case 'Double_AxialSagittal'
                                        curAxes2 = setdiff({'Axial', 'Sagittal'}, curAxes); curAxes2 = curAxes2{1}; 
                                        eval(['tcurAxes = app.UIAxes_' curAxes2 ';']); 
                                        switch app.tV.display
                                            case 'Image'
                                                plot_image(app, curAxes2); 
                                            case 'ImageLabel'
                                                plot_image(app, curAxes2); hold(tcurAxes, 'on'); plot_label(app, curAxes2); hold(tcurAxes, 'off'); 
                                            case 'Label'
                                                plot_label(app, curAxes2); 
                                        end
                                        plotCH(app, curAxes2); 
                                    case 'Double_CoronalSagittal'
                                        curAxes2 = setdiff({'Coronal', 'Sagittal'}, curAxes); curAxes2 = curAxes2{1}; 
                                        eval(['tcurAxes = app.UIAxes_' curAxes2 ';']); 
                                        switch app.tV.display
                                            case 'Image'
                                                plot_image(app, curAxes2); 
                                            case 'ImageLabel'
                                                plot_image(app, curAxes2); hold(tcurAxes, 'on'); plot_label(app, curAxes2); hold(tcurAxes, 'off'); 
                                            case 'Label'
                                                plot_label(app, curAxes2); 
                                        end
                                        plotCH(app, curAxes2); 
                                    case 'Triple'
                                        curAxes23 = setdiff({'Axial', 'Coronal', 'Sagittal'}, curAxes); 
                                        curAxes2 = curAxes23{1}; 
                                        curAxes3 = curAxes23{2}; 
                                        eval(['tcurAxes2 = app.UIAxes_' curAxes2 ';']); 
                                        eval(['tcurAxes3 = app.UIAxes_' curAxes3 ';']); 
                                        switch app.tV.display
                                            case 'Image'
                                                plot_image(app, curAxes2); 
                                                plot_image(app, curAxes3); 
                                            case 'ImageLabel'
                                                plot_image(app, curAxes2); hold(tcurAxes2, 'on'); plot_label(app, curAxes2); hold(tcurAxes2, 'off'); 
                                                plot_image(app, curAxes3); hold(tcurAxes3, 'on'); plot_label(app, curAxes3); hold(tcurAxes3, 'off'); 
                                            case 'Label'
                                                plot_label(app, curAxes2); 
                                                plot_label(app, curAxes3); 
                                        end
                                        plotCH(app, curAxes2);                                         
                                        plotCH(app, curAxes3); 
                                end
                                
                                
                          %% Drawing %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            elseif app.tV.drawing_down == 1 && isequal(app.tV.display, 'ImageLabel') && app.tV.altDown==0 && app.tV.eraser_down==0 && ...
                                    (app.tV.rectSelectOn_Axial==0 && app.tV.rectSelectOn_Coronal==0 && app.tV.rectSelectOn_Sagittal==0) && ... 
                                    app.tV.altDown==0 && app.tV.shiftDown==0 && app.tV.controlDown==0 && ...
                                    app.tV.drawing==0 
                                
                                if isempty(findobj(tcurAxes, 'Type', 'images.roi.freehand'))
                                  % Update drawing on
                                    app.tV.tracing_on = 1; 
                                    app.tV.drawing = 1; 
                                
                                  % Draw freehand lines
                                    tdrawing = drawfreehand(tcurAxes, 'Color', 'red', 'LineWidth', 0.5, 'MarkerSize', 5, 'FaceAlpha', 0.5); 
                                    tdrawing.Selected = 1; 
                                    
                                  % Create binary mask of the freehand ROI
                                    eval(['curImg = app.data.img.cur' curAxes(1) 'imgT;']); 
                                    bw = tdrawing.createMask(curImg'); 
                                    app.tV.tdrawingBW.Mask = bw;
                                    app.tV.tdrawingBW.Axes = curAxes; 
                                    
                                    assignin("base", 'roi', tdrawing); 
                                    assignin("base", 'bw', bw); 
                                    assignin('base', 'app', app); 
                                    app.tV.drawing = 0; 
                                    
                                end
                                
                            
                          %% Pen plotting %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            elseif app.tV.pen_down==1 && isequal(app.tV.display, 'ImageLabel') && app.tV.altDown==0 && app.tV.eraser_down==0 && ...
                                    (app.tV.rectSelectOn_Axial==0 && app.tV.rectSelectOn_Coronal==0 && app.tV.rectSelectOn_Sagittal==0) && ... 
                                    app.tV.altDown==0 && app.tV.altDown==0 && app.tV.shiftDown==0
                              % Update tracing on
                                app.tV.tracing_on = 1; 
                                
                              %% Pen Plotting in Overlay Mode 0 & 1
                                if app.tV.overlayMode < 2
                                  % Create cur_VT and insert the curIx's tracing information from cur_tempVT
                                    cur_VT = zeros(size(app.data.V)); 
                                    cur_VT(app.data.cur_tempVT) = app.tV.labelN; 
                                    
                                  % Update tracing volume (VT#)
                                    switch curAxes
                                        case 'Axial'
                                            oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                            cur_VT(oCurPoint(1), oCurPoint(2), app.tV.curZ) = app.tV.labelN; 
                                        case 'Coronal'
                                            oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                            cur_VT(oCurPoint(1), app.tV.curY, oCurPoint(2)) = app.tV.labelN; 
                                        case 'Sagittal'
                                            oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                            cur_VT(app.tV.curX, oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                                    end
                                    app.data.cur_VT = cur_VT; 
                                    app.data.cur_tempVT = find(app.data.cur_VT(:)==app.tV.labelN); 
                                    
                                  % Delete the previous label image & Re-Plot only the label
                                    h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'Label'); % delete(h);                               
                                    hold(tcurAxes, 'on'); 
                                    plot_label(app, curAxes); 
                                    hold(tcurAxes, 'off'); 
                                    
                              %% Pen Plotting in Overlay Mode 2
                                elseif app.tV.overlayMode==2 && isequal(curAxes, app.tV.overlayPlane)
                                    h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); 
                                    if ~isempty(h) 
                                        % Update Overlay image
                                        switch curAxes
                                            case 'Axial'
                                                oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                                if isempty(find(app.data.cur_oSTa == app.tV.labelN))
                                                    app.data.cur_oSTa = squeeze(app.data.cur_VT(:, :, app.tV.curZ + app.tV.sliceNchange)); 
                                                else
                                                    app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); % <----- Need to swap the X & Y dimension for the original indexing!!
                                                    app.data.cur_oSTa(app.data.cur_otempSTa) = app.tV.labelN; 
                                                end
                                                app.data.cur_oSTa(oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                                                app.data.cur_otempSTa = find(app.data.cur_oSTa == app.tV.labelN); 
                                                app.data.oSTa.sliceN = app.tV.curZ; 
                                            case 'Coronal'
                                                oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                                if isempty(find(app.data.cur_oSTc == app.tV.labelN))
                                                    app.data.cur_oSTc = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); 
                                                else
                                                    app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); % <----- Need to swap the X & Z dimension for the original indexing!!
                                                    app.data.cur_oSTc(app.data.cur_otempSTc) = app.tV.labelN; 
                                                end
                                                app.data.cur_oSTc(oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                                                app.data.cur_otempSTc = find(app.data.cur_oSTc == app.tV.labelN); 
                                                app.data.oSTc.sliceN = app.tV.curY; 
                                            case 'Sagittal'
                                                oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                                if isempty(find(app.data.cur_oSTs == app.tV.labelN))
                                                    app.data.cur_oSTs = squeeze(app.data.cur_VT(app.tV.curX + app.tV.sliceNchange, :, :)); 
                                                else
                                                    app.data.cur_oSTs = zeros(app.data.nY, app.data.nZ); %%% <----- NO Need to swap the Y & Z dimension for the original indexing!!
                                                    % app.data.cur_oSTs = zeros(app.data.nZ, app.data.nY); 
                                                    app.data.cur_oSTs(app.data.cur_otempSTs) = app.tV.labelN; 
                                                end
                                                app.data.cur_oSTs(oCurPoint(1), oCurPoint(2)) = app.tV.labelN; 
                                                app.data.cur_otempSTs = find(app.data.cur_oSTs == app.tV.labelN); 
                                                app.data.oSTs.sliceN = app.tV.curX; 
                                        end
                                        hold(tcurAxes, 'on'); 
%                                         delete(h); 
                                        plot_overlayEdit_label(app, curAxes); 
                                        hold(tcurAxes, 'off'); 
                                    end
                                end
                                if app.tV.crosshairOn == 1
                                    plotCH(app, curAxes); 
                                end
                                

                          % Erasing %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            elseif app.tV.eraser_down==1 && isequal(app.tV.display, 'ImageLabel') && app.tV.altDown==0 && app.tV.pen_down==0 && ...
                                    (app.tV.rectSelectOn_Axial==0 && app.tV.rectSelectOn_Coronal==0 && app.tV.rectSelectOn_Sagittal==0) && ... 
                                    app.tV.altDown==0 && app.tV.altDown==0 && app.tV.shiftDown==0
                              % Update tracing on
                                app.tV.tracing_on = 1; 
                                
                              %% Erasing in Overlay Mode 0 & 1
                                if app.tV.overlayMode < 2
                                  % Create cur_VT and insert the curIx's tracing information from cur_tempVT
                                    cur_VT = zeros(size(app.data.V)); 
                                    cur_VT(app.data.cur_tempVT) = app.tV.labelN; 
                                    
                                  % Update tracing volume (cur_VT)
                                    switch curAxes
                                        case 'Axial'
                                            oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                            cur_VT(oCurPoint(1), oCurPoint(2), app.tV.curZ) = 0; 
                                        case 'Coronal'
                                            oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                            cur_VT(oCurPoint(1), app.tV.curY, oCurPoint(2)) = 0; 
                                        case 'Sagittal'
                                            oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                            cur_VT(app.tV.curX, oCurPoint(1), oCurPoint(2)) = 0; 
                                    end
                                    app.data.cur_VT = cur_VT; 
                                    app.data.cur_tempVT = find(app.data.cur_VT(:)==app.tV.labelN); 
                                    
                                  % Delete the previous label image & Re-Plot only the label
                                    h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                                    hold(tcurAxes, 'on'); plot_label(app, curAxes); hold(tcurAxes, 'off'); 
                                    
                                %% Erasing in Overlay Mode 0 & 1
                                elseif app.tV.overlayMode==2 && isequal(curAxes, app.tV.overlayPlane)
                                    h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); 
                                    if ~isempty(h) 
                                        % Update Overlay image
                                        switch curAxes
                                            case 'Axial'
                                                oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nY - curPoint(2))+1]; 
                                                if isempty(find(app.data.cur_oSTa == app.tV.labelN))
                                                    app.data.cur_oSTa = squeeze(app.data.cur_VT(:, :, app.tV.curZ + app.tV.sliceNchange)); 
                                                else
                                                    app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); % <----- Need to swap the X & Y dimension for the original indexing!!
                                                    app.data.cur_oSTa(app.data.cur_otempSTa) = app.tV.labelN; 
                                                end
                                                app.data.cur_oSTa(oCurPoint(1), oCurPoint(2)) = 0; 
                                                app.data.cur_otempSTa = find(app.data.cur_oSTa == app.tV.labelN); 
                                            case 'Coronal'
                                                oCurPoint = [(app.data.nX - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                                if isempty(find(app.data.cur_oSTc == app.tV.labelN))
                                                    app.data.cur_oSTc = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); 
                                                else
                                                    app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); 
                                                    app.data.cur_oSTc(app.data.cur_otempSTc) = app.tV.labelN; 
                                                end
                                                app.data.cur_oSTc(oCurPoint(1), oCurPoint(2)) = 0; 
                                                app.data.cur_otempSTc = find(app.data.cur_oSTc == app.tV.labelN); 
                                            case 'Sagittal'
                                                oCurPoint = [(app.data.nY - curPoint(1))+1, (app.data.nZ - curPoint(2))+1]; 
                                                if isempty(find(app.data.cur_oSTs == app.tV.labelN))
                                                    app.data.cur_oSTs = squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)); 
                                                else
                                                    app.data.cur_oSTs = zeros(app.data.nZ, app.data.nY); 
                                                    app.data.cur_oSTs(app.data.cur_otempSTc) = app.tV.labelN; 
                                                end
                                                app.data.cur_oSTs(oCurPoint(1), oCurPoint(2)) = 0; 
                                                app.data.cur_otempSTs = find(app.data.cur_oSTs == app.tV.labelN); 
                                        end
%                                         delete(h); 
                                        plot_overlayEdit_label(app, curAxes); 
                                    end
                                end
                                if app.tV.crosshairOn == 1
                                    plotCH(app, curAxes); 
                                end
                                
                            end
                            
                        case 'alt' 
                            if app.tV.controlDown == 1 % Control + Left mouse button
                                
                                
                            elseif app.tV.controlDown == 0 % Right mouse button
                                app.tV.RightMouseDown = 1; 
                                
                            end
                            
                        case 'open' % Double Click
                       
                        
                end
                
            end
            
        end

        % Window button up function: UIFigure
        function buttonUp(app, event)
            clickType = get(app.UIFigure, 'SelectionType')  ; 
            switch clickType
                case 'normal'
                    app.tV.LeftMouseDown = false; 
                    
                    if app.tV.pen_down==1 || app.tV.eraser_down==1 || app.tV.drawing_down==1
                      % Update "curIx" or "ocurIx" & "VT#.vix" or "oVT#.vix"
                        if app.tV.tracing_on == 1 && app.tV.drawing_down == 0
                            if app.tV.overlayMode < 2 && app.tV.curIx < 100
                                app.tV.curIx = app.tV.curIx + 1; 
                                app.tV.lastIx = max([app.tV.curIx, app.tV.lastIx]); 
                                eval(['app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '} = find(app.data.cur_VT(:)==app.tV.labelN);']); 
%                                 eval(['app.data.oVT' num2str(app.tV.labelN) ' = app.data.VT' num2str(app.tV.labelN) ';']); 
                            elseif app.tV.overlayMode < 2 && app.tV.curIx < 100
                                app.tV.curIx = 100; 
                                eval(['app.data.VT' num2str(app.tV.labelN) '.vix = app.data.VT' num2str(app.tV.labelN) '.vix(2:end);']); % Remove the first vix  
                                eval(['app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '} = find(app.data.cur_VT(:)==app.tV.labelN);']); % Add the new 100th vix to the end
%                                 eval(['app.data.oVT' num2str(app.tV.labelN) ' = app.data.VT' num2str(app.tV.labelN) ';']); 
                            elseif app.tV.overlayMode == 2 && app.tV.ocurIx < 100
                                app.tV.ocurIx = app.tV.ocurIx + 1; 
                                app.tV.olastIx = max([app.tV.ocurIx, app.tV.olastIx]); 
                                switch app.tV.overlayPlane
                                    case 'Axial'
                                        if app.tV.ocurIx == 2
                                            app.data.oSTa.pix{1} = find( squeeze(app.data.cur_VT(:, :, app.tV.curZ + app.tV.sliceNchange)) == app.tV.labelN); % the oSTa.pix{1} should be the previous slice's label
                                        end
                                        app.data.oSTa.sliceN = app.tV.curZ; 
                                        app.data.oSTa.pix{app.tV.ocurIx} = find(app.data.cur_oSTa == app.tV.labelN); 
                                    case 'Coronal'
                                        if app.tV.ocurIx == 2
                                            app.data.oSTc.pix{1} = find( squeeze(app.data.cur_VT(:, app.tV.curY + app.tV.sliceNchange, :)) == app.tV.labelN); % the oSTc.pix{1} should be the previous slice's label
                                        end
                                        app.data.oSTc.sliceN = app.tV.curY; 
                                        app.data.oSTc.pix{app.tV.ocurIx} = find(app.data.cur_oSTc == app.tV.labelN); 
                                    case 'Sagittal'
                                        if app.tV.ocurIx == 2
                                            app.data.oSTs.pix{1} = find( squeeze(app.data.cur_VT(app.tV.curX + app.tV.sliceNchange, :, :)) == app.tV.labelN); % the oSTs.pix{1} should be the previous slice's label
                                        end
                                        app.data.oSTs.sliceN = app.tV.curX; 
                                        app.data.oSTs.pix{app.tV.ocurIx} = find(app.data.cur_oSTs == app.tV.labelN); 
                                end
                                
%                                 eval(['app.data.oVT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.ocurIx) '} = find(app.data.cur_oVTe(:) > 0);']); 
%                                 eval(['app.data.oVT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.ocurIx) '} = find(app.data.cur_oVTe(:)==app.tV.labelN);']); 
                            elseif app.tV.overlayMode == 2 && app.tV.ocurIx < 100
                                app.tV.ocurIx = 100; 
                                switch app.tV.overlayPlane
                                    case 'Axial'
                                        app.data.oSTa.sliceN = app.tV.curZ; 
                                        app.data.oSTa.pix = app.data.oSTa.pix(2:end); % Remove the first pix
                                        app.data.oSTa.pix{app.tV.ocurIx} = find(app.data.cur_oSTa == app.tV.labelN); % Add the new 100th pix to the end
                                    case 'Coronal'
                                        app.data.oSTc.sliceN = app.tV.curY; 
                                        app.data.oSTc.pix = app.data.oSTc.pix(2:end); % Remove the first pix
                                        app.data.oSTc.pix{app.tV.ocurIx} = find(app.data.cur_oSTc == app.tV.labelN); % Add the new 100th pix to the end
                                    case 'Sagittal'
                                        app.data.oSTs.sliceN = app.tV.curX; 
                                        app.data.oSTs.pix = app.data.oSTs.pix(2:end); % Remove the first pix
                                        app.data.oSTs.pix{app.tV.ocurIx} = find(app.data.cur_oSTs == app.tV.labelN); % Add the new 100th pix to the end
                                end
                                
%                                 eval(['app.data.oVT' num2str(app.tV.labelN) '.vix = app.data.VT' num2str(app.tV.labelN) '.vix(2:end);']); % Remove the first vix  
%                                 eval(['app.data.oVT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.ocurIx) '} = find(app.data.cur_oVTe(:) > 0);']); % Add the new 100th vix to the end
% %                                 eval(['app.data.oVT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.ocurIx) '} = find(app.data.cur_oVTe(:)==app.tV.labelN);']); % Add the new 100th vix to the end
                            end
                        end
                      % Update tracing off
                        app.tV.tracing_on = 0;                                 
                    end
                    
                case 'extend'
                    app.tV.LeftMouseDown = false; 
                    
                case 'alt' 
                    if app.tV.controlDown == 1 % Control + Left mouse button
                        
                    elseif app.tV.controlDown == 0 % Right mouse button up
                        app.tV.RightMouseDown = 0; 
                        
                    end
            end
            
        end

        % Window key press function: UIFigure
        function keyPress(app, event)
            key = event.Key;
            
            switch key
                case 'shift'
                    app.tV.shiftDown = 1; 
                    app.tV.tracingModeOff = 1; 
                case 'control'
                    app.tV.controlDown = 1;
                case 'alt'
                    app.tV.altDown = 1;
                case 'delete'
                    if app.tV.crosshairOn == 1
                        if ~isempty(findobj(app.UIAxes_Axial, 'Type', 'Line'))
                            h = findobj(app.UIAxes_Axial, 'Type', 'Line'); delete(h); 
                        end
                        if ~isempty(findobj(app.UIAxes_Coronal, 'Type', 'Line'))
                            h = findobj(app.UIAxes_Coronal, 'Type', 'Line'); delete(h); 
                        end
                        if ~isempty(findobj(app.UIAxes_Sagittal, 'Type', 'Line'))
                            h = findobj(app.UIAxes_Sagittal, 'Type', 'Line'); delete(h); 
                        end
%                         plot_image_label(app)
                        app.tV.crosshairOn = 0; 
                    end
                    if ~isempty(findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'))
                        h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'); delete(h); 
                    end
                    if ~isempty(findobj(app.UIAxes_Coronal, 'Type', 'images.roi.freehand'))
                        h = findobj(app.UIAxes_Coronal, 'Type', 'images.roi.freehand'); delete(h); 
                    end
                    if ~isempty(findobj(app.UIAxes_Sagittal, 'Type', 'images.roi.freehand'))
                        h = findobj(app.UIAxes_Sagittal, 'Type', 'images.roi.freehand'); delete(h); 
                    end
                    
                case 'a'
                    if app.tV.controlDown == true
                        if isfield(app.tV, 'tdrawingBW')
                            tMask = rot90(fliplr(app.tV.tdrawingBW.Mask), 3); 
                            
                          % Create cur_VT and insert the curIx's tracing information from the label volume (VT#.vix)
                            cur_VT = zeros(size(app.data.V)); 
                            cur_VT(app.data.cur_tempVT) = app.tV.labelN; 
%                             eval(['cur_VT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
                            
                            switch app.tV.tdrawingBW.Axes 
                                case 'Axial'
                                    curImgT = squeeze(cur_VT(:, :, app.tV.curZ)); 
                                    curImgT = curImgT + tMask; 
                                    curImgT(curImgT~=0) = app.tV.labelN; 
                                    cur_VT(:, :, app.tV.curZ) = curImgT; 
                                    hold(app.UIAxes_Axial, 'on'); %plot_label(app, 'Axial'); 
                                    h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'); delete(h); 
                                case 'Coronal'
                                    curImgT = squeeze(cur_VT(:, app.tV.curY, :)); 
                                    curImgT = curImgT + tMask; 
                                    curImgT(curImgT~=0) = app.tV.labelN; 
                                    cur_VT(:, app.tV.curY, :) = curImgT; 
                                    hold(app.UIAxes_Coronal, 'on'); %plot_label(app, 'Coronal'); 
                                    h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'); delete(h); 
                                case 'Sagittal'
                                    curImgT = squeeze(cur_VT(app.tV.curX, :, :)); 
                                    curImgT = curImgT + tMask; 
                                    curImgT(curImgT~=0) = app.tV.labelN; 
                                    cur_VT(app.tV.curX, :, :) = curImgT; 
                                    hold(app.UIAxes_Sagittal, 'on'); %plot_label(app, 'Sagittal'); 
                                    h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'); delete(h); 
                            end
                            app.data.cur_VT = cur_VT; 
                            app.data.cur_tempVT = find(app.data.cur_VT(:)==app.tV.labelN); 
                            plot_image_label(app); 
                            app.tV = rmfield(app.tV, 'tdrawingBW'); 
                            
                          % Update "curIx" & "VT#.vix"
%                             if app.tV.tracing_on == 1
                                app.tV.curIx = app.tV.curIx + 1; 
                                app.tV.lastIx = max([app.tV.curIx, app.tV.lastIx]); 
                                eval(['app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '} = find(app.data.cur_VT(:)==app.tV.labelN);']); 
                                app.tV.tracing_on = 0; 
%                             end
                        end
                        
                        
                    end
                case 'd'
                    if app.tV.controlDown == true 
                        % Ctr+d -> delete the current drawing or pixels from the current label
                        
                        if isfield(app.tV, 'tdrawingBW') 
                            tMask = rot90(fliplr(app.tV.tdrawingBW.Mask), 3)*app.tV.labelN; 
                            
                          % Create cur_VT and insert the curIx's tracing information from the label volume (VT#.vix)
                            cur_VT = zeros(size(app.data.V)); 
                            cur_VT(app.data.cur_tempVT) = app.tV.labelN; 
                            
                            switch app.tV.tdrawingBW.Axes 
                                case 'Axial'
                                    curImgT = squeeze(cur_VT(:, :, app.tV.curZ)); 
                                    curImgT = curImgT - tMask; 
                                    curImgT(curImgT>0) = app.tV.labelN; 
                                    cur_VT(:, :, app.tV.curZ) = curImgT; 
                                    hold(app.UIAxes_Axial, 'on'); %plot_label(app, 'Axial'); 
                                    h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'); delete(h); 
                                case 'Coronal'
                                    curImgT = squeeze(cur_VT(:, app.tV.curY, :)); 
                                    curImgT = curImgT - tMask; 
                                    curImgT(curImgT>0) = app.tV.labelN; 
                                    cur_VT(:, app.tV.curY, :) = curImgT; 
                                    hold(app.UIAxes_Coronal, 'on'); %plot_label(app, 'Coronal'); 
                                    h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'); delete(h); 
                                case 'Sagittal'
                                    curImgT = squeeze(cur_VT(app.tV.curX, :, :)); 
                                    curImgT = curImgT - tMask; 
                                    curImgT(curImgT>0) = app.tV.labelN; 
                                    cur_VT(app.tV.curX, :, :) = curImgT; 
                                    hold(app.UIAxes_Sagittal, 'on'); %plot_label(app, 'Sagittal'); 
                                    h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.freehand'); delete(h); 
                            end
                            app.data.cur_VT = cur_VT; 
                            app.data.cur_tempVT = find(app.data.cur_VT(:)==app.tV.labelN); 
                            plot_image_label(app); 
                            app.tV = rmfield(app.tV, 'tdrawingBW'); 
                            
                          % Update "curIx" & "VT#.vix"
                            if app.tV.tracing_on == 1
                                app.tV.curIx = app.tV.curIx + 1; 
                                app.tV.lastIx = max([app.tV.curIx, app.tV.lastIx]); 
                                eval(['app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '} = find(app.data.cur_VT(:)==app.tV.labelN);']); 
                                app.tV.tracing_on = 0; 
                            end
                        end
                    end
                    
                case 'z'
                    if app.tV.controlDown == true
                    %    Ctr+z -> undo
                        if isfield(app.tV, 'curIx') && app.tV.overlayMode < 2
                            if app.tV.curIx > 1
                                app.tV.curIx = app.tV.curIx - 1; 
                                app.data.cur_VT = zeros(size(app.data.V)); 
                                eval(['app.data.cur_VT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
                                eval(['app.data.cur_tempVT = app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '};']); 
                                plot_image_label(app); 
                                if app.tV.overlayMode == 1
                                    plot_overlay_label(app, app.tV.overlayPlane)
                                end
                            end
                        elseif isfield(app.tV, 'curIx') && app.tV.overlayMode == 2
                            if app.tV.ocurIx > 1
                                switch app.tV.overlayPlane
                                    case 'Axial'
                                        if isequal(app.tV.curZ, app.data.oSTa.sliceN)
                                            app.tV.ocurIx = app.tV.ocurIx - 1; 
                                            app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); 
                                            app.data.cur_oSTa(app.data.oSTa.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                            app.data.cur_otempSTa = app.data.oSTa.pix{app.tV.ocurIx}; 
                                            plot_overlayEdit_label(app, 'Axial'); 
                                        end
                                    case 'Coronal'
                                        if isequal(app.tV.curY, app.data.oSTc.sliceN)
                                            app.tV.ocurIx = app.tV.ocurIx - 1; 
                                            app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); 
                                            app.data.cur_oSTc(app.data.oSTc.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                            app.data.cur_otempSTc = app.data.oSTc.pix{app.tV.ocurIx}; 
                                            plot_overlayEdit_label(app, 'Coronal'); 
                                        end
                                    case 'Sagittal'
                                        if isequal(app.tV.curX, app.data.oSTs.sliceN)
                                            app.tV.ocurIx = app.tV.ocurIx - 1; 
                                            app.data.cur_oSTs = zeros(app.data.nY, app.data.nZ); 
                                            app.data.cur_oSTs(app.data.oSTs.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                            app.data.cur_otempSTs = app.data.oSTs.pix{app.tV.ocurIx}; 
                                            plot_overlayEdit_label(app, 'Sagittal'); 
                                        end
                                end
                                
                            end
                            
                        end

                    end
                case 'y'
                    if app.tV.controlDown == true
                        % Ctrl+d -> redo
                        if isfield(app.tV, 'curIx') && app.tV.overlayMode < 2
                            if app.tV.curIx < 100 && app.tV.curIx < app.tV.lastIx
                                app.tV.curIx = app.tV.curIx + 1; 
                                app.data.cur_VT = zeros(size(app.data.V)); 
                                eval(['app.data.cur_VT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
                                eval(['app.data.cur_tempVT = app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '};']); 
                                plot_image_label(app); 
                                if app.tV.overlayMode ==1 
                                    plot_overlay_label(app, app.tV.overlayPlane)
                                end
                            end
                        elseif isfield(app.tV, 'curIx') && app.tV.overlayMode == 2 
                            if app.tV.ocurIx < 100 && app.tV.ocurIx < app.tV.olastIx
                                switch app.tV.overlayPlane
                                    case 'Axial'
                                        if isequal(app.tV.curZ, app.data.oSTa.sliceN)
                                            app.tV.ocurIx = app.tV.ocurIx + 1; 
                                            app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); 
                                            app.data.cur_oSTa(app.data.oSTa.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                            app.data.cur_otempSTa = app.data.oSTa.pix{app.tV.ocurIx}; 
                                            plot_overlayEdit_label(app, 'Axial'); 
                                        end
                                    case 'Coronal'
                                        if isequal(app.tV.curY, app.data.oSTc.sliceN)
                                            app.tV.ocurIx = app.tV.ocurIx + 1; 
                                            app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); 
                                            app.data.cur_oSTc(app.data.oSTc.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                            app.data.cur_otempSTc = app.data.oSTc.pix{app.tV.ocurIx}; 
                                            plot_overlayEdit_label(app, 'Coronal'); 
                                        end
                                    case 'Sagittal'
                                        if isequal(app.tV.curX, app.data.oSTs.sliceN)
                                            app.tV.ocurIx = app.tV.ocurIx + 1; 
                                            app.data.cur_oSTs = zeros(app.data.nY, app.data.nZ); 
                                            app.data.cur_oSTs(app.data.oSTs.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                            app.data.cur_otempSTs = app.data.oSTs.pix{app.tV.ocurIx}; 
                                            plot_overlayEdit_label(app, 'Sagittal'); 
                                        end
                                end
                            end
                        end
                    end
                case 'return'
                    if app.tV.rectSelectOn_Axial==1 && isfield(app.tV, 'rectSelect_Axial')
                        rectSelect_Axial = app.tV.rectSelect_Axial; 
                        rectPos = rectSelect_Axial.Position; 
                      % Reset XLim & YLim of Axial view to Zoom In
                        app.tV.Axial_curXLim = [0, rectPos(3)]+rectPos(1); 
                        app.tV.Axial_curYLim = [0, rectPos(4)]+rectPos(2); 
                        h = findobj(app.UIAxes_Axial, 'Type', 'images.roi.rectangle'); delete(h); 
                        set(app.UIAxes_Axial, 'XLim', app.tV.Axial_curXLim, 'YLim', app.tV.Axial_curYLim); 
                        app.tV.rectSelectOn_Axial = 0; 
                        
                        h = findobj(app.UIAxes_Axial, 'Type', 'Text'); 
                        if ~isempty(h)
                            delete(h); 
                            x4text = app.UIAxes_Axial.XLim(1)+3; 
                            y4text = app.UIAxes_Axial.YLim(2)-3; 
                            if app.tV.overlayMode==1
                                text(app.UIAxes_Axial, x4text, y4text, ['Overlay Label: Axial (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.showColor); 
                            elseif app.tV.overlayMode==1
                                text(app.UIAxes_Axial, x4text, y4text, ['Overlay Edit Label: Axial (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.editColor); 
                            end
                        end
                        
                    elseif app.tV.rectSelectOn_Coronal==1 && isfield(app.tV, 'rectSelect_Coronal')
                        rectSelect_Coronal = app.tV.rectSelect_Coronal; 
                        rectPos = rectSelect_Coronal.Position; 
                        h = findobj(app.UIAxes_Coronal, 'Type', 'Rectangle'); delete(h); 
                      % Reset XLim & YLim of Coronal view to Zoom In
                        app.tV.Coronal_curXLim = [0, rectPos(3)]+rectPos(1); 
                        app.tV.Coronal_curYLim = [0, rectPos(4)]+rectPos(2); 
                        h = findobj(app.UIAxes_Coronal, 'Type', 'images.roi.rectangle'); delete(h); 
                        set(app.UIAxes_Coronal, 'XLim', app.tV.Coronal_curXLim, 'YLim', app.tV.Coronal_curYLim); 
                        app.tV.rectSelectOn_Coronal = 0;  
                        
                        h = findobj(app.UIAxes_Coronal, 'Type', 'Text'); 
                        if ~isempty(h)
                            delete(h); 
                            x4text = app.UIAxes_Coronal.XLim(1)+3; 
                            y4text = app.UIAxes_Coronal.YLim(2)-3; 
                            if app.tV.overlayMode==1
                                text(app.UIAxes_Coronal, x4text, y4text, ['Overlay Label: Coronal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.showColor); 
                            elseif app.tV.overlayMode==1
                                text(app.UIAxes_Coronal, x4text, y4text, ['Overlay Edit Label: Coronal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.editColor); 
                            end
                        end
                        
                    elseif app.tV.rectSelectOn_Sagittal==1 && isfield(app.tV, 'rectSelect_Sagittal')
                        rectSelect_Sagittal = app.tV.rectSelect_Sagittal; 
                        rectPos = rectSelect_Sagittal.Position; 
                      % Reset XLim & YLim of Sagittal view to Zoom In
                        app.tV.Sagittal_curXLim = [0, rectPos(3)]+rectPos(1); 
                        app.tV.Sagittal_curYLim = [0, rectPos(4)]+rectPos(2); 
                        h = findobj(app.UIAxes_Sagittal, 'Type', 'images.roi.rectangle'); delete(h); 
                        set(app.UIAxes_Sagittal, 'XLim', app.tV.Sagittal_curXLim, 'YLim', app.tV.Sagittal_curYLim); 
                        app.tV.rectSelectOn_Sagittal = 0; 
                        
                        h = findobj(app.UIAxes_Sagittal, 'Type', 'Text'); 
                        if ~isempty(h)
                            delete(h); 
                            x4text = app.UIAxes_Sagittal.XLim(1)+3; 
                            y4text = app.UIAxes_Sagittal.YLim(2)-3; 
                            if app.tV.overlayMode==1
                                text(app.UIAxes_Sagittal, x4text, y4text, ['Overlay Label: Sagittal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.showColor); 
                            elseif app.tV.overlayMode==1
                                text(app.UIAxes_Sagittal, x4text, y4text, ['Overlay Edit Label: Sagittal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.editColor); 
                            end
                        end
                        
                    end
                    
                case 's'
                    %% Show Label On/Off
                    if isequal(app.tV.display, 'ImageLabel')
                        switch app.tV.view
                            case 'Single_Axial'
                                h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                end                                    
                            case 'Single_Coronal'
                                h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                end 
                            case 'Single_Sagittal'
                                h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                end 
                            case 'Double_AxialCoronal'
                                h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                end 
                                h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                end 
                            case 'Double_AxialSagittal'
                                h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                end 
                                h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                end 
                            case 'Double_CoronalSagittal'
                                h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                end 
                                h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h)x
                                    delete(h); 
                                else
                                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                end 
                            case 'Triple'
                                h1 = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h1)
                                    delete(h1); 
                                else
                                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                end 
                                h2 = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h2)
                                    delete(h2); 
                                else
                                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                end 
                                h3 = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); 
                                if ~isempty(h3)
                                    delete(h3); 
                                else
                                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                end
                        end
                    end
                        
                case 'x' 
                        %% Show OverlayLabel On/Off
                        if isequal(app.tV.display, 'ImageLabel')
                            eval(['tcurAxes = app.UIAxes_' app.tV.overlayPlane ';']); 
                            h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); 
                            if ~isempty(h)
                                delete(h); 
                                h = findobj(tcurAxes, 'Type', 'Text'); delete(h); 
                            else
                                switch app.tV.overlayMode
                                    case 1
                                        hold(tcurAxes, 'on'); plot_overlay_label(app, app.tV.overlayPlane); hold(tcurAxes, 'off'); 
                                    case 2
                                        hold(tcurAxes, 'on'); plot_overlayEdit_label(app, app.tV.overlayPlane); hold(tcurAxes, 'off'); 
                                end
                            end
                        end
                        
                case '1'
                    app.tV.sliceNchange = -1; 
                    app.tV.sliceNchangeText = '-1'; 
                    if app.tV.overlayMode == 1
                        plot_overlay_label(app, app.tV.overlayPlane); 
                    elseif app.tV.overlayMode == 2
                        plot_overlayEdit_label(app, app.tV.overlayPlane); 
                    end
                        
                case '2'
                    app.tV.sliceNchange = 1; 
                    app.tV.sliceNchangeText = '+1'; 
                    if app.tV.overlayMode == 1
                        plot_overlay_label(app, app.tV.overlayPlane); 
                    elseif app.tV.overlayMode == 2
                        plot_overlayEdit_label(app, app.tV.overlayPlane); 
                    end
                case 'c'
                    if app.tV.controlDown == true
                        if app.tV.overlayMode==2
                            app.tV.ocurIx = 1; 
                            switch app.tV.overlayPlane
                                case 'Axial'
                                    app.data.cur_VT(:, :, app.tV.curZ) = app.data.cur_oSTa; 
                                    app.data.oSTa.sliceN = 0; 
                                    app.data.oSTa.pix = cell(1,100);                                     
                                case 'Coronal'
                                    app.data.cur_VT(:, app.tV.curY, :) = app.data.cur_oSTc; 
                                    app.data.oSTc.sliceN = 0; 
                                    app.data.oSTc.pix = cell(1,100); 
                                case 'Sagittal'
                                    app.data.cur_VT(app.tV.curX, :, :) = app.data.cur_oSTs; 
                                    app.data.oSTa.sliceN = 0; 
                                    app.data.oSTa.pix = cell(1,100); 
                            end
                            eval(['tcurAxes = app.UIAxes_' app.tV.overlayPlane ';']); 
                            eval(['app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '} = find(app.data.cur_VT(:)==app.tV.labelN);']); 
                            plot_image_label(app); 
                            h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); delete(h); 
                            h = findobj(tcurAxes, 'Type', 'Text'); delete(h); 
                                x4text = tcurAxes.XLim(1)+3; 
                                y4text = tcurAxes.YLim(2)-3; 
                                h = findobj(tcurAxes, 'Type', 'Text'); delete(h); 
                                text(tcurAxes, x4text, y4text, ['Overlay Label Edit Done!'], 'FontSize', 20, 'Color', app.tV.editColor); 
                                hold(tcurAxes, 'off'); 
                            
                        end
                    end
                case 'uparrow'
                    dPoint = get(app.UIFigure,'CurrentPoint'); 
                    curAxes = determineCurAxes(app, dPoint); 
                    if ~isequal(curAxes, 'None')
                        eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
                        
                        switch curAxes
                            case 'Axial'
                                app.tV.curZ = app.tV.curZ + 1; 
                                app.Slider_Axial.Value = app.tV.curZ; 
                                app.Spinner_Axial.Value = app.tV.curZ; 
                            case 'Coronal'
                                app.tV.curY = app.tV.curY + 1; 
                                app.Slider_Coronal.Value = app.tV.curY; 
                                app.Spinner_Coronal.Value = app.tV.curY; 
                            case 'Sagittal'
                                app.tV.curX = app.tV.curX + 1; 
                                app.Slider_Sagittal.Value = app.tV.curX; 
                                app.Spinner_Sagittal.Value = app.tV.curX; 
                        end
                        prepare2Dimages(app); 
                        switch app.tV.display
                            case 'Image'
                                plot_image(app, curAxes); 
                            case 'ImageLabel'
                                plot_image(app, curAxes); hold(tcurAxes, 'on'); 
                                plot_label(app, curAxes); hold(tcurAxes, 'off'); 
                            case 'Label'
                                plot_label_only(app, curAxes); 
                        end
                        if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, curAxes)
                            plot_overlay_label(app, curAxes)
                        elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, curAxes)
                            plot_overlayEdit_label(app, curAxes) 
                        end
                        if app.tV.crosshairOn == 1
                            plotCH(app, curAxes); 
                        end
                    end
                    
                case 'downarrow'
                    dPoint = get(app.UIFigure,'CurrentPoint'); 
                    curAxes = determineCurAxes(app, dPoint); 
                    if ~isequal(curAxes, 'None')
                        eval(['tcurAxes = app.UIAxes_' curAxes ';']); 
                        
                        switch curAxes
                            case 'Axial'
                                app.tV.curZ = app.tV.curZ - 1; 
                                app.Slider_Axial.Value = app.tV.curZ; 
                                app.Spinner_Axial.Value = app.tV.curZ; 
                            case 'Coronal'
                                app.tV.curY = app.tV.curY - 1; 
                                app.Slider_Coronal.Value = app.tV.curY; 
                                app.Spinner_Coronal.Value = app.tV.curY; 
                            case 'Sagittal'
                                app.tV.curX = app.tV.curX - 1; 
                                app.Slider_Sagittal.Value = app.tV.curX; 
                                app.Spinner_Sagittal.Value = app.tV.curX; 
                        end
                        prepare2Dimages(app); 
                        switch app.tV.display
                            case 'Image'
                                plot_image(app, curAxes); 
                            case 'ImageLabel'
                                plot_image(app, curAxes); hold(tcurAxes, 'on'); 
                                plot_label(app, curAxes); hold(tcurAxes, 'off'); 
                            case 'Label'
                                plot_label_only(app, curAxes); 
                        end
                        if app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, curAxes)
                            plot_overlay_label(app, curAxes)
                        elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, curAxes)
                            plot_overlayEdit_label(app, curAxes) 
                        end
                        if app.tV.crosshairOn == 1
                            plotCH(app, curAxes); 
                        end
                    end
                    
            end
        end

        % Window key release function: UIFigure
        function keyRelease(app, event)
            key = event.Key;
            
            switch key
                case 'shift'
                    app.tV.shiftDown = 0; 
                case 'control'
                    app.tV.controlDown = 0;
                case 'alt'
                    app.tV.altDown = 0;
            end
        end

        % Window scroll wheel function: UIFigure
        function mouseScroll(app, event)
            if app.tV.mriPlotted == 1 && app.tV.shiftDown==0 && app.tV.controlDown==0
              %% Navigating across Slices %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                dPoint = round(get(app.UIFigure,'CurrentPoint')); 
                curAxes = determineCurAxes(app, dPoint); 
                switch curAxes
                    case 'Axial'
    %                     verticalScrollAmount = event.VerticalScrollAmount
                        verticalScrollCount = event.VerticalScrollCount; 
                        
                        if verticalScrollCount > 0 % scroll down
                          % Down curZ to show the Lower slice
                            app.tV.curZ = app.tV.curZ - 1; 
                            prepare2Dimages(app); 
                            switch app.tV.display
                                case 'Image'
                                    plot_image(app, 'Axial'); 
                                case 'ImageLabel'
                                    plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                                    if app.tV.overlayMode==0
                                        plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                    elseif app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Axial')
                                        plot_overlay_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                    elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Axial')
                                        plot_overlayEdit_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                    end
                                case 'Label'
                                    plot_label_only(app, 'Axial'); 
                            end
                            
                        elseif verticalScrollCount < 0 % scroll up
                          % Up curZ to show the Upper slice
                            app.tV.curZ = app.tV.curZ + 1; 
                            prepare2Dimages(app); 
                            switch app.tV.display
                                case 'Image'
                                    plot_image(app, 'Axial'); 
                                case 'ImageLabel'
                                    plot_image(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                                    if app.tV.overlayMode==0
                                        plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                                    elseif app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Axial')
                                        plot_overlay_label(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                                    elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Axial')
                                        plot_overlayEdit_label(app, 'Axial'); hold(app.UIAxes_Axial, 'on'); 
                                    end
                                case 'Label'
                                    plot_label_only(app, 'Axial'); 
                            end
                        end
                        if app.tV.crosshairOn == 1
                            plotCH(app, 'Axial'); 
                        end
                        
                    case 'Coronal'
    %                     verticalScrollAmount = event.VerticalScrollAmount
                        verticalScrollCount = event.VerticalScrollCount; 
                        
                        if verticalScrollCount > 0 % scroll down
                          % Down curY to show the Posterior slice
                            app.tV.curY = app.tV.curY - 1; 
                            prepare2Dimages(app); 
                            switch app.tV.display
                                case 'Image'
                                    plot_image(app, 'Coronal'); 
                                case 'ImageLabel'
                                    plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                                    if app.tV.overlayMode==0
                                        plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                    elseif app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Coronal')
                                        plot_overlay_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                    elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Coronal')
                                        plot_overlayEdit_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                    end
                                case 'Label'
                                    plot_label_only(app, 'Coronal'); 
                            end
                        elseif verticalScrollCount < 0 % scroll up
                          % Up curY to show the Anterior slice
                            app.tV.curY = app.tV.curY + 1; 
                            prepare2Dimages(app); 
                            switch app.tV.display
                                case 'Image'
                                    plot_image(app, 'Coronal'); 
                                case 'ImageLabel'
                                    plot_image(app, 'Coronal'); hold(app.UIAxes_Coronal, 'on'); 
                                    if app.tV.overlayMode==0
                                        plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                    elseif app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Coronal')
                                        plot_overlay_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                    elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Coronal')
                                        plot_overlayEdit_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                                    end
                                case 'Label'
                                    plot_label_only(app, 'Coronal'); 
                            end
                        end
                        if app.tV.crosshairOn == 1
                            plotCH(app, 'Coronal'); 
                        end
                        
                    case 'Sagittal'
    %                     verticalScrollAmount = event.VerticalScrollAmount
                        verticalScrollCount = event.VerticalScrollCount; 
                        
                        if verticalScrollCount > 0 % scroll down
                          % Down curX to show the Left slice
                            app.tV.curX = app.tV.curX - 1; 
                            prepare2Dimages(app); 
                            switch app.tV.display
                                case 'Image'
                                    plot_image(app, 'Sagittal'); 
                                case 'ImageLabel'
                                    plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                                    if app.tV.overlayMode==0
                                        plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                    elseif app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Sagittal')
                                        plot_overlay_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                    elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Sagittal')
                                        plot_overlayEdit_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                    end
                                case 'Label'
                                    plot_label_only(app, 'Sagittal'); 
                            end
                            
                        elseif verticalScrollCount < 0 % scroll up
                          % Up curX to show the Right slice
                            app.tV.curX = app.tV.curX + 1; 
                            prepare2Dimages(app); 
                            switch app.tV.display
                                case 'Image'
                                    plot_image(app, 'Sagittal'); 
                                case 'ImageLabel'
                                    plot_image(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'on'); 
                                    if app.tV.overlayMode==0
                                        plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                    elseif app.tV.overlayMode==1 && isequal(app.tV.overlayPlane, 'Sagittal')
                                        plot_overlay_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                    elseif app.tV.overlayMode==2 && isequal(app.tV.overlayPlane, 'Sagittal')
                                        plot_overlayEdit_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                                    end
                                case 'Label'
                                    plot_label_only(app, 'Sagittal'); 
                            end
                        end
                        if app.tV.crosshairOn == 1
                            plotCH(app, 'Sagittal'); 
                        end
                        
                end
                
            elseif app.tV.mriPlotted == 1 && app.tV.shiftDown == 1 && (isequal(app.tV.display, 'ImageLabel') || isequal(app.tV.display, 'Label'))
              %% Adjusting Label Opacity %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                verticalScrollCount = event.VerticalScrollCount; 
                if verticalScrollCount > 0 % scroll down
                    if app.tV.alphaL>=.1
                        app.tV.alphaL = app.tV.alphaL - .1; 
                    end
                elseif verticalScrollCount < 0 % scroll up
                    if app.tV.alphaL<=.9
                        app.tV.alphaL = app.tV.alphaL + .1; 
                    end
                end
                app.Opacity_Slider.Value = app.tV.alphaL; 
                switch app.tV.view
                    case 'Single_Axial'
                        h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                    case 'Single_Coronal'
                        h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                    case 'Single_Sagittal'
                        h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                    case 'Double_AxialCoronal'
                        h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Axial, 'on');   plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                        h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                    case 'Double_AxialSagittal'
                      % Delete the previous label image & Re-Plot only the label
                        h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                        h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                    case 'Double_CoronalSagittal'
                        h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                        h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                    case 'Triple'
                        h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                        h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                        h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                        hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                end
                   
            elseif app.tV.mriPlotted == 1 && app.tV.controlDown == 1 && isequal(app.tV.display, 'ImageLabel')
              %% Adjusting Overlay Label Opacity %%%%%%%%%%%%%%%%%%%%%%%%%%
                verticalScrollCount = event.VerticalScrollCount; 
                if verticalScrollCount > 0 % scroll down
                    if app.tV.overlayAlphaL>=.1
                        app.tV.overlayAlphaL = app.tV.overlayAlphaL - .1; 
                    end
                elseif verticalScrollCount < 0 % scroll up
                    if app.tV.overlayAlphaL<=.9
                        app.tV.overlayAlphaL = app.tV.overlayAlphaL + .1; 
                    end
                end
                app.OpacityOverlay_Slider.Value = app.tV.overlayAlphaL; 
                eval(['tcurAxes = app.UIAxes_' app.tV.overlayPlane ';']); 
                h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); delete(h); 
                if app.tV.overlayMode==1
                    plot_overlay_label(app, app.tV.overlayPlane); 
                elseif app.tV.overlayMode==2
                    plot_overlayEdit_label(app, app.tV.overlayPlane); 
                end
                
            end
            
        end

        % Button pushed function: Button_select_Axial
        function Button_select_AxialPushed(app, event)
            
          % Select a range of Axial view
            app.tV.rectSelectOn_Axial = 1; 
            rectSelect = drawrectangle(app.UIAxes_Axial); 
            rectSelect.Position = round(rectSelect.Position); 
            app.tV.rectSelect_Axial = rectSelect; 
            
        end

        % Button pushed function: Button_home_Axial
        function Button_home_AxialPushed(app, event)
            
          % Reset the XLim & YLim of Axial view to the full Image
            app.tV.Axial_curXLim = app.tV.imgR(1,:); 
            app.tV.Axial_curYLim = app.tV.imgR(2,:); 
            set(app.UIAxes_Axial, 'XLim', app.tV.Axial_curXLim, 'YLim', app.tV.Axial_curYLim); 
            if app.tV.crosshairOn==1
                plotCH(app, 'Axial'); 
            end
            h = findobj(app.UIAxes_Axial, 'Type', 'Text'); 
            if ~isempty(h)
                delete(h); 
                x4text = app.UIAxes_Axial.XLim(1)+3; 
                y4text = app.UIAxes_Axial.YLim(2)-3; 
                if app.tV.overlayMode==1
                    text(app.UIAxes_Axial, x4text, y4text, ['Overlay Label: Axial (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.showColor); 
                elseif app.tV.overlayMode==1
                    text(app.UIAxes_Axial, x4text, y4text, ['Overlay Edit Label: Axial (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.editColor); 
                end
            end
            
        end

        % Button pushed function: Button_select_Coronal
        function Button_select_CoronalPushed(app, event)
            
          % Select a range of Coronal view
            app.tV.rectSelectOn_Coronal = 1; 
            rectSelect = drawrectangle(app.UIAxes_Coronal); 
            rectSelect.Position = round(rectSelect.Position); 
            app.tV.rectSelect_Coronal = rectSelect; 
            
        end

        % Button pushed function: Button_home_Coronal
        function Button_home_CoronalPushed(app, event)
            
          % Reset the XLim & YLim of Coronal view to the full Image
            app.tV.Coronal_curXLim = app.tV.imgR(1,:); 
            app.tV.Coronal_curYLim = app.tV.imgR(3,:); 
            set(app.UIAxes_Coronal, 'XLim', app.tV.Coronal_curXLim, 'YLim', app.tV.Coronal_curYLim); 
            if app.tV.crosshairOn
                plotCH(app, 'Coronal'); 
            end
            
            h = findobj(app.UIAxes_Coronal, 'Type', 'Text'); 
            if ~isempty(h)
                delete(h); 
                x4text = app.UIAxes_Coronal.XLim(1)+3; 
                y4text = app.UIAxes_Coronal.YLim(2)-3; 
                if app.tV.overlayMode==1
                    text(app.UIAxes_Coronal, x4text, y4text, ['Overlay Label: Coronal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.showColor); 
                elseif app.tV.overlayMode==1
                    text(app.UIAxes_Coronal, x4text, y4text, ['Overlay Edit Label: Coronal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.editColor); 
                end
            end
            
        end

        % Button pushed function: Button_select_Sagittal
        function Button_select_SagittalPushed(app, event)
            
          % Select a range of Sagittal view
            app.tV.rectSelectOn_Sagittal = 1; 
            rectSelect = drawrectangle(app.UIAxes_Sagittal); 
            rectSelect.Position = round(rectSelect.Position); 
            app.tV.rectSelect_Sagittal = rectSelect; 
            
        end

        % Button pushed function: Button_home_Sagittal
        function Button_home_SagittalPushed(app, event)
            
          % Reset the XLim & YLim of Sagittal view to the full Image
            app.tV.Sagittal_curXLim = app.tV.imgR(2,:); 
            app.tV.Sagittal_curYLim = app.tV.imgR(3,:); 
            set(app.UIAxes_Sagittal, 'XLim', app.tV.Sagittal_curXLim, 'YLim', app.tV.Sagittal_curYLim); 
            if app.tV.crosshairOn
                plotCH(app, 'Sagittal'); 
            end
            
            h = findobj(app.UIAxes_Sagittal, 'Type', 'Text'); 
            if ~isempty(h)
                delete(h); 
                x4text = app.UIAxes_Sagittal.XLim(1)+3; 
                y4text = app.UIAxes_Sagittal.YLim(2)-3; 
                if app.tV.overlayMode==1
                    text(app.UIAxes_Sagittal, x4text, y4text, ['Overlay Label: Sagittal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.showColor); 
                elseif app.tV.overlayMode==1
                    text(app.UIAxes_Sagittal, x4text, y4text, ['Overlay Edit Label: Sagittal (' app.tV.sliceNchangeText ')'], 'FontSize', 15, 'Color', app.tV.editColor); 
                end
            end
            
        end

        % Size changed function: UIFigure
        function FigResize(app, event)
            if ~isfield(app.data, 'V')
                position = app.UIFigure.Position; 
                app.dV.aH = position(4); 
                
              % Set the Positions of the left display option panels
                app.Panel_DisplayParameters.Units   = 'pixels'; 
                app.ButtonGroup_DisplayOption.Units = 'pixels'; 
                app.Panel_TracingLabels.Units       = 'pixels'; 
                app.Panel_TracingTools.Units        = 'pixels'; 
                
              % Set the Left Panel position variables
                app.dV.LP_Rm = 12; 
                app.dV.LP_Hm = 10; 
                app.dV.LP_w = 160; 
                    app.dV.DP_h = 243; 
                    app.dV.DO_h = 100; 
                    app.dV.TL_h = 187; 
                    app.dV.TT_h = 121; 
                    app.dV.LO_h = 152; 
                app.dV.DP_b = app.dV.aH - (app.dV.DP_h + app.dV.LP_Hm*1); 
                app.dV.DO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.LP_Hm*2); 
                app.dV.TL_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.LP_Hm*3); 
                app.dV.TT_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LP_Hm*4); 
                app.dV.LO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LO_h + app.dV.LP_Hm*5); 
                
                app.Panel_DisplayParameters.Position =   [app.dV.LP_Rm,   app.dV.DP_b,   app.dV.LP_w,   app.dV.DP_h]; 
                app.ButtonGroup_DisplayOption.Position = [app.dV.LP_Rm,   app.dV.DO_b,   app.dV.LP_w,   app.dV.DO_h]; 
                app.Panel_TracingLabels.Position =       [app.dV.LP_Rm,   app.dV.TL_b,   app.dV.LP_w,   app.dV.TL_h]; 
                app.Panel_TracingTools.Position =        [app.dV.LP_Rm,   app.dV.TT_b,   app.dV.LP_w,   app.dV.TT_h]; 
                app.Panel_LabelOverlay.Position =        [app.dV.LP_Rm,   app.dV.LO_b,   app.dV.LP_w,   app.dV.LO_h]; 

%               % Set the Left Panel position variables
%                 app.dV.LP_Rm = 15; 
%                 app.dV.LP_Hm = 15; 
%                 app.dV.LP_w = 160; 
%                     app.dV.DP_h = 282; 
%                     app.dV.DO_h = 123; 
%                     app.dV.TL_h = 250; 
%                     app.dV.TT_h = 170; 
%                 app.dV.DP_b = app.dV.aH - (app.dV.DP_h + app.dV.LP_Hm*1); 
%                 app.dV.DO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.LP_Hm*2); 
%                 app.dV.TL_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.LP_Hm*3); 
%                 app.dV.TT_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LP_Hm*4); 
%                 
%                 app.Panel_DisplayParameters.Position =   [app.dV.LP_Rm,   app.dV.DP_b,   app.dV.LP_w,   app.dV.DP_h]; 
%                 app.ButtonGroup_DisplayOption.Position = [app.dV.LP_Rm,   app.dV.DO_b,   app.dV.LP_w,   app.dV.DO_h]; 
%                 app.Panel_TracingLabels.Position =       [app.dV.LP_Rm,   app.dV.TL_b,   app.dV.LP_w,   app.dV.TL_h]; 
%                 app.Panel_TracingTools.Position =        [app.dV.LP_Rm,   app.dV.TT_b,   app.dV.LP_w,   app.dV.TT_h]; 
                
            else
              % Resize UIAxes when the UIFigure is resized. 
                app.dV.aW = app.UIFigure.Position(3); 
                app.dV.aH = app.UIFigure.Position(4); 
                determinePositions(app); 
                prepare_AxesControls(app); 
                plot_image_label(app); 
                
              % Set the Positions of the left display option panels
                app.Panel_DisplayParameters.Units   = 'pixels'; 
                app.ButtonGroup_DisplayOption.Units = 'pixels'; 
                app.Panel_TracingLabels.Units       = 'pixels'; 
                app.Panel_TracingTools.Units        = 'pixels'; 
                
              % Set the Left Panel position variables
                app.dV.LP_Rm = 12; 
                app.dV.LP_Hm = 10; 
                app.dV.LP_w = 160; 
                    app.dV.DP_h = 243; 
                    app.dV.DO_h = 100; 
                    app.dV.TL_h = 187; 
                    app.dV.TT_h = 121; 
                    app.dV.LO_h = 152; 
                app.dV.DP_b = app.dV.aH - (app.dV.DP_h + app.dV.LP_Hm*1); 
                app.dV.DO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.LP_Hm*2); 
                app.dV.TL_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.LP_Hm*3); 
                app.dV.TT_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LP_Hm*4); 
                app.dV.LO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LO_h + app.dV.LP_Hm*5); 
                
                app.Panel_DisplayParameters.Position =   [app.dV.LP_Rm,   app.dV.DP_b,   app.dV.LP_w,   app.dV.DP_h]; 
                app.ButtonGroup_DisplayOption.Position = [app.dV.LP_Rm,   app.dV.DO_b,   app.dV.LP_w,   app.dV.DO_h]; 
                app.Panel_TracingLabels.Position =       [app.dV.LP_Rm,   app.dV.TL_b,   app.dV.LP_w,   app.dV.TL_h]; 
                app.Panel_TracingTools.Position =        [app.dV.LP_Rm,   app.dV.TT_b,   app.dV.LP_w,   app.dV.TT_h]; 
                app.Panel_LabelOverlay.Position =        [app.dV.LP_Rm,   app.dV.LO_b,   app.dV.LP_w,   app.dV.LO_h]; 

%               % Set the Left Panel position variables
%                 app.dV.LP_Rm = 15; 
%                 app.dV.LP_Hm = 15; 
%                 app.dV.LP_w = 160; 
%                     app.dV.DP_h = 282; 
%                     app.dV.DO_h = 123; 
%                     app.dV.TL_h = 250; 
%                     app.dV.TT_h = 170; 
%                 app.dV.DP_b = app.dV.aH - (app.dV.DP_h + app.dV.LP_Hm*1); 
%                 app.dV.DO_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.LP_Hm*2); 
%                 app.dV.TL_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.LP_Hm*3); 
%                 app.dV.TT_b = app.dV.aH - (app.dV.DP_h + app.dV.DO_h + app.dV.TL_h + app.dV.TT_h + app.dV.LP_Hm*4); 
%                 
%                 app.Panel_DisplayParameters.Position =   [app.dV.LP_Rm,   app.dV.DP_b,   app.dV.LP_w,   app.dV.DP_h]; 
%                 app.ButtonGroup_DisplayOption.Position = [app.dV.LP_Rm,   app.dV.DO_b,   app.dV.LP_w,   app.dV.DO_h]; 
%                 app.Panel_TracingLabels.Position =       [app.dV.LP_Rm,   app.dV.TL_b,   app.dV.LP_w,   app.dV.TL_h]; 
%                 app.Panel_TracingTools.Position =        [app.dV.LP_Rm,   app.dV.TT_b,   app.dV.LP_w,   app.dV.TT_h]; 
                
            end
            
        end

        % Value changed function: StateButton_pen
        function StateButton_penValueChanged(app, event)
            app.tV.pen_down = app.StateButton_pen.Value;
            if app.tV.pen_down==1
                app.tV.drawing_down = 0; 
                app.tV.eraser_down   = 0; 
                app.StateButton_drawing.Value = 0; 
                app.StateButton_eraser.Value  = 0;  
              % Change UIFigure Pointer type to cross
                app.UIFigure.Pointer = 'cross'; 
            else
              % Change UIFigure Pointer type to arrow
                app.UIFigure.Pointer = 'arrow'; 
            end
        end

        % Value changed function: StateButton_eraser
        function StateButton_eraserValueChanged(app, event)
            app.tV.eraser_down = app.StateButton_eraser.Value;
            if app.tV.eraser_down==1
                app.tV.drawing_down = 0; 
                app.tV.pen_down     = 0; 
                app.StateButton_drawing.Value = 0; 
                app.StateButton_pen.Value     = 0; 
              % Change UIFigure Pointer type to cross
                app.UIFigure.Pointer = 'crosshair'; 
            else
              % Change UIFigure Pointer type to arrow
                app.UIFigure.Pointer = 'arrow'; 
            end
        end

        % Value changed function: StateButton_drawing
        function StateButton_drawingValueChanged(app, event)
            app.tV.drawing_down = app.StateButton_drawing.Value;
            if app.tV.drawing_down==1
                app.tV.pen_down     = 0; 
                app.tV.eraser_down   = 0; 
                app.StateButton_pen.Value     = 0; 
                app.StateButton_eraser.Value  = 0; 
%               % Change UIFigure Pointer type to cross
%                 app.UIFigure.Pointer = 'circle'; 
%             else
              % Change UIFigure Pointer type to arrow
                app.UIFigure.Pointer = 'arrow'; 
            end
        end

        % Button pushed function: Undo_button
        function Undo_buttonPushed(app, event)
            if isfield(app.tV, 'curIx') && app.tV.overlayMode < 2
                if app.tV.curIx > 1
                    app.tV.curIx = app.tV.curIx - 1; 
                    app.data.cur_VT = zeros(size(app.data.V)); 
                    eval(['app.data.cur_VT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
                    eval(['app.data.cur_tempVT = app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '};']); 
                    plot_image_label(app); 
                    if app.tV.overlayMode == 1
                        plot_overlay_label(app, app.tV.overlayPlane)
                    end
                end
            elseif isfield(app.tV, 'curIx') && app.tV.overlayMode == 2
                if app.tV.ocurIx > 1
                    switch app.tV.overlayPlane
                        case 'Axial'
                            if isequal(app.tV.curZ, app.data.oSTa.sliceN)
                                app.tV.ocurIx = app.tV.ocurIx - 1; 
                                app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); 
                                app.data.cur_oSTa(app.data.oSTa.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                app.data.cur_otempSTa = app.data.oSTa.pix{app.tV.ocurIx}; 
                                plot_overlayEdit_label(app, 'Axial'); 
                            end
                        case 'Coronal'
                            if isequal(app.tV.curY, app.data.oSTc.sliceN)
                                app.tV.ocurIx = app.tV.ocurIx - 1; 
                                app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); 
                                app.data.cur_oSTc(app.data.oSTc.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                app.data.cur_otempSTc = app.data.oSTc.pix{app.tV.ocurIx}; 
                                plot_overlayEdit_label(app, 'Coronal'); 
                            end
                        case 'Sagittal'
                            if isequal(app.tV.curX, app.data.oSTs.sliceN)
                                app.tV.ocurIx = app.tV.ocurIx - 1; 
                                app.data.cur_oSTs = zeros(app.data.nY, app.data.nZ); 
                                app.data.cur_oSTs(app.data.oSTs.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                app.data.cur_otempSTs = app.data.oSTs.pix{app.tV.ocurIx}; 
                                plot_overlayEdit_label(app, 'Sagittal'); 
                            end
                    end
                    
                end
                
            end
            
            % Updo during overlayEdit mode -> when osTa.slicN==curZ
            

        end

        % Button pushed function: Redo_button
        function Redo_buttonPushed(app, event)
            if isfield(app.tV, 'curIx') && app.tV.overlayMode < 2
                if app.tV.curIx < 100 && app.tV.curIx < app.tV.lastIx
                    app.tV.curIx = app.tV.curIx + 1; 
                    app.data.cur_VT = zeros(size(app.data.V)); 
                    eval(['app.data.cur_VT(app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '}) = app.tV.labelN;']); 
                    eval(['app.data.cur_tempVT = app.data.VT' num2str(app.tV.labelN) '.vix{' num2str(app.tV.curIx) '};']); 
                    plot_image_label(app); 
                    if app.tV.overlayMode ==1 
                        plot_overlay_label(app, app.tV.overlayPlane)
                    end
                end
            elseif isfield(app.tV, 'curIx') && app.tV.overlayMode == 2 
                if app.tV.ocurIx < 100 && app.tV.ocurIx < app.tV.olastIx
                    switch app.tV.overlayPlane
                        case 'Axial'
                            if isequal(app.tV.curZ, app.data.oSTa.sliceN)
                                app.tV.ocurIx = app.tV.ocurIx + 1; 
                                app.data.cur_oSTa = zeros(app.data.nX, app.data.nY); 
                                app.data.cur_oSTa(app.data.oSTa.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                app.data.cur_otempSTa = app.data.oSTa.pix{app.tV.ocurIx}; 
                                plot_overlayEdit_label(app, 'Axial'); 
                            end
                        case 'Coronal'
                            if isequal(app.tV.curY, app.data.oSTc.sliceN)
                                app.tV.ocurIx = app.tV.ocurIx + 1; 
                                app.data.cur_oSTc = zeros(app.data.nZ, app.data.nX); 
                                app.data.cur_oSTc(app.data.oSTc.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                app.data.cur_otempSTc = app.data.oSTc.pix{app.tV.ocurIx}; 
                                plot_overlayEdit_label(app, 'Coronal'); 
                            end
                        case 'Sagittal'
                            if isequal(app.tV.curX, app.data.oSTs.sliceN)
                                app.tV.ocurIx = app.tV.ocurIx + 1; 
                                app.data.cur_oSTs = zeros(app.data.nY, app.data.nZ); 
                                app.data.cur_oSTs(app.data.oSTs.pix{app.tV.ocurIx}) = app.tV.labelN; 
                                app.data.cur_otempSTs = app.data.oSTs.pix{app.tV.ocurIx}; 
                                plot_overlayEdit_label(app, 'Sagittal'); 
                            end
                    end
                end
            end
            
            % Redo during overlayEdit mode -> when osTa.slicN==curZ

        end

        % Cell edit callback: UITable_Labels
        function Determine_Label_Color(app, event)
            indices = event.Indices;
            newData = event.NewData; 
            
            if indices(2)==2           
                eval(['app.data.VT' num2str(indices(1)) '.label = newData;']);  
            elseif indices(2)==3
                eval(['app.data.VT' num2str(indices(1)) '.cCode = newData;']); 
            end
%             app.UITable_Labels.Data; 
%             app.tV.cCode
            
        end

        % Value changed function: EditField_CurrentTracing
        function EditField_CurrentTracingValueChanged(app, event)
            app.tV.labelN = app.EditField_CurrentTracing.Value;
            
        end

        % Callback function
        function Button_ColorCodesPushed(app, event)
            
            h = figure(1974); 
            h.Position = [app.dV.sW*.5, app.dV.sH*.5, 150, 500]; 
            set(h, 'MenuBar', 'none', 'ToolBar', 'none');
            set(h, 'Name', 'Color Codes', 'NumberTitle','off') 
            set(h, 'Resize', 'off'); 
            for j=1:size(app.dV.cCodes,1)
                plot([1,150], [1, 1]-(j-1)*10, 'Color', app.dV.cCodes(j,:), 'LineWidth', 19); hold on; 
                if j<10
                    text(75, 1-(j-1)*10, num2str(j), 'FontSize', 10, 'FontWeight', 'bold'); 
                else
                    text(70, 1-(j-1)*10, num2str(j), 'FontSize', 10, 'FontWeight', 'bold'); 
                end
            end
            set(gca, 'XTick', [1,75], 'XTickLabel', {'', ''}); 
            set(gca, 'YLim', [-145, 5], 'YTick', [-140:10:0], 'YTickLabel', 15:-1:1); axis off; 

        end

        % Menu selected function: ColorCodesMenu
        function ColorCodesMenuSelected(app, event)
            h = figure(1974); 
            h.Position = [app.dV.sW*.5, app.dV.sH*.5, 150, 500]; 
            set(h, 'MenuBar', 'none', 'ToolBar', 'none');
            set(h, 'Name', 'Color Codes', 'NumberTitle','off') 
            set(h, 'Resize', 'off'); 
            for j=1:size(app.dV.cCodes,1)
                plot([1,150], [1, 1]-(j-1)*10, 'Color', app.dV.cCodes(j,:), 'LineWidth', 19); hold on; 
                if j<10
                    text(75, 1-(j-1)*10, num2str(j), 'FontSize', 10, 'FontWeight', 'bold'); 
                else
                    text(70, 1-(j-1)*10, num2str(j), 'FontSize', 10, 'FontWeight', 'bold'); 
                end
            end
            set(gca, 'XTick', [1,75], 'XTickLabel', {'', ''}); 
            set(gca, 'YLim', [-145, 5], 'YTick', [-140:10:0], 'YTickLabel', 15:-1:1); axis off; 

        end

        % Value changed function: Opacity_Slider
        function Opacity_SliderValueChanged(app, event)
            app.tV.alphaL = app.Opacity_Slider.Value;
            
            switch app.tV.view
                case 'Single_Axial'
                    h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                case 'Single_Coronal'
                    h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                case 'Single_Sagittal'
                    h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                case 'Double_AxialCoronal'
                    h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                    h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                case 'Double_AxialSagittal'
                    h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                    h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                case 'Double_CoronalSagittal'
                    h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                    h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
                case 'Triple'
                    h = findobj(app.UIAxes_Axial, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Axial, 'on'); plot_label(app, 'Axial'); hold(app.UIAxes_Axial, 'off'); 
                    h = findobj(app.UIAxes_Coronal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Coronal, 'on'); plot_label(app, 'Coronal'); hold(app.UIAxes_Coronal, 'off'); 
                    h = findobj(app.UIAxes_Sagittal, 'Type', 'Image', 'Tag', 'Label'); delete(h); 
                    hold(app.UIAxes_Sagittal, 'on'); plot_label(app, 'Sagittal'); hold(app.UIAxes_Sagittal, 'off'); 
            end
            
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'No'
                    app.tV.overlayMode = 0; 
                case 'Show'
                    app.tV.overlayMode = 1; 
                case 'Edit'
                    app.tV.overlayMode = 2; 
%                 case ' - Edit'
%                     app.tV.overlayMode = 4; 
%                     app.tV.sliceNchange = -1; 
            end
            
        end

        % Selection changed function: PlaneButtonGroup
        function PlaneButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlaneButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Axial'
                    app.tV.overlayPlane = 'Axial'; 
                case 'Coronal'
                    app.tV.overlayPlane = 'Coronal'; 
                case 'Sagittal'
                    app.tV.overlayPlane = 'Sagittal'; 
            end
            
        end

        % Value changed function: OpacityOverlay_Slider
        function OpacityOverlay_SliderValueChanged(app, event)
            app.tV.overlayAlphaL = app.OpacityOverlay_Slider.Value;
            
            %% Show OverlayLabel On/Off
            if isequal(app.tV.display, 'ImageLabel')
                eval(['tcurAxes = app.UIAxes_' app.tV.overlayPlane ';']); 
                h = findobj(tcurAxes, 'Type', 'Image', 'Tag', 'OverlayLabel'); 
                delete(h); 
                hold(tcurAxes, 'on'); plot_overlay_label(app, app.tV.overlayPlane); hold(tcurAxes, 'off'); 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @FigResize, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @buttonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @buttonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @buttonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @mouseScroll, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @keyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @keyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMRIMenu = uimenu(app.FileMenu);
            app.OpenMRIMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMRIMenuSelected, true);
            app.OpenMRIMenu.Text = 'Open MRI';
            app.OpenLabelFileMenu = uimenu(app.FileMenu);
            app.OpenLabelFileMenu.Text = 'Open Label File';
            app.SaveLabelFileMenu = uimenu(app.FileMenu);
            app.SaveLabelFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveLabelFileMenuSelected, true);
            app.SaveLabelFileMenu.Text = 'Save Label File';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.Menu_Single = uimenu(app.ViewMenu);
            app.Menu_Single.MenuSelectedFcn = createCallbackFcn(app, @Menu_SingleSelected, true);
            app.Menu_Single.Text = 'Single';
            app.Menu_Axial = uimenu(app.Menu_Single);
            app.Menu_Axial.MenuSelectedFcn = createCallbackFcn(app, @Menu_AxialSelected, true);
            app.Menu_Axial.Text = 'Axial';
            app.Menu_Coronal = uimenu(app.Menu_Single);
            app.Menu_Coronal.MenuSelectedFcn = createCallbackFcn(app, @Menu_CoronalSelected, true);
            app.Menu_Coronal.Text = 'Coronal';
            app.Menu_Sagittal = uimenu(app.Menu_Single);
            app.Menu_Sagittal.MenuSelectedFcn = createCallbackFcn(app, @Menu_SagittalSelected, true);
            app.Menu_Sagittal.Text = 'Sagittal';
            app.Menu_Double = uimenu(app.ViewMenu);
            app.Menu_Double.MenuSelectedFcn = createCallbackFcn(app, @Menu_DoubleSelected, true);
            app.Menu_Double.Text = 'Double';
            app.Menu_AxialCoronal = uimenu(app.Menu_Double);
            app.Menu_AxialCoronal.MenuSelectedFcn = createCallbackFcn(app, @Menu_AxialCoronalSelected, true);
            app.Menu_AxialCoronal.Text = 'Axial + Coronal';
            app.Menu_AxialSagittal = uimenu(app.Menu_Double);
            app.Menu_AxialSagittal.MenuSelectedFcn = createCallbackFcn(app, @Menu_AxialSagittalSelected, true);
            app.Menu_AxialSagittal.Text = 'Axial + Sagittal';
            app.Menu_CoronalSagittal = uimenu(app.Menu_Double);
            app.Menu_CoronalSagittal.MenuSelectedFcn = createCallbackFcn(app, @Menu_CoronalSagittalSelected, true);
            app.Menu_CoronalSagittal.Text = 'Coronal + Sagittal';
            app.Menu_Triple = uimenu(app.ViewMenu);
            app.Menu_Triple.MenuSelectedFcn = createCallbackFcn(app, @Menu_TripleSelected, true);
            app.Menu_Triple.Text = 'Triple';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ColorCodesMenu = uimenu(app.OptionsMenu);
            app.ColorCodesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorCodesMenuSelected, true);
            app.ColorCodesMenu.Text = 'Color Codes';
            app.Panel_DisplayParameters = uipanel(app.UIFigure);
            app.Panel_DisplayParameters.Title = 'Display Parameters';
            app.EditField_Contrast = uieditfield(app.Panel_DisplayParameters, 'numeric');
            app.EditField_Contrast.ValueChangedFcn = createCallbackFcn(app, @EditField_ContrastValueChanged, true);
            app.CenterBrightnessLabel = uilabel(app.Panel_DisplayParameters);
            app.CenterBrightnessLabel.Text = {'Center'; '(Brightness)'; ''};
            app.WidthContrastLabel = uilabel(app.Panel_DisplayParameters);
            app.WidthContrastLabel.Text = {'Width'; '(Contrast)'};
            app.Slider_Brightness = uislider(app.Panel_DisplayParameters);
            app.Slider_Brightness.ValueChangedFcn = createCallbackFcn(app, @Slider_BrightnessValueChanged, true);
            app.EditField_Brightness = uieditfield(app.Panel_DisplayParameters, 'numeric');
            app.EditField_Brightness.ValueChangedFcn = createCallbackFcn(app, @EditField_BrightnessValueChanged, true);
            app.Slider_Contrast = uislider(app.Panel_DisplayParameters);
            app.Slider_Contrast.ValueChangedFcn = createCallbackFcn(app, @Slider_ContrastValueChanged, true);
            app.UIAxes_imgHist = uiaxes(app.Panel_DisplayParameters);
            title(app.UIAxes_imgHist, 'Image Values')
            zlabel(app.UIAxes_imgHist, 'Z')
            app.ButtonGroup_DisplayOption = uibuttongroup(app.UIFigure);
            app.ButtonGroup_DisplayOption.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_DisplayOptionSelectionChanged, true);
            app.ButtonGroup_DisplayOption.Title = 'Display Option';
            app.ImageButton = uiradiobutton(app.ButtonGroup_DisplayOption);
            app.ImageButton.Text = 'Image';
            app.ImageButton.Value = true;
            app.ImageLabelButton = uiradiobutton(app.ButtonGroup_DisplayOption);
            app.ImageLabelButton.Text = 'Image + Label';
            app.LabelButton = uiradiobutton(app.ButtonGroup_DisplayOption);
            app.LabelButton.Text = 'Label';
            app.Button_Plot = uibutton(app.ButtonGroup_DisplayOption, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Text = 'Plot';
            app.Button_SelectRange = uibutton(app.ButtonGroup_DisplayOption, 'push');
            app.Button_SelectRange.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectRangePushed, true);
            app.Button_SelectRange.Text = {'Select '; 'Range'};
            app.Button_3Dshape = uiradiobutton(app.ButtonGroup_DisplayOption);
            app.Button_3Dshape.Text = '3D Shape';
            app.Panel_Axial = uipanel(app.UIFigure);
            app.Slider_Axial = uislider(app.Panel_Axial);
            app.Slider_Axial.ValueChangedFcn = createCallbackFcn(app, @Slider_AxialValueChanged, true);
            app.Spinner_Axial = uispinner(app.Panel_Axial);
            app.Spinner_Axial.ValueChangedFcn = createCallbackFcn(app, @Spinner_AxialValueChanged, true);
            app.Spinner_Axial.Value = 330;
            app.Button_home_Axial = uibutton(app.Panel_Axial, 'push');
            app.Button_home_Axial.ButtonPushedFcn = createCallbackFcn(app, @Button_home_AxialPushed, true);
            app.Button_home_Axial.Icon = 'home_32pixel.png';
            app.Button_home_Axial.Text = '';
            app.Button_select_Axial = uibutton(app.Panel_Axial, 'push');
            app.Button_select_Axial.ButtonPushedFcn = createCallbackFcn(app, @Button_select_AxialPushed, true);
            app.Button_select_Axial.Icon = 'select_rectangle.png';
            app.Button_select_Axial.Text = '';
            app.Panel_Coronal = uipanel(app.UIFigure);
            app.Slider_Coronal = uislider(app.Panel_Coronal);
            app.Slider_Coronal.ValueChangedFcn = createCallbackFcn(app, @Slider_CoronalValueChanged, true);
            app.Spinner_Coronal = uispinner(app.Panel_Coronal);
            app.Spinner_Coronal.ValueChangedFcn = createCallbackFcn(app, @Spinner_CoronalValueChanged, true);
            app.Button_home_Coronal = uibutton(app.Panel_Coronal, 'push');
            app.Button_home_Coronal.ButtonPushedFcn = createCallbackFcn(app, @Button_home_CoronalPushed, true);
            app.Button_home_Coronal.Icon = 'home_32pixel.png';
            app.Button_home_Coronal.Text = '';
            app.Button_select_Coronal = uibutton(app.Panel_Coronal, 'push');
            app.Button_select_Coronal.ButtonPushedFcn = createCallbackFcn(app, @Button_select_CoronalPushed, true);
            app.Button_select_Coronal.Icon = 'select_rectangle.png';
            app.Button_select_Coronal.Text = '';
            app.Panel_Sagittal = uipanel(app.UIFigure);
            app.Slider_Sagittal = uislider(app.Panel_Sagittal);
            app.Slider_Sagittal.ValueChangedFcn = createCallbackFcn(app, @Slider_SagittalValueChanged, true);
            app.Spinner_Sagittal = uispinner(app.Panel_Sagittal);
            app.Spinner_Sagittal.ValueChangedFcn = createCallbackFcn(app, @Spinner_SagittalValueChanged, true);
            app.Spinner_Sagittal.Value = 300;
            app.Button_home_Sagittal = uibutton(app.Panel_Sagittal, 'push');
            app.Button_home_Sagittal.ButtonPushedFcn = createCallbackFcn(app, @Button_home_SagittalPushed, true);
            app.Button_home_Sagittal.Icon = 'home_32pixel.png';
            app.Button_home_Sagittal.Text = '';
            app.Button_select_Sagittal = uibutton(app.Panel_Sagittal, 'push');
            app.Button_select_Sagittal.ButtonPushedFcn = createCallbackFcn(app, @Button_select_SagittalPushed, true);
            app.Button_select_Sagittal.Icon = 'select_rectangle.png';
            app.Button_select_Sagittal.Text = '';
            app.Panel_TracingLabels = uipanel(app.UIFigure);
            app.Panel_TracingLabels.Title = 'Tracing Label';
            app.UITable_Labels = uitable(app.Panel_TracingLabels);
            app.UITable_Labels.ColumnName = {'#'; 'Name'; 'Color'};
            app.UITable_Labels.RowName = {};
            app.UITable_Labels.ColumnEditable = [false true true];
            app.UITable_Labels.CellEditCallback = createCallbackFcn(app, @Determine_Label_Color, true);
            app.TracingCcodeEditFieldLabel = uilabel(app.Panel_TracingLabels);
            app.TracingCcodeEditFieldLabel.Text = 'Tracing Label #';
            app.EditField_CurrentTracing = uieditfield(app.Panel_TracingLabels, 'numeric');
            app.EditField_CurrentTracing.ValueChangedFcn = createCallbackFcn(app, @EditField_CurrentTracingValueChanged, true);
            app.Panel_TracingTools = uipanel(app.UIFigure);
            app.Panel_TracingTools.Title = 'Tracing Tools';
            app.StateButton_pen = uibutton(app.Panel_TracingTools, 'state');
            app.StateButton_pen.ValueChangedFcn = createCallbackFcn(app, @StateButton_penValueChanged, true);
            app.StateButton_pen.Icon = 'pen.png';
            app.StateButton_pen.Text = '';
            app.StateButton_eraser = uibutton(app.Panel_TracingTools, 'state');
            app.StateButton_eraser.ValueChangedFcn = createCallbackFcn(app, @StateButton_eraserValueChanged, true);
            app.StateButton_eraser.Icon = 'eraser.png';
            app.StateButton_eraser.Text = '';
            app.Redo_button = uibutton(app.Panel_TracingTools, 'push');
            app.Redo_button.ButtonPushedFcn = createCallbackFcn(app, @Redo_buttonPushed, true);
            app.Redo_button.Icon = 'redo.png';
            app.Redo_button.Text = '';
            app.Undo_button = uibutton(app.Panel_TracingTools, 'push');
            app.Undo_button.ButtonPushedFcn = createCallbackFcn(app, @Undo_buttonPushed, true);
            app.Undo_button.Icon = 'undo.png';
            app.Undo_button.Text = '';
            app.StateButton_drawing = uibutton(app.Panel_TracingTools, 'state');
            app.StateButton_drawing.ValueChangedFcn = createCallbackFcn(app, @StateButton_drawingValueChanged, true);
            app.StateButton_drawing.Icon = 'drawing.png';
            app.StateButton_drawing.Text = '';
            app.OpacitySliderLabel = uilabel(app.Panel_TracingTools);
            app.OpacitySliderLabel.Text = {'Opacity'; ''};
            app.Opacity_Slider = uislider(app.Panel_TracingTools);
            app.Opacity_Slider.Limits = [0 1];
            app.Opacity_Slider.ValueChangedFcn = createCallbackFcn(app, @Opacity_SliderValueChanged, true);
            app.Opacity_Slider.Value = 1;
            app.PenSizeSpinnerLabel = uilabel(app.Panel_TracingTools);
            app.PenSizeSpinnerLabel.Text = {'Pen'; 'Size'; ''};
            app.PenSizeSpinner = uispinner(app.Panel_TracingTools);
            app.PenSizeSpinner.Limits = [1 10];
            app.PenSizeSpinner.Value = 1;
            app.Panel_LabelOverlay = uipanel(app.UIFigure);
            app.Panel_LabelOverlay.Title = 'Label Overlay';
            app.ModeButtonGroup = uibuttongroup(app.Panel_LabelOverlay);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.NoButton = uiradiobutton(app.ModeButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.Show_Button = uiradiobutton(app.ModeButtonGroup);
            app.Show_Button.Text = 'Show';
            app.Edit_Button = uiradiobutton(app.ModeButtonGroup);
            app.Edit_Button.Text = 'Edit';
            app.PlaneButtonGroup = uibuttongroup(app.Panel_LabelOverlay);
            app.PlaneButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlaneButtonGroupSelectionChanged, true);
            app.PlaneButtonGroup.Title = 'Plane';
            app.AxialButton = uiradiobutton(app.PlaneButtonGroup);
            app.AxialButton.Text = 'Axial';
            app.AxialButton.Value = true;
            app.CoronalButton = uiradiobutton(app.PlaneButtonGroup);
            app.CoronalButton.Text = 'Coronal';
            app.SagittalButton = uiradiobutton(app.PlaneButtonGroup);
            app.SagittalButton.Text = 'Sagittal';
            app.OverlayOpacityLabel = uilabel(app.Panel_LabelOverlay);
            app.OverlayOpacityLabel.Text = {'Overlay Opacity'; ''};
            app.OpacityOverlay_Slider = uislider(app.Panel_LabelOverlay);
            app.OpacityOverlay_Slider.Limits = [0 1];
            app.OpacityOverlay_Slider.ValueChangedFcn = createCallbackFcn(app, @OpacityOverlay_SliderValueChanged, true);
            app.OpacityOverlay_Slider.Value = 0.5;
            app.UIAxes_Axial = uiaxes(app.UIFigure);
            title(app.UIAxes_Axial, 'Title')
            zlabel(app.UIAxes_Axial, 'Z')
            app.UIAxes_Coronal = uiaxes(app.UIFigure);
            title(app.UIAxes_Coronal, 'Title')
            zlabel(app.UIAxes_Coronal, 'Z')
            app.UIAxes_Sagittal = uiaxes(app.UIFigure);
            title(app.UIAxes_Sagittal, 'Title')
            zlabel(app.UIAxes_Sagittal, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRItrace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end